classdef Channel_location < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SetPathButton            matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        DeleteButton             matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        ChannelLocationMapLabel  matlab.ui.control.Label
        Tree                     matlab.ui.container.Tree
        ChannelNode              matlab.ui.container.TreeNode
        FP1Node                  matlab.ui.container.TreeNode
        FPZNode                  matlab.ui.container.TreeNode
        FP2Node                  matlab.ui.container.TreeNode
        NZNode                   matlab.ui.container.TreeNode
        AF9Node                  matlab.ui.container.TreeNode
        AF7Node                  matlab.ui.container.TreeNode
        AF3Node                  matlab.ui.container.TreeNode
        AFZNode                  matlab.ui.container.TreeNode
        AF4Node                  matlab.ui.container.TreeNode
        AF8Node                  matlab.ui.container.TreeNode
        AF10Node                 matlab.ui.container.TreeNode
        F9Node                   matlab.ui.container.TreeNode
        F7Node                   matlab.ui.container.TreeNode
        F5Node                   matlab.ui.container.TreeNode
        F3Node                   matlab.ui.container.TreeNode
        F1Node                   matlab.ui.container.TreeNode
        FZNode                   matlab.ui.container.TreeNode
        F2Node                   matlab.ui.container.TreeNode
        F4Node                   matlab.ui.container.TreeNode
        F6Node                   matlab.ui.container.TreeNode
        F8Node                   matlab.ui.container.TreeNode
        F10Node                  matlab.ui.container.TreeNode
        FT9Node                  matlab.ui.container.TreeNode
        FT7Node                  matlab.ui.container.TreeNode
        FC5Node                  matlab.ui.container.TreeNode
        FC3Node                  matlab.ui.container.TreeNode
        FC1Node                  matlab.ui.container.TreeNode
        FCZNode                  matlab.ui.container.TreeNode
        FC2Node                  matlab.ui.container.TreeNode
        FC4Node                  matlab.ui.container.TreeNode
        FC6Node                  matlab.ui.container.TreeNode
        FT8Node                  matlab.ui.container.TreeNode
        FT10Node                 matlab.ui.container.TreeNode
        T9Node                   matlab.ui.container.TreeNode
        T7Node                   matlab.ui.container.TreeNode
        C5Node                   matlab.ui.container.TreeNode
        C3Node                   matlab.ui.container.TreeNode
        C1Node                   matlab.ui.container.TreeNode
        CZNode                   matlab.ui.container.TreeNode
        C2Node                   matlab.ui.container.TreeNode
        C4Node                   matlab.ui.container.TreeNode
        C6Node                   matlab.ui.container.TreeNode
        T8Node                   matlab.ui.container.TreeNode
        T10Node                  matlab.ui.container.TreeNode
        TP9Node                  matlab.ui.container.TreeNode
        TP7Node                  matlab.ui.container.TreeNode
        CP5Node                  matlab.ui.container.TreeNode
        CP3Node                  matlab.ui.container.TreeNode
        CP1Node                  matlab.ui.container.TreeNode
        CPZNode                  matlab.ui.container.TreeNode
        CP2Node                  matlab.ui.container.TreeNode
        CP4Node                  matlab.ui.container.TreeNode
        CP6Node                  matlab.ui.container.TreeNode
        TP8Node                  matlab.ui.container.TreeNode
        TP10Node                 matlab.ui.container.TreeNode
        P9Node                   matlab.ui.container.TreeNode
        P7Node                   matlab.ui.container.TreeNode
        P5Node                   matlab.ui.container.TreeNode
        P3Node                   matlab.ui.container.TreeNode
        P1Node                   matlab.ui.container.TreeNode
        PZNode                   matlab.ui.container.TreeNode
        P2Node                   matlab.ui.container.TreeNode
        P4Node                   matlab.ui.container.TreeNode
        P6Node                   matlab.ui.container.TreeNode
        P8Node                   matlab.ui.container.TreeNode
        P10Node                  matlab.ui.container.TreeNode
        PO9Node                  matlab.ui.container.TreeNode
        PO7Node                  matlab.ui.container.TreeNode
        PO3Node                  matlab.ui.container.TreeNode
        POZNode                  matlab.ui.container.TreeNode
        PO4Node                  matlab.ui.container.TreeNode
        PO8Node                  matlab.ui.container.TreeNode
        PO10Node                 matlab.ui.container.TreeNode
        O1Node                   matlab.ui.container.TreeNode
        OZNode                   matlab.ui.container.TreeNode
        O2Node                   matlab.ui.container.TreeNode
        O9Node                   matlab.ui.container.TreeNode
        O10Node                  matlab.ui.container.TreeNode
        CB1Node                  matlab.ui.container.TreeNode
        CB2Node                  matlab.ui.container.TreeNode
        IZNode                   matlab.ui.container.TreeNode
        UITable                  matlab.ui.control.Table
        PlotTopographyUIAxes     matlab.ui.control.UIAxes
        CreatingEEGChannelLocationFileLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        all_locations
        selected_locations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app name
            app.UIFigure.Name = 'Channel Location';
            
            app.all_locations = load('Standard-10-10-Cap81.mat');
            app.all_locations = app.all_locations.locations;

        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            try
                selectedFolder = uigetdir('Select a file');
                addpath(genpath(selectedFolder));
            catch
                disp('Try again')
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % Select channel
            selected_channel = find(strcmp(app.all_locations.labels, app.Tree.SelectedNodes.Text));
            try
                if isempty(app.UITable.Data)
                    app.selected_locations = app.all_locations(selected_channel, :);
                    app.selected_locations.Number = 1;
                elseif ~isempty(app.UITable.Data) && isempty(intersect(app.selected_locations.labels, app.Tree.SelectedNodes.Text))
                    app.selected_locations(size(app.selected_locations, 1) + 1, :) = app.all_locations(selected_channel, :);
                    app.selected_locations.Number(size(app.selected_locations, 1)) = size(app.selected_locations, 1);
                end
            catch
            end

            vars = {'Number','labels','theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
            t = app.selected_locations(:,vars);
            app.UITable.Data = t;
            

            % Plot channel location map
            if ~isempty(app.UITable.Data) && size(app.selected_locations, 1) >= 5
                locations = app.selected_locations;
                channel_list = cellstr(transpose(app.selected_locations.labels));
                map_value = zeros(1,size(app.selected_locations, 1));
                plot_topography(app, channel_list, map_value, true, locations, false, true, 300);   
                hold(app.PlotTopographyUIAxes, 'on')
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % Delete channel
            if ~isempty(app.UITable.Data)
                app.selected_locations(size(app.selected_locations, 1), :) = [];
            elseif isempty(app.UITable.Data)
                app.selected_locations = app.all_locations(1, :);
                app.selected_locations(size(app.selected_locations, 1), :) = [];
            end
            
            vars = {'Number','labels','theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
            t = app.selected_locations(:,vars);
            app.UITable.Data = t;
            
            
            % Plot channel location map
            if ~isempty(app.UITable.Data) && size(app.selected_locations, 1) < 5
                cla(app.PlotTopographyUIAxes)
                
            elseif ~isempty(app.UITable.Data) && size(app.selected_locations, 1) >= 5
                locations = app.selected_locations;
                channel_list = cellstr(transpose(app.selected_locations.labels));
                map_value = zeros(1,size(app.selected_locations, 1));
                plot_topography(app, channel_list, map_value, true, locations, false, true, 300);   
                hold(app.PlotTopographyUIAxes, 'on')
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            locations = app.selected_locations;
            try
                uisave({'locations'},'')
            catch
                disp('Try again')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreatingEEGChannelLocationFileLabel = uilabel(app.UIFigure);
            app.CreatingEEGChannelLocationFileLabel.Text = 'Creating - EEG Channel Location File';
            app.Panel = uipanel(app.UIFigure);
            app.PlotTopographyUIAxes = uiaxes(app.Panel);
            app.PlotTopographyUIAxes.XTick = [];
            app.PlotTopographyUIAxes.YTick = [];
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Number'; 'labels'; 'theta'; 'radius'; 'X'; 'Y'; 'Z'; 'sph_theta'; 'sph_phi'; 'sph_radius'};
            app.UITable.RowName = {};
            app.Tree = uitree(app.Panel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ChannelNode = uitreenode(app.Tree);
            app.ChannelNode.Text = 'Channel';
            app.FP1Node = uitreenode(app.ChannelNode);
            app.FP1Node.Text = 'FP1';
            app.FPZNode = uitreenode(app.ChannelNode);
            app.FPZNode.Text = 'FPZ';
            app.FP2Node = uitreenode(app.ChannelNode);
            app.FP2Node.Text = 'FP2';
            app.NZNode = uitreenode(app.ChannelNode);
            app.NZNode.Text = 'NZ';
            app.AF9Node = uitreenode(app.ChannelNode);
            app.AF9Node.Text = 'AF9';
            app.AF7Node = uitreenode(app.ChannelNode);
            app.AF7Node.Text = 'AF7';
            app.AF3Node = uitreenode(app.ChannelNode);
            app.AF3Node.Text = 'AF3';
            app.AFZNode = uitreenode(app.ChannelNode);
            app.AFZNode.Text = 'AFZ';
            app.AF4Node = uitreenode(app.ChannelNode);
            app.AF4Node.Text = 'AF4';
            app.AF8Node = uitreenode(app.ChannelNode);
            app.AF8Node.Text = 'AF8';
            app.AF10Node = uitreenode(app.ChannelNode);
            app.AF10Node.Text = 'AF10';
            app.F9Node = uitreenode(app.ChannelNode);
            app.F9Node.Text = 'F9';
            app.F7Node = uitreenode(app.ChannelNode);
            app.F7Node.Text = 'F7';
            app.F5Node = uitreenode(app.ChannelNode);
            app.F5Node.Text = 'F5';
            app.F3Node = uitreenode(app.ChannelNode);
            app.F3Node.Text = 'F3';
            app.F1Node = uitreenode(app.ChannelNode);
            app.F1Node.Text = 'F1';
            app.FZNode = uitreenode(app.ChannelNode);
            app.FZNode.Text = 'FZ';
            app.F2Node = uitreenode(app.ChannelNode);
            app.F2Node.Text = 'F2';
            app.F4Node = uitreenode(app.ChannelNode);
            app.F4Node.Text = 'F4';
            app.F6Node = uitreenode(app.ChannelNode);
            app.F6Node.Text = 'F6';
            app.F8Node = uitreenode(app.ChannelNode);
            app.F8Node.Text = 'F8';
            app.F10Node = uitreenode(app.ChannelNode);
            app.F10Node.Text = 'F10';
            app.FT9Node = uitreenode(app.ChannelNode);
            app.FT9Node.Text = 'FT9';
            app.FT7Node = uitreenode(app.ChannelNode);
            app.FT7Node.Text = 'FT7';
            app.FC5Node = uitreenode(app.ChannelNode);
            app.FC5Node.Text = 'FC5';
            app.FC3Node = uitreenode(app.ChannelNode);
            app.FC3Node.Text = 'FC3';
            app.FC1Node = uitreenode(app.ChannelNode);
            app.FC1Node.Text = 'FC1';
            app.FCZNode = uitreenode(app.ChannelNode);
            app.FCZNode.Text = 'FCZ';
            app.FC2Node = uitreenode(app.ChannelNode);
            app.FC2Node.Text = 'FC2';
            app.FC4Node = uitreenode(app.ChannelNode);
            app.FC4Node.Text = 'FC4';
            app.FC6Node = uitreenode(app.ChannelNode);
            app.FC6Node.Text = 'FC6';
            app.FT8Node = uitreenode(app.ChannelNode);
            app.FT8Node.Text = 'FT8';
            app.FT10Node = uitreenode(app.ChannelNode);
            app.FT10Node.Text = 'FT10';
            app.T9Node = uitreenode(app.ChannelNode);
            app.T9Node.Text = 'T9';
            app.T7Node = uitreenode(app.ChannelNode);
            app.T7Node.Text = 'T7';
            app.C5Node = uitreenode(app.ChannelNode);
            app.C5Node.Text = 'C5';
            app.C3Node = uitreenode(app.ChannelNode);
            app.C3Node.Text = 'C3';
            app.C1Node = uitreenode(app.ChannelNode);
            app.C1Node.Text = 'C1';
            app.CZNode = uitreenode(app.ChannelNode);
            app.CZNode.Text = 'CZ';
            app.C2Node = uitreenode(app.ChannelNode);
            app.C2Node.Text = 'C2';
            app.C4Node = uitreenode(app.ChannelNode);
            app.C4Node.Text = 'C4';
            app.C6Node = uitreenode(app.ChannelNode);
            app.C6Node.Text = 'C6';
            app.T8Node = uitreenode(app.ChannelNode);
            app.T8Node.Text = 'T8';
            app.T10Node = uitreenode(app.ChannelNode);
            app.T10Node.Text = 'T10';
            app.TP9Node = uitreenode(app.ChannelNode);
            app.TP9Node.Text = 'TP9';
            app.TP7Node = uitreenode(app.ChannelNode);
            app.TP7Node.Text = 'TP7';
            app.CP5Node = uitreenode(app.ChannelNode);
            app.CP5Node.Text = 'CP5';
            app.CP3Node = uitreenode(app.ChannelNode);
            app.CP3Node.Text = 'CP3';
            app.CP1Node = uitreenode(app.ChannelNode);
            app.CP1Node.Text = 'CP1';
            app.CPZNode = uitreenode(app.ChannelNode);
            app.CPZNode.Text = 'CPZ';
            app.CP2Node = uitreenode(app.ChannelNode);
            app.CP2Node.Text = 'CP2';
            app.CP4Node = uitreenode(app.ChannelNode);
            app.CP4Node.Text = 'CP4';
            app.CP6Node = uitreenode(app.ChannelNode);
            app.CP6Node.Text = 'CP6';
            app.TP8Node = uitreenode(app.ChannelNode);
            app.TP8Node.Text = 'TP8';
            app.TP10Node = uitreenode(app.ChannelNode);
            app.TP10Node.Text = 'TP10';
            app.P9Node = uitreenode(app.ChannelNode);
            app.P9Node.Text = 'P9';
            app.P7Node = uitreenode(app.ChannelNode);
            app.P7Node.Text = 'P7';
            app.P5Node = uitreenode(app.ChannelNode);
            app.P5Node.Text = 'P5';
            app.P3Node = uitreenode(app.ChannelNode);
            app.P3Node.Text = 'P3';
            app.P1Node = uitreenode(app.ChannelNode);
            app.P1Node.Text = 'P1';
            app.PZNode = uitreenode(app.ChannelNode);
            app.PZNode.Text = 'PZ';
            app.P2Node = uitreenode(app.ChannelNode);
            app.P2Node.Text = 'P2';
            app.P4Node = uitreenode(app.ChannelNode);
            app.P4Node.Text = 'P4';
            app.P6Node = uitreenode(app.ChannelNode);
            app.P6Node.Text = 'P6';
            app.P8Node = uitreenode(app.ChannelNode);
            app.P8Node.Text = 'P8';
            app.P10Node = uitreenode(app.ChannelNode);
            app.P10Node.Text = 'P10';
            app.PO9Node = uitreenode(app.ChannelNode);
            app.PO9Node.Text = 'PO9';
            app.PO7Node = uitreenode(app.ChannelNode);
            app.PO7Node.Text = 'PO7';
            app.PO3Node = uitreenode(app.ChannelNode);
            app.PO3Node.Text = 'PO3';
            app.POZNode = uitreenode(app.ChannelNode);
            app.POZNode.Text = 'POZ';
            app.PO4Node = uitreenode(app.ChannelNode);
            app.PO4Node.Text = 'PO4';
            app.PO8Node = uitreenode(app.ChannelNode);
            app.PO8Node.Text = 'PO8';
            app.PO10Node = uitreenode(app.ChannelNode);
            app.PO10Node.Text = 'PO10';
            app.O1Node = uitreenode(app.ChannelNode);
            app.O1Node.Text = 'O1';
            app.OZNode = uitreenode(app.ChannelNode);
            app.OZNode.Text = 'OZ';
            app.O2Node = uitreenode(app.ChannelNode);
            app.O2Node.Text = 'O2';
            app.O9Node = uitreenode(app.ChannelNode);
            app.O9Node.Text = 'O9';
            app.O10Node = uitreenode(app.ChannelNode);
            app.O10Node.Text = 'O10';
            app.CB1Node = uitreenode(app.ChannelNode);
            app.CB1Node.Text = 'CB1';
            app.CB2Node = uitreenode(app.ChannelNode);
            app.CB2Node.Text = 'CB2';
            app.IZNode = uitreenode(app.ChannelNode);
            app.IZNode.Text = 'IZ';
            app.ChannelLocationMapLabel = uilabel(app.Panel);
            app.ChannelLocationMapLabel.Text = 'Channel Location Map';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SetPathButton = uibutton(app.UIFigure, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_location

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end