classdef Channel_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SetPathButton                matlab.ui.control.Button
        ViewerOpenButton             matlab.ui.control.Button
        SettingEEGChannelInformationToolLabel  matlab.ui.control.Label
        LocationFileButton           matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        P300ToolButton               matlab.ui.control.StateButton
        NeurofeedbackToolButton      matlab.ui.control.StateButton
        LocationFilePanel            matlab.ui.container.Panel
        DataSampleRateSpinner        matlab.ui.control.Spinner
        DataSampleRateSpinnerLabel   matlab.ui.control.Label
        StandardCheckBox             matlab.ui.control.CheckBox
        StandardListBox              matlab.ui.control.ListBox
        LoadButton                   matlab.ui.control.Button
        ChannelLocationFileCheckBox  matlab.ui.control.CheckBox
        UITable                      matlab.ui.control.Table
        ChannelLocationMapLabel      matlab.ui.control.Label
        PlotConnectivityUIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NftoolApp;
        P300toolApp;
        LocationApp;
    end
    
    properties (Access = public)
        locations
        sample_rate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app name
            app.UIFigure.Name = 'Channel Selection';

        end

        % Value changed function: StandardListBox
        function StandardListBoxValueChanged(app, event)
            % Select standard location file
            value = app.StandardListBox.Value;
            switch value
                case 'Standard-10-10-Cap33'
                    app.locations = load('Standard-10-10-Cap33.mat');
                case 'Standard-10-10-Cap47'
                    app.locations = load('Standard-10-10-Cap47.mat');
                case 'Standard-10-20-Cap19'
                    app.locations = load('Standard-10-10-Cap19.mat');
                case 'Standard-10-20-Cap25'
                    app.locations = load('Standard-10-10-Cap25.mat');
               case 'Standard-10-20-Cap81'
                    app.locations = load('Standard-10-10-Cap81.mat');
            end
            app.locations = app.locations.locations;
            vars = {'Number','labels','theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
            t = app.locations(:,vars);
            app.UITable.Data = t;
%             assignin('base', 'locations', app.locations)


            % Plot channel location map
            app.locations.labels = cellstr(app.locations.labels);
            location_struct = table2struct(app.locations);
            clear ds;
            ds.connectStrength = ones(size(location_struct,1),1);
            vector_sequence = 1:size(location_struct,1);
            ds.chanPairs = repmat(vector_sequence', 1, 2);
            topoplot_connect(app, ds, location_struct);

        end

        % Value changed function: StandardCheckBox
        function StandardCheckBoxValueChanged(app, event)
            value = app.StandardCheckBox.Value;
            if value == true
                app.StandardListBox.Enable = true;
                app.LoadButton.Enable = false;
                app.ChannelLocationFileCheckBox.Value = false;
            else
                app.StandardListBox.Enable = false;
            end
            
        end

        % Value changed function: ChannelLocationFileCheckBox
        function ChannelLocationFileCheckBoxValueChanged(app, event)
            value = app.ChannelLocationFileCheckBox.Value;
            if value == true
                app.LoadButton.Enable = true;
                app.StandardListBox.Enable = false;
                app.StandardCheckBox.Value = false;
            else
                app.LoadButton.Enable = false;
            end

        end

        % Button pushed function: LocationFileButton
        function LocationFileButtonPushed(app, event)
            app.LocationApp = Channel_location;
            
        end

        % Button pushed function: ViewerOpenButton
        function ViewerOpenButtonPushed(app, event)
            global locations
            global sample_rate
            
            app.sample_rate = app.DataSampleRateSpinner.Value;
            
            locations = app.locations;
            sample_rate = app.sample_rate;
            
            if app.NeurofeedbackToolButton.Value == 1
                app.NftoolApp = Neurofeedback_toolbox;
            elseif app.P300ToolButton.Value == 1
                app.P300toolApp = P300_toolbox;
            end
            
            close(app.UIFigure)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Select other location file
            [filename, ~] = uigetfile('*.mat');
            if isequal(filename, 0)
                return;
            end
            matFile = matfile(filename);
            fileVars = who(matFile);
            
            if ~isempty(fileVars)
                firstVarName = fileVars{1};
                if isstruct(matFile.(firstVarName))
                    singval = matFile.(firstVarName);
                    app.locations = struct2table(singval);
                else
                    app.locations = matFile.(firstVarName);
                end
            elseif isempty(fileVars)
                disp('Invalid file.');
            end

            vars = {'Number','labels','theta','radius','X','Y','Z','sph_theta','sph_phi','sph_radius'};
            t = app.locations(:,vars);
            app.UITable.Data = t;
%             assignin('base', 'locations', app.locations)


            % Plot channel location map
            app.locations.labels = cellstr(app.locations.labels);
            location_struct = table2struct(app.locations);
            clear ds;
            ds.connectStrength = ones(size(location_struct,1),1);
            vector_sequence = 1:size(location_struct,1);
            ds.chanPairs = repmat(vector_sequence', 1, 2);
            topoplot_connect(app, ds, location_struct);

        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            try
                selectedFolder = uigetdir('Select a file');
                addpath(genpath(selectedFolder));
            catch
                disp('Try again')
            end

        end

        % Value changed function: NeurofeedbackToolButton
        function NeurofeedbackToolButtonValueChanged(app, event)
            if app.NeurofeedbackToolButton.Value == 1
                app.P300ToolButton.Value = 0;
                app.NeurofeedbackToolButton.FontWeight = 'bold';
                app.P300ToolButton.FontWeight = 'normal';
            else
                app.P300ToolButton.Value = 1;
                app.P300ToolButton.FontWeight = 'bold';
                app.NeurofeedbackToolButton.FontWeight = 'normal';
            end
            
        end

        % Value changed function: P300ToolButton
        function P300ToolButtonValueChanged(app, event)
            if app.P300ToolButton.Value == 1
                app.NeurofeedbackToolButton.Value = 0;
                app.P300ToolButton.FontWeight = 'bold';
                app.NeurofeedbackToolButton.FontWeight = 'normal';
            else
                app.NeurofeedbackToolButton.Value = 1;
                app.NeurofeedbackToolButton.FontWeight = 'bold';
                app.P300ToolButton.FontWeight = 'normal';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.PlotConnectivityUIAxes = uiaxes(app.Panel);
            app.PlotConnectivityUIAxes.XTick = [];
            app.PlotConnectivityUIAxes.YTick = [];
            app.ChannelLocationMapLabel = uilabel(app.Panel);
            app.ChannelLocationMapLabel.Text = 'Channel Location Map';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Number'; 'labels'; 'theta'; 'radius'; 'X'; 'Y'; 'Z'; 'sph_theta'; 'sph_phi'; 'sph_radius'};
            app.UITable.RowName = {};
            app.LocationFilePanel = uipanel(app.Panel);
            app.ChannelLocationFileCheckBox = uicheckbox(app.LocationFilePanel);
            app.ChannelLocationFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelLocationFileCheckBoxValueChanged, true);
            app.ChannelLocationFileCheckBox.Text = 'Channel Location File';
            app.LoadButton = uibutton(app.LocationFilePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.StandardListBox = uilistbox(app.LocationFilePanel);
            app.StandardListBox.Items = {'Standard-10-10-Cap33', 'Standard-10-10-Cap47', 'Standard-10-20-Cap19', 'Standard-10-20-Cap25', 'Standard-10-20-Cap81'};
            app.StandardListBox.ValueChangedFcn = createCallbackFcn(app, @StandardListBoxValueChanged, true);
            app.StandardListBox.Enable = 'off';
            app.StandardListBox.Value = {};
            app.StandardCheckBox = uicheckbox(app.LocationFilePanel);
            app.StandardCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandardCheckBoxValueChanged, true);
            app.StandardCheckBox.Text = 'Standard';
            app.DataSampleRateSpinnerLabel = uilabel(app.LocationFilePanel);
            app.DataSampleRateSpinnerLabel.Text = 'Data  Sample Rate';
            app.DataSampleRateSpinner = uispinner(app.LocationFilePanel);
            app.DataSampleRateSpinner.Limits = [0 Inf];
            app.DataSampleRateSpinner.ValueDisplayFormat = '%.0f';
            app.DataSampleRateSpinner.Value = 500;
            app.NeurofeedbackToolButton = uibutton(app.Panel, 'state');
            app.NeurofeedbackToolButton.ValueChangedFcn = createCallbackFcn(app, @NeurofeedbackToolButtonValueChanged, true);
            app.NeurofeedbackToolButton.Text = {'Neurofeedback'; 'Tool'};
            app.NeurofeedbackToolButton.Value = true;
            app.P300ToolButton = uibutton(app.Panel, 'state');
            app.P300ToolButton.ValueChangedFcn = createCallbackFcn(app, @P300ToolButtonValueChanged, true);
            app.P300ToolButton.Text = {'P300'; 'Tool'};
            app.LocationFileButton = uibutton(app.UIFigure, 'push');
            app.LocationFileButton.ButtonPushedFcn = createCallbackFcn(app, @LocationFileButtonPushed, true);
            app.LocationFileButton.Text = 'Location File';
            app.SettingEEGChannelInformationToolLabel = uilabel(app.UIFigure);
            app.SettingEEGChannelInformationToolLabel.Text = 'Setting - EEG Channel Information & Tool';
            app.ViewerOpenButton = uibutton(app.UIFigure, 'push');
            app.ViewerOpenButton.ButtonPushedFcn = createCallbackFcn(app, @ViewerOpenButtonPushed, true);
            app.ViewerOpenButton.Text = 'Viewer Open';
            app.SetPathButton = uibutton(app.UIFigure, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_selection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end