classdef Connectivity_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ColorMapButtonGroup             matlab.ui.container.ButtonGroup
        grayButton                      matlab.ui.control.RadioButton
        boneButton                      matlab.ui.control.RadioButton
        turboButton                     matlab.ui.control.RadioButton
        hotButton                       matlab.ui.control.RadioButton
        jetButton                       matlab.ui.control.RadioButton
        parulaButton                    matlab.ui.control.RadioButton
        CalculatingLabel                matlab.ui.control.Label
        BrainNetworkPanel               matlab.ui.container.Panel
        SlidingWindowEditField          matlab.ui.control.NumericEditField
        BackwardButton                  matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        SlidingWindowCheckBox           matlab.ui.control.CheckBox
        TimeWindowCheckBox              matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        SlidingWindowPanel              matlab.ui.container.Panel
        OverlapsSpinner                 matlab.ui.control.Spinner
        OverlapsSpinnerLabel            matlab.ui.control.Label
        SizesSpinner                    matlab.ui.control.Spinner
        SizesSpinnerLabel               matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        ColormapRangePanel              matlab.ui.container.Panel
        ColormapRangeCheckBox           matlab.ui.control.CheckBox
        AbsoluteValuesCheckBox          matlab.ui.control.CheckBox
        FrequencyBandPanel              matlab.ui.container.Panel
        HighRangeSpinner2               matlab.ui.control.Spinner
        LowRangeSpinner2                matlab.ui.control.Spinner
        WindowLabel2                    matlab.ui.control.Label
        RangeLabel                      matlab.ui.control.Label
        ThresholdPanel                  matlab.ui.container.Panel
        ThresholdSpinner                matlab.ui.control.Spinner
        TabGroup                        matlab.ui.container.TabGroup
        SignalProcessingTab             matlab.ui.container.Tab
        DetrendCheckBox                 matlab.ui.control.CheckBox
        BaselineTimesSpinner            matlab.ui.control.Spinner
        BaselineTimesSpinnerLabel       matlab.ui.control.Label
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        HighCutOffSpinner               matlab.ui.control.Spinner
        LowCutOffSpinner                matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        NotchFilterButton               matlab.ui.control.RadioButton
        BandpassFilterButton            matlab.ui.control.RadioButton
        FilteringCheckBox               matlab.ui.control.CheckBox
        ICATab                          matlab.ui.container.Tab
        MuscleLabel_4                   matlab.ui.control.Label
        ChannelNoiseEditField           matlab.ui.control.NumericEditField
        ChannelNoiseButton              matlab.ui.control.StateButton
        MuscleLabel_3                   matlab.ui.control.Label
        LineNoiseEditField              matlab.ui.control.NumericEditField
        LineNoiseButton                 matlab.ui.control.StateButton
        MuscleLabel_2                   matlab.ui.control.Label
        HeartEditField                  matlab.ui.control.NumericEditField
        HeartButton                     matlab.ui.control.StateButton
        EyeEditField                    matlab.ui.control.NumericEditField
        EyeLabel                        matlab.ui.control.Label
        EyeButton                       matlab.ui.control.StateButton
        MuscleLabel                     matlab.ui.control.Label
        MuscleEditField                 matlab.ui.control.NumericEditField
        MuscleButton                    matlab.ui.control.StateButton
        ICAArtifactsRemovalCheckBox     matlab.ui.control.CheckBox
        EdgePanel                       matlab.ui.container.Panel
        EdgeLabel4                      matlab.ui.control.Label
        EdgeCheckBox4                   matlab.ui.control.CheckBox
        NodeDropDown4_2                 matlab.ui.control.DropDown
        NodeDropDown4_1                 matlab.ui.control.DropDown
        EdgeLabel3                      matlab.ui.control.Label
        EdgeCheckBox3                   matlab.ui.control.CheckBox
        NodeDropDown3_2                 matlab.ui.control.DropDown
        NodeDropDown3_1                 matlab.ui.control.DropDown
        EdgeLabel2                      matlab.ui.control.Label
        EdgeCheckBox2                   matlab.ui.control.CheckBox
        NodeDropDown2_2                 matlab.ui.control.DropDown
        NodeDropDown2_1                 matlab.ui.control.DropDown
        EdgeLabel                       matlab.ui.control.Label
        EdgeCheckBox                    matlab.ui.control.CheckBox
        NodeDropDown1_2                 matlab.ui.control.DropDown
        NodeDropDown1_1                 matlab.ui.control.DropDown
        EdgeButtonGroup                 matlab.ui.container.ButtonGroup
        NodetoNodeButton                matlab.ui.control.RadioButton
        AllEdgeButton                   matlab.ui.control.RadioButton
        TimeWindowPanel                 matlab.ui.container.Panel
        EndsSpinner                     matlab.ui.control.Spinner
        EndsSpinnerLabel                matlab.ui.control.Label
        StartsSpinner                   matlab.ui.control.Spinner
        StartsSpinnerLabel              matlab.ui.control.Label
        TriggerTextAreaLabel            matlab.ui.control.Label
        TriggerTextArea                 matlab.ui.control.TextArea
        ConnectivityMetricButtonGroup   matlab.ui.container.ButtonGroup
        PLVButton                       matlab.ui.control.RadioButton
        PLIButton                       matlab.ui.control.RadioButton
        CorrelationButton               matlab.ui.control.RadioButton
        CoherenceButton                 matlab.ui.control.RadioButton
        ValueDisplayCheckBox            matlab.ui.control.CheckBox
        LoadDataButton                  matlab.ui.control.Button
        DataFileNameTextArea            matlab.ui.control.TextArea
        DataFileNameTextAreaLabel       matlab.ui.control.Label
        PlotConnectivityUIAxes          matlab.ui.control.UIAxes
        MatrixUIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        locations
        sample_rate = 500;
    end
    
    properties (Access = private)
        network
        save = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global sample_rate
            global locations

            % app name
            app.UIFigure.Name = 'Brain Network';
            
            % public properties
            app.locations = locations;
            app.sample_rate = sample_rate;

            % channel list (Drop-Down)
            app.NodeDropDown1_1.Items=transpose(locations.labels); app.NodeDropDown1_2.Items=transpose(locations.labels);
            app.NodeDropDown2_1.Items=transpose(locations.labels); app.NodeDropDown2_2.Items=transpose(locations.labels);
            app.NodeDropDown3_1.Items=transpose(locations.labels); app.NodeDropDown3_2.Items=transpose(locations.labels);
            app.NodeDropDown4_1.Items=transpose(locations.labels); app.NodeDropDown4_2.Items=transpose(locations.labels);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            global EEG
            
            % EEG struct
            EEG.setname = 'matfile'; EEG.filename = ''; EEG.filepath = ''; EEG.subject = '';
            EEG.nbchan = size(app.locations,1); EEG.srate = app.sample_rate;
            EEG.trials = 1; EEG.pnts = []; EEG.xmin = 0; EEG.xmax = []; EEG.data = [];
            EEG.icaact = []; EEG.icawinv = []; EEG.icasphere = []; EEG.icaweights = []; EEG.icachansind = [];
            location_struct = table2struct(app.locations); EEG.chanlocs = location_struct;
            EEG.urchanlocs = []; EEG.chaninfo = [];
            EEG.event = []; EEG.urevent = []; EEG.epoch = [];
            EEG.reject = []; EEG.specdata = []; EEG.specicaact = [];
            EEG.etc = [];

            % load data
            [filename, filepath] = uigetfile('*.mat');
            if (ischar(filepath))
               file_name_path = [filepath filename];
               data = importdata(file_name_path);
            end
            app.DataFileNameTextArea.Value = file_name_path;

            if isstruct(data)
                rawdata = data.rawdata; % channel X data (matrix)
            else
                rawdata = data;
            end

            try
                if isstruct(data)
                    event_text = data.event_text;
                end
                if class(event_text) == "cell"
                    app.TriggerTextArea.Value = event_text;
                end
            catch
            end

            plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)

            % additional EEG struct
            EEG.nbchan = size(plot_rawdata, 1); EEG.pnts = size(plot_rawdata, 2);
            EEG.data = plot_rawdata; % channel X data (matrix)
            EEG.xmax = (EEG.pnts-1)/EEG.srate;

            % x-axis (window)
            app.EndsSpinner.Limits = [EEG.xmin, EEG.xmax];
            app.EndsSpinner.Value = EEG.xmax;

            app.Slider.Limits = [EEG.xmin EEG.xmax];
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global EEG

            % channel list
            ch_list = transpose(app.locations.labels);

            app.CalculatingLabel.Text = 'Connectivity is calculating...';
            drawnow
            

            %%% Pre-processing
            EEG2 = EEG;

            % filtering
            if app.FilteringCheckBox.Value == 1
                EEG2 = pop_eegfiltnew(EEG2, app.LowCutOffSpinner.Value, app.HighCutOffSpinner.Value, [], app.NotchFilterButton.Value, [], 0);
            end
            
            % common average reference
            if app.CommonAverageReferenceCheckBox.Value == 1
                EEG2 = pop_reref(EEG2,[]); % channel X data (matrix)
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end

            % detrend
            if app.DetrendCheckBox.Value == 1
                detrend_data = detrend(transpose(EEG2.data)); % data X channel (matrix)
                EEG2.data = transpose(detrend_data); % channel X data (matrix)
            end

            % baseline correction
            if app.BaselineCorrectionCheckBox.Value == 1
                if (app.StartsSpinner.Value >= app.BaselineTimesSpinner.Value) && (app.StartsSpinner.Value ~= 0) && (app.BaselineTimesSpinner.Value ~= 0)
                    baseline_data  = EEG2.data(:, (app.StartsSpinner.Value*EEG.srate+1 - app.BaselineTimesSpinner.Value*EEG.srate):app.StartsSpinner.Value*EEG.srate+1); % channel X data (matrix)

                    baseline_mean = mean(baseline_data, 2);
                    corrected_data = EEG2.data - baseline_mean;

                    EEG2.data = corrected_data; % channel X data (matrix)
                end
            end

            % ICA -> artifacts removal
            if app.ICAArtifactsRemovalCheckBox.Value == 1
                brain_value = [NaN NaN];
                other_value = [NaN NaN];

                if app.MuscleButton.Value == 1
                    muscle_value = [app.MuscleEditField.Value/100 1];
                else, muscle_value = [NaN NaN];
                end

                if app.EyeButton.Value == 1
                    eye_value = [app.EyeEditField.Value/100 1];
                else, eye_value = [NaN NaN];
                end

                if app.HeartButton.Value == 1
                    heart_value = [app.HeartEditField.Value/100 1];
                else, heart_value = [NaN NaN];
                end

                if app.LineNoiseButton.Value == 1
                    lineNoise_value = [app.LineNoiseEditField.Value/100 1];
                else, lineNoise_value = [NaN NaN];
                end

                if app.ChannelNoiseButton.Value == 1
                    channelNoise_value = [app.ChannelNoiseEditField.Value/100 1];
                else, channelNoise_value = [NaN NaN];
                end

                EEG2 = pop_runica(EEG2, 'icatype', 'runica', 'extended',1, 'rndreset','yes', 'interrupt','off');
                EEG2 = pop_iclabel(EEG2, 'default');
                EEG2 = pop_icflag(EEG2, [brain_value;muscle_value;eye_value;heart_value;lineNoise_value;channelNoise_value;other_value]);
                EEG2 = pop_subcomp(EEG2, find(EEG2.reject.gcompreject)', 0);
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end

            % time window
            if app.TimeWindowCheckBox.Value == 1
                EEG2.data = EEG2.data(:, app.StartsSpinner.Value*EEG.srate+1:app.EndsSpinner.Value*EEG.srate);
            else
                EEG2.data = EEG2.data(:, app.SlidingWindowEditField.Value*EEG.srate+1:(app.SlidingWindowEditField.Value+app.SizesSpinner.Value)*EEG.srate);
            end
            EEG2.pnts = size(EEG2.data, 2);
            EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;

            plot_data_T = transpose(EEG2.data); % data X channel (matrix)


            %%% Brain Network
            % node to node
            node_to_node = zeros(1,2); node_to_node2 = zeros(1,2);
            node_to_node3 = zeros(1,2); node_to_node4 = zeros(1,2);

            if app.NodetoNodeButton.Value == 1
                if app.EdgeCheckBox.Value == 1
                    node_to_node = [find(strcmp(ch_list, app.NodeDropDown1_1.Value)), find(strcmp(ch_list, app.NodeDropDown1_2.Value))];
                end
                if app.EdgeCheckBox2.Value == 1
                    node_to_node2 = [find(strcmp(ch_list, app.NodeDropDown2_1.Value)), find(strcmp(ch_list, app.NodeDropDown2_2.Value))];
                end
                if app.EdgeCheckBox3.Value == 1
                    node_to_node3 = [find(strcmp(ch_list, app.NodeDropDown3_1.Value)), find(strcmp(ch_list, app.NodeDropDown3_2.Value))];
                end
                if app.EdgeCheckBox4.Value == 1
                    node_to_node4 = [find(strcmp(ch_list, app.NodeDropDown4_1.Value)), find(strcmp(ch_list, app.NodeDropDown4_2.Value))];
                end
            end
            selected_nodes = [node_to_node, node_to_node2, node_to_node3, node_to_node4];
                        
            % frequency band, threshold
            f_min = app.LowRangeSpinner2.Value;
            f_max = app.HighRangeSpinner2.Value;
            threshold = app.ThresholdSpinner.Value;
                        
            % analysis index (Coherence, Correlation, PLI, PLV)
            if app.CoherenceButton.Value == 1
                if app.AllEdgeButton.Value == 1
                    Coher = Coherence(plot_data_T, app.sample_rate, [f_min, f_max]);
                    analysis_index = Coher.Coher;
                elseif app.NodetoNodeButton.Value == 1
                    Coher = Coherence(plot_data_T, app.sample_rate, [f_min, f_max]);
                    ntn_matrix = eye(size(Coher.Coher, 1), size(Coher.Coher, 2));

                    for i = 1:2:length(selected_nodes)
                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                            continue
                        end
                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = Coher.Coher(selected_nodes(i), selected_nodes(i+1));
                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = Coher.Coher(selected_nodes(i+1), selected_nodes(i));
                    end
                    Coher.Coher = ntn_matrix;
                    analysis_index = Coher.Coher;
                end

            elseif app.CorrelationButton.Value == 1
                if app.AllEdgeButton.Value == 1
                    Corr = Correlations(plot_data_T);
                    analysis_index = Corr.Corr;
                elseif app.NodetoNodeButton.Value == 1
                    Corr = Correlations(plot_data_T);
                    ntn_matrix = eye(size(Corr.Corr, 1), size(Corr.Corr, 2));
                    
                    for i = 1:2:length(selected_nodes)
                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                            continue
                        end
                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = Corr.Corr(selected_nodes(i), selected_nodes(i+1));
                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = Corr.Corr(selected_nodes(i+1), selected_nodes(i));
                    end
                    Corr.Corr = ntn_matrix;
                    analysis_index = Corr.Corr;
                end

            elseif app.PLIButton.Value == 1
                if app.AllEdgeButton.Value == 1
                    PLI = PhaseLagIndex(plot_data_T);
                    analysis_index = PLI.PLI;
                elseif app.NodetoNodeButton.Value == 1
                    PLI = PhaseLagIndex(plot_data_T);
                    ntn_matrix = eye(size(PLI.PLI, 1), size(PLI.PLI, 2));
                    
                    for i = 1:2:length(selected_nodes)
                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                            continue
                        end
                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = PLI.PLI(selected_nodes(i), selected_nodes(i+1));
                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = PLI.PLI(selected_nodes(i+1), selected_nodes(i));
                    end
                    PLI.PLI = ntn_matrix;
                    analysis_index = PLI.PLI;
                end
                
            elseif app.PLVButton.Value == 1
                if app.AllEdgeButton.Value == 1
                    PLV = PhaseLockingValue(plot_data_T, app.sample_rate, [f_min, f_max]);
                    analysis_index = PLV.PLV;
                elseif app.NodetoNodeButton.Value == 1
                    PLV = PhaseLockingValue(plot_data_T, app.sample_rate, [f_min, f_max]);
                    ntn_matrix = eye(size(PLV.PLV, 1), size(PLV.PLV, 2));

                    for i = 1:2:length(selected_nodes)
                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                            continue
                        end
                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = PLV.PLV(selected_nodes(i), selected_nodes(i+1));
                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = PLV.PLV(selected_nodes(i+1), selected_nodes(i));
                    end
                    PLV.PLV = ntn_matrix;
                    analysis_index = PLV.PLV;
                end
            end
            if app.AbsoluteValuesCheckBox.Value == 1
                analysis_index = abs(analysis_index);
            end

            app.network = analysis_index;

            if app.save == 0

                % colormap for brain network and matrix
                switch app.ColorMapButtonGroup.SelectedObject.Text
                    case 'jet',colormap(app.MatrixUIAxes,'jet'); colormap(app.PlotConnectivityUIAxes,'jet');
                    case 'hot',colormap(app.MatrixUIAxes,'hot'); colormap(app.PlotConnectivityUIAxes,'hot');
                    case 'parula',colormap(app.MatrixUIAxes,'parula'); colormap(app.PlotConnectivityUIAxes,'parula');
                    case 'turbo',colormap(app.MatrixUIAxes,'turbo'); colormap(app.PlotConnectivityUIAxes,'turbo');
                    case 'gray',colormap(app.MatrixUIAxes,'gray'); colormap(app.PlotConnectivityUIAxes,'gray');
                    case 'bone',colormap(app.MatrixUIAxes,'bone'); colormap(app.PlotConnectivityUIAxes,'bone');
                end
    
                % displayStruct for brain network
                adjMat = analysis_index >= threshold;
                clear ds;
                ds.connectStrength = analysis_index(adjMat);
                [ds.chanPairs(:, 1), ds.chanPairs(:, 2)] = ind2sub(size(adjMat), find(adjMat));
                
                % brain network map
                topoplot_connect(app, ds, EEG.chanlocs);
                colorbar(app.PlotConnectivityUIAxes);
                if app.ColormapRangeCheckBox.Value == 1
                    clim(app.PlotConnectivityUIAxes, [0, 1]);
                else
                    clim(app.PlotConnectivityUIAxes, [-1, 1]);
                end
    
                % matrix
                xticks(app.MatrixUIAxes, 1:size(plot_data_T,2));
                yticks(app.MatrixUIAxes, 1:size(plot_data_T,2));
                xticklabels(app.MatrixUIAxes, transpose(app.locations.labels));
                yticklabels(app.MatrixUIAxes, transpose(app.locations.labels));
                
                % font size (matrix labels)
                ax = app.MatrixUIAxes;
                ax.XAxis.FontSize = 10;
                ax.YAxis.FontSize = 10;
                
                imagesc(app.MatrixUIAxes, analysis_index);
                if app.ColormapRangeCheckBox.Value == 1
                    clim(app.MatrixUIAxes, [0, 1]);
                else
                    clim(app.MatrixUIAxes, [-1, 1]);
                end
                app.MatrixUIAxes.Colormap(end, :) = [1, 1, 1];
    
                if app.ValueDisplayCheckBox.Value == 1
                    for i = 1:size(analysis_index, 1)
                        for j = 1:size(analysis_index, 2)
                            if isnan(analysis_index(i, j))
                                analysis_index(i, j) = 0;
                            end
    
                            text(app.MatrixUIAxes, j, i, num2str(analysis_index(i, j), '%.2f'), ...
                                'HorizontalAlignment', 'center', ...
                                'VerticalAlignment', 'middle', ...
                                'Color', 'k', 'FontSize', 8); % font size (matrix values)
                        end
                    end
                end
                
                app.CalculatingLabel.Text = 'Connectivity calculation done.';
            end

        end

        % Selection changed function: EdgeButtonGroup
        function EdgeButtonGroupSelectionChanged(app, event)
            if app.NodetoNodeButton.Value == true
                app.EdgeCheckBox.Enable = 'on'; app.EdgeCheckBox2.Enable = 'on';
                app.EdgeCheckBox3.Enable = 'on'; app.EdgeCheckBox4.Enable = 'on';
            else
                app.EdgeCheckBox.Value = false; app.EdgeCheckBox2.Value = false;
                app.EdgeCheckBox3.Value = false; app.EdgeCheckBox4.Value = false;
                app.EdgeCheckBox.Enable = 'off'; app.EdgeCheckBox2.Enable = 'off';
                app.EdgeCheckBox3.Enable = 'off'; app.EdgeCheckBox4.Enable = 'off';
            end

        end

        % Selection changed function: ConnectivityMetricButtonGroup
        function ConnectivityMetricButtonGroupSelectionChanged(app, event)
            if app.CorrelationButton.Value == 1
                app.RangeLabel.Enable = "off"; app.WindowLabel2.Enable = "off";
                app.LowRangeSpinner2.Enable = "off"; app.HighRangeSpinner2.Enable = "off";
            elseif app.CoherenceButton.Value == 1
                app.RangeLabel.Enable = "on"; app.WindowLabel2.Enable = "on";
                app.LowRangeSpinner2.Enable = "on"; app.HighRangeSpinner2.Enable = "on";
            elseif app.PLVButton.Value == 1
                app.RangeLabel.Enable = "on"; app.WindowLabel2.Enable = "on";
                app.LowRangeSpinner2.Enable = "on"; app.HighRangeSpinner2.Enable = "on";
            elseif app.PLIButton.Value == 1
                app.RangeLabel.Enable = "off"; app.WindowLabel2.Enable = "off";
                app.LowRangeSpinner2.Enable = "off"; app.HighRangeSpinner2.Enable = "off";
            end
        end

        % Value changed function: TimeWindowCheckBox
        function TimeWindowCheckBoxValueChanged(app, event)
            if app.TimeWindowCheckBox.Value == 1
                app.SlidingWindowCheckBox.Value = 0;
                app.Slider.Enable = 'off';
                app.SlidingWindowEditField.Enable = 'off';
                app.ForwardButton.Enable = 'off';
                app.BackwardButton.Enable = 'off';
                app.SlidingWindowPanel.Enable = 'off';
                app.TimeWindowPanel.Enable = 'on';
            else
                app.SlidingWindowCheckBox.Value = 1;
                app.Slider.Enable = 'on';
                app.SlidingWindowEditField.Enable = 'on';
                app.ForwardButton.Enable = 'on';
                app.BackwardButton.Enable = 'on';
                app.SlidingWindowPanel.Enable = 'on';
                app.TimeWindowPanel.Enable = 'off';
            end
            
        end

        % Value changed function: SlidingWindowCheckBox
        function SlidingWindowCheckBoxValueChanged(app, event)
            if app.SlidingWindowCheckBox.Value == 1
                app.TimeWindowCheckBox.Value = 0;
                app.Slider.Enable = 'on';
                app.SlidingWindowEditField.Enable = 'on';
                app.ForwardButton.Enable = 'on';
                app.BackwardButton.Enable = 'on';
                app.SlidingWindowPanel.Enable = 'on';
                app.TimeWindowPanel.Enable = 'off';
            else
                app.TimeWindowCheckBox.Value = 1;
                app.Slider.Enable = 'off';
                app.SlidingWindowEditField.Enable = 'off';
                app.ForwardButton.Enable = 'off';
                app.BackwardButton.Enable = 'off';
                app.SlidingWindowPanel.Enable = 'off';
                app.TimeWindowPanel.Enable = 'on';
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            rawValue = event.Value;
            
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            snappedValue = round(rawValue / stepSize) * stepSize;
            app.Slider.Value = snappedValue;

            app.SlidingWindowEditField.Value = app.Slider.Value;
            
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            if app.Slider.Value == 0
                PlotButtonPushed(app, event);
            else
                app.Slider.Value = app.Slider.Value - stepSize;
                app.SlidingWindowEditField.Value = app.Slider.Value;
                PlotButtonPushed(app, event);
            end

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            if app.Slider.Value + windowSize + stepSize <= app.Slider.Limits(2)
                app.Slider.Value = app.Slider.Value + stepSize;
                app.SlidingWindowEditField.Value = app.Slider.Value;
                PlotButtonPushed(app, event);
            else
                PlotButtonPushed(app, event);
            end

        end

        % Value changed function: SizesSpinner
        function SizesSpinnerValueChanged(app, event)
            app.Slider.Value = 0;
            app.SlidingWindowEditField.Value = 0;
            
        end

        % Value changed function: OverlapsSpinner
        function OverlapsSpinnerValueChanged(app, event)
            app.Slider.Value = 0;
            app.SlidingWindowEditField.Value = 0;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.save = 1;
            
            if app.CorrelationButton.Value == 1
                connectivity_metric = 'Correlation';
            elseif app.CoherenceButton.Value == 1
                connectivity_metric = 'Coherence';
            elseif app.PLVButton.Value == 1
                connectivity_metric = 'PLV';
            elseif app.PLIButton.Value == 1
                connectivity_metric = 'PLI';
            end

            
            if app.TimeWindowCheckBox.Value == 1
                PlotButtonPushed(app, event);
                
                data = app.network;

                network_info.windowType = 'Time Window';
                network_info.windowSize = [app.StartsSpinner.Value app.EndsSpinner.Value];
                network_info.network = connectivity_metric;
                network_info.data = data;

                uisave({'network_info'},'')

            elseif app.SlidingWindowCheckBox.Value == 1
                total_time = app.Slider.Limits(2);
                sizeWindow = app.SizesSpinner.Value;
                overlap = app.OverlapsSpinner.Value;
                stepSize = sizeWindow - overlap;
                nSteps = floor((total_time - sizeWindow) / stepSize) + 1;

                data = zeros(size(app.locations,1), size(app.locations,1), nSteps);
            
                for i = 1:nSteps
                    currentStart = (i - 1) * stepSize;
                    app.Slider.Value = currentStart;
                    app.SlidingWindowEditField.Value = currentStart;
            
                    PlotButtonPushed(app, event);

                    data(:,:,i) = app.network;
                end

                network_info.windowType = 'Sliding Window';
                network_info.windowSize = sizeWindow;
                network_info.overlapSize = overlap;
                network_info.network = connectivity_metric;
                network_info.data = data;

                uisave({'network_info'},'')
            end

            app.save = 0;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatrixUIAxes = uiaxes(app.UIFigure);
            title(app.MatrixUIAxes, 'Matrix')
            zlabel(app.MatrixUIAxes, 'Z')
            app.MatrixUIAxes.XTick = [];
            app.MatrixUIAxes.YTick = [];
            app.PlotConnectivityUIAxes = uiaxes(app.UIFigure);
            title(app.PlotConnectivityUIAxes, 'Brain Network Map')
            zlabel(app.PlotConnectivityUIAxes, 'Z')
            app.PlotConnectivityUIAxes.XTick = [];
            app.PlotConnectivityUIAxes.YTick = [];
            app.BrainNetworkPanel = uipanel(app.UIFigure);
            app.DataFileNameTextAreaLabel = uilabel(app.BrainNetworkPanel);
            app.DataFileNameTextAreaLabel.Text = 'Data File Name';
            app.DataFileNameTextArea = uitextarea(app.BrainNetworkPanel);
            app.DataFileNameTextArea.Editable = 'off';
            app.LoadDataButton = uibutton(app.BrainNetworkPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ValueDisplayCheckBox = uicheckbox(app.BrainNetworkPanel);
            app.ValueDisplayCheckBox.Text = 'Value Display';
            app.ConnectivityMetricButtonGroup = uibuttongroup(app.BrainNetworkPanel);
            app.ConnectivityMetricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConnectivityMetricButtonGroupSelectionChanged, true);
            app.ConnectivityMetricButtonGroup.Title = 'Connectivity Metric';
            app.CoherenceButton = uiradiobutton(app.ConnectivityMetricButtonGroup);
            app.CoherenceButton.Text = 'Coherence';
            app.CorrelationButton = uiradiobutton(app.ConnectivityMetricButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.PLIButton = uiradiobutton(app.ConnectivityMetricButtonGroup);
            app.PLIButton.Text = 'PLI';
            app.PLVButton = uiradiobutton(app.ConnectivityMetricButtonGroup);
            app.PLVButton.Text = 'PLV';
            app.TriggerTextArea = uitextarea(app.BrainNetworkPanel);
            app.TriggerTextArea.Editable = 'off';
            app.TriggerTextAreaLabel = uilabel(app.BrainNetworkPanel);
            app.TriggerTextAreaLabel.Text = 'Trigger';
            app.TimeWindowPanel = uipanel(app.BrainNetworkPanel);
            app.TimeWindowPanel.Title = 'Time Window';
            app.StartsSpinnerLabel = uilabel(app.TimeWindowPanel);
            app.StartsSpinnerLabel.Text = 'Start (s)';
            app.StartsSpinner = uispinner(app.TimeWindowPanel);
            app.StartsSpinner.Limits = [0 Inf];
            app.EndsSpinnerLabel = uilabel(app.TimeWindowPanel);
            app.EndsSpinnerLabel.Text = 'End (s)';
            app.EndsSpinner = uispinner(app.TimeWindowPanel);
            app.EndsSpinner.Limits = [0 Inf];
            app.EdgeButtonGroup = uibuttongroup(app.BrainNetworkPanel);
            app.EdgeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgeButtonGroupSelectionChanged, true);
            app.EdgeButtonGroup.Title = 'Type of Edge Visualization';
            app.AllEdgeButton = uiradiobutton(app.EdgeButtonGroup);
            app.AllEdgeButton.Text = 'All Edge';
            app.AllEdgeButton.Value = true;
            app.NodetoNodeButton = uiradiobutton(app.EdgeButtonGroup);
            app.NodetoNodeButton.Text = 'Node to Node';
            app.EdgePanel = uipanel(app.BrainNetworkPanel);
            app.EdgePanel.Title = 'Node to Node';
            app.NodeDropDown1_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown1_1.Items = {};
            app.NodeDropDown1_1.Value = {};
            app.NodeDropDown1_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown1_2.Items = {};
            app.NodeDropDown1_2.Value = {};
            app.EdgeCheckBox = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox.Enable = 'off';
            app.EdgeCheckBox.Text = '';
            app.EdgeLabel = uilabel(app.EdgePanel);
            app.EdgeLabel.Text = '↔';
            app.NodeDropDown2_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown2_1.Items = {};
            app.NodeDropDown2_1.Value = {};
            app.NodeDropDown2_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown2_2.Items = {};
            app.NodeDropDown2_2.Value = {};
            app.EdgeCheckBox2 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox2.Enable = 'off';
            app.EdgeCheckBox2.Text = '';
            app.EdgeLabel2 = uilabel(app.EdgePanel);
            app.EdgeLabel2.Text = '↔';
            app.NodeDropDown3_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown3_1.Items = {};
            app.NodeDropDown3_1.Value = {};
            app.NodeDropDown3_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown3_2.Items = {};
            app.NodeDropDown3_2.Value = {};
            app.EdgeCheckBox3 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox3.Enable = 'off';
            app.EdgeCheckBox3.Text = '';
            app.EdgeLabel3 = uilabel(app.EdgePanel);
            app.EdgeLabel3.Text = '↔';
            app.NodeDropDown4_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown4_1.Items = {};
            app.NodeDropDown4_1.Value = {};
            app.NodeDropDown4_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown4_2.Items = {};
            app.NodeDropDown4_2.Value = {};
            app.EdgeCheckBox4 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox4.Enable = 'off';
            app.EdgeCheckBox4.Text = '';
            app.EdgeLabel4 = uilabel(app.EdgePanel);
            app.EdgeLabel4.Text = '↔';
            app.TabGroup = uitabgroup(app.BrainNetworkPanel);
            app.SignalProcessingTab = uitab(app.TabGroup);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.FilteringCheckBox = uicheckbox(app.SignalProcessingTab);
            app.FilteringCheckBox.Text = 'Filtering';
            app.FilteringCheckBox.Value = true;
            app.FilterButtonGroup = uibuttongroup(app.SignalProcessingTab);
            app.BandpassFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.BandpassFilterButton.Value = true;
            app.NotchFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.WindowLabel = uilabel(app.FilterButtonGroup);
            app.WindowLabel.Text = '~';
            app.LowCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.LowCutOffSpinner.Limits = [0 Inf];
            app.LowCutOffSpinner.Value = 4;
            app.HighCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.HighCutOffSpinner.Limits = [0 Inf];
            app.HighCutOffSpinner.Value = 30;
            app.CommonAverageReferenceCheckBox = uicheckbox(app.SignalProcessingTab);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.BaselineCorrectionCheckBox = uicheckbox(app.SignalProcessingTab);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineTimesSpinnerLabel = uilabel(app.SignalProcessingTab);
            app.BaselineTimesSpinnerLabel.Text = '▷ Baseline Time (s)';
            app.BaselineTimesSpinner = uispinner(app.SignalProcessingTab);
            app.BaselineTimesSpinner.Step = 0.1;
            app.BaselineTimesSpinner.Limits = [0 Inf];
            app.BaselineTimesSpinner.ValueDisplayFormat = '%.1f';
            app.DetrendCheckBox = uicheckbox(app.SignalProcessingTab);
            app.DetrendCheckBox.Text = 'Detrend';
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.ICAArtifactsRemovalCheckBox = uicheckbox(app.ICATab);
            app.ICAArtifactsRemovalCheckBox.Text = 'ICA +Artifacts Removal';
            app.MuscleButton = uibutton(app.ICATab, 'state');
            app.MuscleButton.Text = 'Muscle';
            app.MuscleButton.Value = true;
            app.MuscleEditField = uieditfield(app.ICATab, 'numeric');
            app.MuscleEditField.Limits = [0 100];
            app.MuscleEditField.ValueDisplayFormat = '%.0f';
            app.MuscleEditField.Value = 90;
            app.MuscleLabel = uilabel(app.ICATab);
            app.MuscleLabel.Text = '%';
            app.EyeButton = uibutton(app.ICATab, 'state');
            app.EyeButton.Text = 'Eye';
            app.EyeButton.Value = true;
            app.EyeLabel = uilabel(app.ICATab);
            app.EyeLabel.Text = '%';
            app.EyeEditField = uieditfield(app.ICATab, 'numeric');
            app.EyeEditField.Limits = [0 100];
            app.EyeEditField.ValueDisplayFormat = '%.0f';
            app.EyeEditField.Value = 90;
            app.HeartButton = uibutton(app.ICATab, 'state');
            app.HeartButton.Text = 'Heart';
            app.HeartEditField = uieditfield(app.ICATab, 'numeric');
            app.HeartEditField.Limits = [0 100];
            app.HeartEditField.ValueDisplayFormat = '%.0f';
            app.HeartEditField.Value = 90;
            app.MuscleLabel_2 = uilabel(app.ICATab);
            app.MuscleLabel_2.Text = '%';
            app.LineNoiseButton = uibutton(app.ICATab, 'state');
            app.LineNoiseButton.Text = 'Line Noise';
            app.LineNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.LineNoiseEditField.Limits = [0 100];
            app.LineNoiseEditField.ValueDisplayFormat = '%.0f';
            app.LineNoiseEditField.Value = 90;
            app.MuscleLabel_3 = uilabel(app.ICATab);
            app.MuscleLabel_3.Text = '%';
            app.ChannelNoiseButton = uibutton(app.ICATab, 'state');
            app.ChannelNoiseButton.Text = 'Channel Noise';
            app.ChannelNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.ChannelNoiseEditField.Limits = [0 100];
            app.ChannelNoiseEditField.ValueDisplayFormat = '%.0f';
            app.ChannelNoiseEditField.Value = 90;
            app.MuscleLabel_4 = uilabel(app.ICATab);
            app.MuscleLabel_4.Text = '%';
            app.ThresholdPanel = uipanel(app.BrainNetworkPanel);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdSpinner = uispinner(app.ThresholdPanel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.Value = 0.8;
            app.FrequencyBandPanel = uipanel(app.BrainNetworkPanel);
            app.FrequencyBandPanel.Title = 'Frequency Band';
            app.RangeLabel = uilabel(app.FrequencyBandPanel);
            app.RangeLabel.Enable = 'off';
            app.RangeLabel.Text = 'Range:';
            app.WindowLabel2 = uilabel(app.FrequencyBandPanel);
            app.WindowLabel2.Enable = 'off';
            app.WindowLabel2.Text = '~';
            app.LowRangeSpinner2 = uispinner(app.FrequencyBandPanel);
            app.LowRangeSpinner2.Limits = [0 Inf];
            app.LowRangeSpinner2.Enable = 'off';
            app.LowRangeSpinner2.Value = 1;
            app.HighRangeSpinner2 = uispinner(app.FrequencyBandPanel);
            app.HighRangeSpinner2.Limits = [0 Inf];
            app.HighRangeSpinner2.Enable = 'off';
            app.HighRangeSpinner2.Value = 4;
            app.ColormapRangePanel = uipanel(app.BrainNetworkPanel);
            app.ColormapRangePanel.Title = 'Colormap Range';
            app.AbsoluteValuesCheckBox = uicheckbox(app.ColormapRangePanel);
            app.AbsoluteValuesCheckBox.Text = 'Absolute Values';
            app.ColormapRangeCheckBox = uicheckbox(app.ColormapRangePanel);
            app.ColormapRangeCheckBox.Text = 'Range [0 1] <= [-1 1]';
            app.ColormapRangeCheckBox.Value = true;
            app.Slider = uislider(app.BrainNetworkPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.SlidingWindowPanel = uipanel(app.BrainNetworkPanel);
            app.SlidingWindowPanel.Enable = 'off';
            app.SlidingWindowPanel.Title = 'Sliding Window';
            app.SizesSpinnerLabel = uilabel(app.SlidingWindowPanel);
            app.SizesSpinnerLabel.Text = 'Size (s)';
            app.SizesSpinner = uispinner(app.SlidingWindowPanel);
            app.SizesSpinner.Limits = [0 Inf];
            app.SizesSpinner.ValueChangedFcn = createCallbackFcn(app, @SizesSpinnerValueChanged, true);
            app.SizesSpinner.Value = 10;
            app.OverlapsSpinnerLabel = uilabel(app.SlidingWindowPanel);
            app.OverlapsSpinnerLabel.Text = 'Overlap (s)';
            app.OverlapsSpinner = uispinner(app.SlidingWindowPanel);
            app.OverlapsSpinner.Limits = [0 Inf];
            app.OverlapsSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlapsSpinnerValueChanged, true);
            app.PlotButton = uibutton(app.BrainNetworkPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.BrainNetworkPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TimeWindowCheckBox = uicheckbox(app.BrainNetworkPanel);
            app.TimeWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeWindowCheckBoxValueChanged, true);
            app.TimeWindowCheckBox.Text = '';
            app.TimeWindowCheckBox.Value = true;
            app.SlidingWindowCheckBox = uicheckbox(app.BrainNetworkPanel);
            app.SlidingWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlidingWindowCheckBoxValueChanged, true);
            app.SlidingWindowCheckBox.Text = '';
            app.ForwardButton = uibutton(app.BrainNetworkPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.BrainNetworkPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Enable = 'off';
            app.BackwardButton.Text = 'Backward';
            app.SlidingWindowEditField = uieditfield(app.BrainNetworkPanel, 'numeric');
            app.SlidingWindowEditField.Editable = 'off';
            app.SlidingWindowEditField.Enable = 'off';
            app.CalculatingLabel = uilabel(app.UIFigure);
            app.CalculatingLabel.Text = '';
            app.ColorMapButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.jetButton = uiradiobutton(app.ColorMapButtonGroup);
            app.jetButton.Text = 'jet';
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.turboButton = uiradiobutton(app.ColorMapButtonGroup);
            app.turboButton.Text = 'turbo';
            app.boneButton = uiradiobutton(app.ColorMapButtonGroup);
            app.boneButton.Text = 'bone';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Connectivity_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end