classdef Frequency_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ColorMapButtonGroup             matlab.ui.container.ButtonGroup
        grayButton                      matlab.ui.control.RadioButton
        boneButton                      matlab.ui.control.RadioButton
        turboButton                     matlab.ui.control.RadioButton
        hotButton                       matlab.ui.control.RadioButton
        jetButton                       matlab.ui.control.RadioButton
        parulaButton                    matlab.ui.control.RadioButton
        BrainNetworkPanel               matlab.ui.container.Panel
        SlidingWindowEditField          matlab.ui.control.NumericEditField
        BackwardButton                  matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        SlidingWindowCheckBox           matlab.ui.control.CheckBox
        TimeWindowCheckBox              matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        SlidingWindowPanel              matlab.ui.container.Panel
        OverlapsSpinner                 matlab.ui.control.Spinner
        OverlapsSpinnerLabel            matlab.ui.control.Label
        SizesSpinner                    matlab.ui.control.Spinner
        SizesSpinnerLabel               matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        BrainActivityMapTab             matlab.ui.container.Tab
        WindowLabel4                    matlab.ui.control.Label
        CustomizationLabel              matlab.ui.control.Label
        HighRangeSpinner2               matlab.ui.control.Spinner
        LowRangeSpinner2                matlab.ui.control.Spinner
        TypeofEEGListBoxLabel           matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        AmplitudeLabel                  matlab.ui.control.Label
        FrequencyRangeofValuesButtonGroup  matlab.ui.container.ButtonGroup
        CustomizationButton2            matlab.ui.control.RadioButton
        TypeofEEGButton                 matlab.ui.control.RadioButton
        FrequencyRangeofValuesLabel     matlab.ui.control.Label
        FrequencySpectrumTab            matlab.ui.container.Tab
        AllPanel                        matlab.ui.container.Panel
        XlimSpinner                     matlab.ui.control.Spinner
        XlimSpinner2                    matlab.ui.control.Spinner
        XlimSpinnerLabel                matlab.ui.control.Label
        YlimAutoCheckBox                matlab.ui.control.CheckBox
        YlimSpinner                     matlab.ui.control.Spinner
        YlimSpinnerLabel                matlab.ui.control.Label
        WindowLabel3                    matlab.ui.control.Label
        ByTypeofEEGButtonGroup          matlab.ui.container.ButtonGroup
        HighRangeSpinner                matlab.ui.control.Spinner
        LowRangeSpinner                 matlab.ui.control.Spinner
        WindowLabel2                    matlab.ui.control.Label
        ByTypeofEEGButton               matlab.ui.control.RadioButton
        CustomizationButton             matlab.ui.control.RadioButton
        Tree                            matlab.ui.container.CheckBoxTree
        AllChannelNode                  matlab.ui.container.TreeNode
        TypeofFourierTransformButtonGroup  matlab.ui.container.ButtonGroup
        PowerSpectrumDensityWelchButton  matlab.ui.control.RadioButton
        FourierTransformFFTButton       matlab.ui.control.RadioButton
        YAxisButtonGroup                matlab.ui.container.ButtonGroup
        LogPSDButton                    matlab.ui.control.RadioButton
        PSDButton                       matlab.ui.control.RadioButton
        PowerButton                     matlab.ui.control.RadioButton
        AmplitudeButton                 matlab.ui.control.RadioButton
        FourierOptionPanel              matlab.ui.container.Panel
        WindowOverlapEditFieldLabel     matlab.ui.control.Label
        WindowOverlapEditFieldLabel2    matlab.ui.control.Label
        WindowOverlapEditField          matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowLengthEditFieldLabel2     matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        DataFileNameTextArea            matlab.ui.control.TextArea
        DataFileNameTextAreaLabel       matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        SignalProcessingTab             matlab.ui.container.Tab
        DetrendCheckBox                 matlab.ui.control.CheckBox
        BaselineTimesSpinner            matlab.ui.control.Spinner
        BaselineTimesSpinnerLabel       matlab.ui.control.Label
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        HighCutOffSpinner               matlab.ui.control.Spinner
        LowCutOffSpinner                matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        NotchFilterButton               matlab.ui.control.RadioButton
        BandpassFilterButton            matlab.ui.control.RadioButton
        FilteringCheckBox               matlab.ui.control.CheckBox
        ICATab                          matlab.ui.container.Tab
        MuscleLabel_4                   matlab.ui.control.Label
        ChannelNoiseEditField           matlab.ui.control.NumericEditField
        ChannelNoiseButton              matlab.ui.control.StateButton
        MuscleLabel_3                   matlab.ui.control.Label
        LineNoiseEditField              matlab.ui.control.NumericEditField
        LineNoiseButton                 matlab.ui.control.StateButton
        MuscleLabel_2                   matlab.ui.control.Label
        HeartEditField                  matlab.ui.control.NumericEditField
        HeartButton                     matlab.ui.control.StateButton
        EyeEditField                    matlab.ui.control.NumericEditField
        EyeLabel                        matlab.ui.control.Label
        EyeButton                       matlab.ui.control.StateButton
        MuscleLabel                     matlab.ui.control.Label
        MuscleEditField                 matlab.ui.control.NumericEditField
        MuscleButton                    matlab.ui.control.StateButton
        ICAArtifactsRemovalCheckBox     matlab.ui.control.CheckBox
        TriggerTextAreaLabel            matlab.ui.control.Label
        TriggerTextArea                 matlab.ui.control.TextArea
        TimeWindowPanel                 matlab.ui.container.Panel
        EndsSpinner                     matlab.ui.control.Spinner
        EndsSpinner_2Label              matlab.ui.control.Label
        StartsSpinner                   matlab.ui.control.Spinner
        StartsSpinner_2Label            matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        PlotTopographyUIAxes            matlab.ui.control.UIAxes
        SpectrumAllUIAxes               matlab.ui.control.UIAxes
        SpectrumByTypeofEEGUIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        locations
        sample_rate = 500;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global sample_rate
            global locations

            % app name
            app.UIFigure.Name = 'Brain Activity';
            
            % public properties
            app.locations = locations;
            app.sample_rate = sample_rate;
            
            % channel list (tree and nodes)
            for i = 1:size(locations,1)
                uitreenode(app.AllChannelNode,'Text',locations.labels{i});
            end

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            global EEG

            % EEG struct
            EEG.setname = 'matfile'; EEG.filename = ''; EEG.filepath = ''; EEG.subject = '';
            EEG.nbchan = size(app.locations,1); EEG.srate = app.sample_rate;
            EEG.trials = 1; EEG.pnts = []; EEG.xmin = 0; EEG.xmax = []; EEG.data = [];
            EEG.icaact = []; EEG.icawinv = []; EEG.icasphere = []; EEG.icaweights = []; EEG.icachansind = [];
            location_struct = table2struct(app.locations); EEG.chanlocs = location_struct;
            EEG.urchanlocs = []; EEG.chaninfo = [];
            EEG.event = []; EEG.urevent = []; EEG.epoch = [];
            EEG.reject = []; EEG.specdata = []; EEG.specicaact = [];
            EEG.etc = [];

            % load data
            [filename, filepath] = uigetfile('*.mat');
            if (ischar(filepath))
               file_name_path = [filepath filename];
               data = importdata(file_name_path);
            end
            app.DataFileNameTextArea.Value = file_name_path;
            
            if isstruct(data)
                rawdata = data.rawdata; % channel X data (matrix)
            else
                rawdata = data;
            end

            try
                if isstruct(data)
                    event_text = data.event_text;
                end
                if class(event_text) == "cell"
                    app.TriggerTextArea.Value = event_text;
                end
            catch
            end

            plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)

            % additional EEG struct
            EEG.nbchan = size(plot_rawdata, 1); EEG.pnts = size(plot_rawdata, 2);
            EEG.data = plot_rawdata; % channel X data (matrix)
            EEG.xmax = (EEG.pnts-1)/EEG.srate;
            
            % x-axis (window)
            app.EndsSpinner.Limits = [EEG.xmin, EEG.xmax];
            app.EndsSpinner.Value = EEG.xmax;

            app.Slider.Limits = [EEG.xmin EEG.xmax];

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global EEG

            % channel list
            ch_list = transpose(app.locations.labels);


            %%% Pre-processing
            EEG2 = EEG;

            % filtering
            if app.FilteringCheckBox.Value == 1
                EEG2 = pop_eegfiltnew(EEG2, app.LowCutOffSpinner.Value, app.HighCutOffSpinner.Value, [], app.NotchFilterButton.Value, [], 0);
            end

            % common average reference
            if app.CommonAverageReferenceCheckBox.Value == 1
                EEG2 = pop_reref(EEG2,[]); % channel X data (matrix)
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end

            % detrend
            if app.DetrendCheckBox.Value == 1
                detrend_data = detrend(transpose(EEG2.data)); % data X channel (matrix)
                EEG2.data = transpose(detrend_data); % channel X data (matrix)
            end
            
            % baseline correction
            if app.BaselineCorrectionCheckBox.Value == 1
                if (app.StartsSpinner.Value >= app.BaselineTimesSpinner.Value) && (app.StartsSpinner.Value ~= 0) && (app.BaselineTimesSpinner.Value ~= 0)
                    baseline_data  = EEG2.data(:, (app.StartsSpinner.Value*EEG.srate+1 - app.BaselineTimesSpinner.Value*EEG.srate):app.StartsSpinner.Value*EEG.srate+1); % channel X data (matrix)

                    baseline_mean = mean(baseline_data, 2);
                    corrected_data = EEG2.data - baseline_mean;

                    EEG2.data = corrected_data; % channel X data (matrix)
                end
            end
            
            % ICA -> artifacts removal
            if app.ICAArtifactsRemovalCheckBox.Value == 1
                brain_value = [NaN NaN];
                other_value = [NaN NaN];

                if app.MuscleButton.Value == 1
                    muscle_value = [app.MuscleEditField.Value/100 1];
                else, muscle_value = [NaN NaN];
                end

                if app.EyeButton.Value == 1
                    eye_value = [app.EyeEditField.Value/100 1];
                else, eye_value = [NaN NaN];
                end

                if app.HeartButton.Value == 1
                    heart_value = [app.HeartEditField.Value/100 1];
                else, heart_value = [NaN NaN];
                end

                if app.LineNoiseButton.Value == 1
                    lineNoise_value = [app.LineNoiseEditField.Value/100 1];
                else, lineNoise_value = [NaN NaN];
                end

                if app.ChannelNoiseButton.Value == 1
                    channelNoise_value = [app.ChannelNoiseEditField.Value/100 1];
                else, channelNoise_value = [NaN NaN];
                end

                EEG2 = pop_runica(EEG2, 'icatype', 'runica', 'extended',1, 'rndreset','yes', 'interrupt','off');
                EEG2 = pop_iclabel(EEG2, 'default');
                EEG2 = pop_icflag(EEG2, [brain_value;muscle_value;eye_value;heart_value;lineNoise_value;channelNoise_value;other_value]);
                EEG2 = pop_subcomp(EEG2, find(EEG2.reject.gcompreject)', 0);
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end

            % time window
                        % time window
            if app.TimeWindowCheckBox.Value == 1
                EEG2.data = EEG2.data(:, app.StartsSpinner.Value*EEG.srate+1:app.EndsSpinner.Value*EEG.srate);
            else
                EEG2.data = EEG2.data(:, app.SlidingWindowEditField.Value*EEG.srate+1:(app.SlidingWindowEditField.Value+app.SizesSpinner.Value)*EEG.srate);
            end
            EEG2.pnts = size(EEG2.data, 2);
            EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;

            plot_data_T = transpose(EEG2.data); % data X channel (matrix)
            
            % selected channels
            checkedNodes = app.Tree.CheckedNodes;
            checkedIndices = [];
            for i = 1:length(checkedNodes)
            nodeText = checkedNodes(i).Text;
            idx = find(strcmp(app.locations.labels, nodeText));
            if ~isempty(idx)
                checkedIndices = [checkedIndices, idx];
            end
            end

            % fourier transform algorithm (FFT of Welch)
            if app.FourierTransformFFTButton.Value == 1
                all_fftdata = fft(plot_data_T);
                N = length(all_fftdata(:,1));
                n = 0:N-1;
                f_axis = app.sample_rate*n/N;
                cutoff = ceil(N/2);
                all_fftdata = all_fftdata(1:cutoff,:);
                f_axis = f_axis(1:cutoff);

                % amplitude or power
                if app.AmplitudeButton.Value == 1
                    all_fftdata = (1/N)*2*abs(all_fftdata); % all channel
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                elseif app.PowerButton.Value == 1
                    all_fftdata = (1/N^2)*2*abs(all_fftdata).^2; % all channel
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                end

            elseif app.PowerSpectrumDensityWelchButton.Value == 1
                % welch method parameters
                W_length = round(app.WindowLengthEditField.Value*app.sample_rate);
                W = hamming(W_length);
                NoverLap = floor(app.WindowOverlapEditField.Value/100 * W_length);
                nFFT = 2^nextpow2(W_length*2);

                % amplitude or power or psd or log(psd)
                if app.AmplitudeButton.Value == 1
                    [all_fftdata, f_axis] = pwelch(plot_data_T,W,NoverLap,nFFT,app.sample_rate,'power'); % all channel
                    all_fftdata = sqrt(2*all_fftdata);
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                elseif app.PowerButton.Value == 1
                    [all_fftdata, f_axis] = pwelch(plot_data_T,W,NoverLap,nFFT,app.sample_rate,'power'); % all channel
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                elseif app.PSDButton.Value == 1
                    [all_fftdata, f_axis] = pwelch(plot_data_T,W,NoverLap,nFFT,app.sample_rate,'psd'); % all channel
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                elseif app.LogPSDButton.Value == 1
                    [all_fftdata, f_axis] = pwelch(plot_data_T,W,NoverLap,nFFT,app.sample_rate,'psd'); % all channel
                    all_fftdata = pow2db(all_fftdata);
                    fftdata = all_fftdata(:, checkedIndices); % selected channel
                end
            end

            % frequency spectrum (by type of EEG)
            delta = mean(mean(fftdata((f_axis >= 1) & (f_axis <= 3),:)));
            theta = mean(mean(fftdata((f_axis >= 4) & (f_axis <= 8),:)));
            alpha = mean(mean(fftdata((f_axis >= 8) & (f_axis <= 13),:)));
            smr = mean(mean(fftdata((f_axis >= 13) & (f_axis <= 15),:)));
            beta = mean(mean(fftdata((f_axis >= 13) & (f_axis <= 30),:)));
            highbeta = mean(mean(fftdata((f_axis >= 22) & (f_axis <= 30),:)));
            gamma = mean(mean(fftdata((f_axis >= 30) & (f_axis <= 50),:)));
            cus_band = mean(mean(fftdata((f_axis >= app.LowRangeSpinner.Value) & (f_axis <= app.HighRangeSpinner.Value),:)));

            % by type of EEG or all
            if app.ByTypeofEEGButton.Value == 1
                bar(app.SpectrumByTypeofEEGUIAxes, 1:7, [delta, theta, alpha, smr, beta, highbeta, gamma], 'FaceColor',[.5 .5 .5], 'LineWidth',.5);
                xticklabels(app.SpectrumByTypeofEEGUIAxes, {'Delta', 'Theta', 'Alpha', 'Smr', 'Beta', 'High Beta', 'Gamma'});
                xlim(app.SpectrumByTypeofEEGUIAxes, [-0.2    8.2]); set(app.SpectrumByTypeofEEGUIAxes ,'ytick', []);
                ax = app.SpectrumByTypeofEEGUIAxes;
                ax.XAxis.FontSize = 8;
                
                selected_fftdata_values = [delta, theta, alpha, smr, beta, highbeta, gamma];
                for i = 1:length(selected_fftdata_values)
                    text(app.SpectrumByTypeofEEGUIAxes, i, selected_fftdata_values(i), num2str(selected_fftdata_values(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                end
            elseif app.CustomizationButton.Value == 1
                bar(app.SpectrumByTypeofEEGUIAxes, 1, cus_band, 'FaceColor',[.5 .5 .5], 'LineWidth',.5)
                xlim(app.SpectrumByTypeofEEGUIAxes, [0   2]); set(app.SpectrumByTypeofEEGUIAxes ,'ytick', []);
                xticklabels(app.SpectrumByTypeofEEGUIAxes, {strcat(num2str(app.LowRangeSpinner.Value),'-',num2str(app.HighRangeSpinner.Value),'Hz')});
                text(app.SpectrumByTypeofEEGUIAxes, 1, cus_band, num2str(cus_band), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            end
            
            % frequency spectrum (all)
            plot(app.SpectrumAllUIAxes, f_axis, fftdata)
            xticks(app.SpectrumAllUIAxes, linspace(app.XlimSpinner.Value, app.XlimSpinner2.Value, 5));
            xtick_labels = cellstr(num2str(round(linspace(app.XlimSpinner.Value, app.XlimSpinner2.Value, 5), 1)'));
            xticklabels(app.SpectrumAllUIAxes, xtick_labels);
            yticks(app.SpectrumAllUIAxes, 'auto'); yticklabels(app.SpectrumAllUIAxes, 'auto')
            ylim(app.SpectrumAllUIAxes, 'auto');
            xlim(app.SpectrumAllUIAxes,[app.XlimSpinner.Value app.XlimSpinner2.Value])

            if app.YlimAutoCheckBox.Value == 1
                ylim(app.SpectrumAllUIAxes, [0 app.YlimSpinner.Value]);
            end

            %%% Brain Activity
            % type of EEG or customization
            if app.LogPSDButton.Value == 1
                all_fftdata2 = db2pow(all_fftdata);
            elseif app.LogPSDButton.Value == 0
                all_fftdata2 = all_fftdata;
            end

            if app.TypeofEEGButton.Value == 1
                switch app.ChannelDropDown.Value
                    case 'Delta', activity_values = mean(all_fftdata2((f_axis >= 1) & (f_axis <= 3),:), 1);
                    case 'Theta', activity_values = mean(all_fftdata2((f_axis >= 4) & (f_axis <= 8),:), 1);
                    case 'Alpha', activity_values = mean(all_fftdata2((f_axis >= 8) & (f_axis <= 13),:), 1);
                    case 'Smr', activity_values = mean(all_fftdata2((f_axis >= 13) & (f_axis <= 15),:), 1);
                    case 'Beta', activity_values = mean(all_fftdata2((f_axis >= 13) & (f_axis <= 30),:), 1);
                    case 'High Beta', activity_values = mean(all_fftdata2((f_axis >= 22) & (f_axis <= 30),:), 1);
                    case 'Gamma', activity_values = mean(all_fftdata2((f_axis >= 30) & (f_axis <= 50),:), 1);
                end
            elseif app.CustomizationButton2.Value == 1
                activity_values = mean(all_fftdata2((f_axis >= app.LowRangeSpinner2.Value) & (f_axis <= app.HighRangeSpinner2.Value),:), 1);
            end

            % normalization
            activity_values = (activity_values-min(activity_values))/(max(activity_values)-min(activity_values));
            
            % colormap for brain activity
            switch app.ColorMapButtonGroup.SelectedObject.Text
                case 'jet',colormap(app.PlotTopographyUIAxes,'jet'); case 'hot',colormap(app.PlotTopographyUIAxes,'hot');
                case 'parula',colormap(app.PlotTopographyUIAxes,'parula'); case 'turbo',colormap(app.PlotTopographyUIAxes,'turbo');
                case 'gray',colormap(app.PlotTopographyUIAxes,'gray'); case 'bone',colormap(app.PlotTopographyUIAxes,'bone');
            end

            % brain activity map
            plot_topography(app, ch_list, activity_values, true, app.locations, false, true, 200);
            colorbar(app.PlotTopographyUIAxes);
            clim(app.PlotTopographyUIAxes, [0, 1]);

        end

        % Selection changed function: ByTypeofEEGButtonGroup
        function ByTypeofEEGButtonGroupSelectionChanged(app, event)
            if app.CustomizationButton.Value == 1
                app.LowRangeSpinner.Enable = "on"; app.HighRangeSpinner.Enable = "on";
            elseif app.ByTypeofEEGButton.Value == 1
                app.LowRangeSpinner.Enable = "off"; app.HighRangeSpinner.Enable = "off";
            end

        end

        % Selection changed function: FrequencyRangeofValuesButtonGroup
        function FrequencyRangeofValuesButtonGroupSelectionChanged(app, event)
            if app.CustomizationButton2.Value == 1
                app.LowRangeSpinner2.Enable = "on"; app.HighRangeSpinner2.Enable = "on";
                app.ChannelDropDown.Enable = "off";
            elseif app.TypeofEEGButton.Value == 1
                app.LowRangeSpinner2.Enable = "off"; app.HighRangeSpinner2.Enable = "off";
                app.ChannelDropDown.Enable = "on";
            end
            
        end

        % Selection changed function: TypeofFourierTransformButtonGroup
        function TypeofFourierTransformButtonGroupSelectionChanged(app, event)
            if app.FourierTransformFFTButton.Value == 1
                app.PSDButton.Enable = "off";
                app.LogPSDButton.Enable = "off";
                app.WindowOverlapEditFieldLabel.Enable = "off";
                app.WindowLengthEditFieldLabel.Enable = "off";
                app.WindowOverlapEditFieldLabel2.Enable = "off";
                app.WindowOverlapEditField.Enable = "off";
                app.WindowLengthEditFieldLabel2.Enable = "off";
                app.WindowLengthEditField.Enable = "off";
                if app.PSDButton.Value == 1 || app.LogPSDButton.Value == 1
                    app.AmplitudeButton.Value = 1;
                    app.AmplitudeLabel.Text = "(Amplitude)";
                    app.AmplitudeLabel.FontColor = [0.07,0.62,1.00];
                    ylabel(app.SpectrumByTypeofEEGUIAxes, 'Amplitude (µV)')
                    ylabel(app.SpectrumAllUIAxes, 'Amplitude (µV)')
                end
            elseif app.PowerSpectrumDensityWelchButton.Value == 1
                app.PSDButton.Enable = "on";
                app.LogPSDButton.Enable = "on";
                app.WindowOverlapEditFieldLabel.Enable = "on";
                app.WindowLengthEditFieldLabel.Enable = "on";
                app.WindowOverlapEditFieldLabel2.Enable = "on";
                app.WindowOverlapEditField.Enable = "on";
                app.WindowLengthEditFieldLabel2.Enable = "on";
                app.WindowLengthEditField.Enable = "on";
            end

        end

        % Selection changed function: YAxisButtonGroup
        function YAxisButtonGroupSelectionChanged2(app, event)
            if app.AmplitudeButton.Value == 1
                app.AmplitudeLabel.Text = "(Amplitude)";
                app.AmplitudeLabel.FontColor = [0.07,0.62,1.00];
                ylabel(app.SpectrumByTypeofEEGUIAxes, 'Amplitude (µV)')
                ylabel(app.SpectrumAllUIAxes, 'Amplitude (µV)')
            elseif app.PowerButton.Value == 1
                app.AmplitudeLabel.Text = "(Power)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.SpectrumByTypeofEEGUIAxes, 'Power (µV^2)')
                ylabel(app.SpectrumAllUIAxes, 'Power (µV^2)')
            elseif app.PSDButton.Value == 1
                app.AmplitudeLabel.Text = "(PSD)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.SpectrumByTypeofEEGUIAxes, 'PSD (µV^2/Hz)')
                ylabel(app.SpectrumAllUIAxes, 'PSD (µV^2/Hz)')
            elseif app.LogPSDButton.Value == 1
                app.AmplitudeLabel.Text = "(PSD)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.SpectrumByTypeofEEGUIAxes, 'Log PSD 10*log_{10} (µV^2/Hz)')
                ylabel(app.SpectrumAllUIAxes, 'Log PSD 10*log_{10} (µV^2/Hz)')
            end

        end

        % Value changed function: TimeWindowCheckBox
        function TimeWindowCheckBoxValueChanged(app, event)
            if app.TimeWindowCheckBox.Value == 1
                app.SlidingWindowCheckBox.Value = 0;
                app.Slider.Enable = 'off';
                app.SlidingWindowEditField.Enable = 'off';
                app.ForwardButton.Enable = 'off';
                app.BackwardButton.Enable = 'off';
                app.SlidingWindowPanel.Enable = 'off';
                app.TimeWindowPanel.Enable = 'on';
            else
                app.SlidingWindowCheckBox.Value = 1;
                app.Slider.Enable = 'on';
                app.SlidingWindowEditField.Enable = 'on';
                app.ForwardButton.Enable = 'on';
                app.BackwardButton.Enable = 'on';
                app.SlidingWindowPanel.Enable = 'on';
                app.TimeWindowPanel.Enable = 'off';
            end
            
        end

        % Value changed function: SlidingWindowCheckBox
        function SlidingWindowCheckBoxValueChanged(app, event)
            if app.SlidingWindowCheckBox.Value == 1
                app.TimeWindowCheckBox.Value = 0;
                app.Slider.Enable = 'on';
                app.SlidingWindowEditField.Enable = 'on';
                app.ForwardButton.Enable = 'on';
                app.BackwardButton.Enable = 'on';
                app.SlidingWindowPanel.Enable = 'on';
                app.TimeWindowPanel.Enable = 'off';
            else
                app.TimeWindowCheckBox.Value = 1;
                app.Slider.Enable = 'off';
                app.SlidingWindowEditField.Enable = 'off';
                app.ForwardButton.Enable = 'off';
                app.BackwardButton.Enable = 'off';
                app.SlidingWindowPanel.Enable = 'off';
                app.TimeWindowPanel.Enable = 'on';
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            rawValue = event.Value;
            
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            snappedValue = round(rawValue / stepSize) * stepSize;
            app.Slider.Value = snappedValue;

            app.SlidingWindowEditField.Value = app.Slider.Value;
            
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            if app.Slider.Value == 0
                PlotButtonPushed(app, event);
            else
                app.Slider.Value = app.Slider.Value - stepSize;
                app.SlidingWindowEditField.Value = app.Slider.Value;
                PlotButtonPushed(app, event);
            end

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            windowSize = app.SizesSpinner.Value;
            overlap = app.OverlapsSpinner.Value;
            stepSize = windowSize - overlap;

            if app.Slider.Value + windowSize + stepSize <= app.Slider.Limits(2)
                app.Slider.Value = app.Slider.Value + stepSize;
                app.SlidingWindowEditField.Value = app.Slider.Value;
                PlotButtonPushed(app, event);
            else
                PlotButtonPushed(app, event);
            end

        end

        % Value changed function: SizesSpinner
        function SizesSpinnerValueChanged(app, event)
            app.Slider.Value = 0;
            app.SlidingWindowEditField.Value = 0;
            
        end

        % Value changed function: OverlapsSpinner
        function OverlapsSpinnerValueChanged(app, event)
            app.Slider.Value = 0;
            app.SlidingWindowEditField.Value = 0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrumByTypeofEEGUIAxes = uiaxes(app.UIFigure);
            title(app.SpectrumByTypeofEEGUIAxes, 'Frequency Spectrum (By Type of EEG)')
            xlabel(app.SpectrumByTypeofEEGUIAxes, 'Hz')
            ylabel(app.SpectrumByTypeofEEGUIAxes, 'Amplitude (uV)')
            app.SpectrumByTypeofEEGUIAxes.XTick = [];
            app.SpectrumByTypeofEEGUIAxes.XTickLabel = '';
            app.SpectrumByTypeofEEGUIAxes.YTick = [];
            app.SpectrumAllUIAxes = uiaxes(app.UIFigure);
            title(app.SpectrumAllUIAxes, 'Frequency Spectrum (All)')
            xlabel(app.SpectrumAllUIAxes, 'Hz')
            ylabel(app.SpectrumAllUIAxes, 'Amplitude (uV)')
            zlabel(app.SpectrumAllUIAxes, 'Z')
            app.SpectrumAllUIAxes.XTick = [];
            app.SpectrumAllUIAxes.XTickLabel = '';
            app.SpectrumAllUIAxes.YTick = [];
            app.SpectrumAllUIAxes.YTickLabel = '';
            app.PlotTopographyUIAxes = uiaxes(app.UIFigure);
            title(app.PlotTopographyUIAxes, 'Brain Activity Map')
            zlabel(app.PlotTopographyUIAxes, 'Z')
            app.PlotTopographyUIAxes.XTick = [];
            app.PlotTopographyUIAxes.YTick = [];
            app.BrainNetworkPanel = uipanel(app.UIFigure);
            app.LoadDataButton = uibutton(app.BrainNetworkPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TimeWindowPanel = uipanel(app.BrainNetworkPanel);
            app.TimeWindowPanel.Title = 'Time Window';
            app.StartsSpinner_2Label = uilabel(app.TimeWindowPanel);
            app.StartsSpinner_2Label.Text = 'Start (s)';
            app.StartsSpinner = uispinner(app.TimeWindowPanel);
            app.StartsSpinner.Limits = [0 Inf];
            app.EndsSpinner_2Label = uilabel(app.TimeWindowPanel);
            app.EndsSpinner_2Label.Text = 'End (s)';
            app.EndsSpinner = uispinner(app.TimeWindowPanel);
            app.EndsSpinner.Limits = [0 Inf];
            app.TriggerTextArea = uitextarea(app.BrainNetworkPanel);
            app.TriggerTextArea.Editable = 'off';
            app.TriggerTextAreaLabel = uilabel(app.BrainNetworkPanel);
            app.TriggerTextAreaLabel.Text = 'Trigger';
            app.TabGroup_2 = uitabgroup(app.BrainNetworkPanel);
            app.SignalProcessingTab = uitab(app.TabGroup_2);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.FilteringCheckBox = uicheckbox(app.SignalProcessingTab);
            app.FilteringCheckBox.Text = 'Filtering';
            app.FilteringCheckBox.Value = true;
            app.FilterButtonGroup = uibuttongroup(app.SignalProcessingTab);
            app.BandpassFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.BandpassFilterButton.Value = true;
            app.NotchFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.WindowLabel = uilabel(app.FilterButtonGroup);
            app.WindowLabel.Text = '~';
            app.LowCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.LowCutOffSpinner.Limits = [0 Inf];
            app.LowCutOffSpinner.Value = 4;
            app.HighCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.HighCutOffSpinner.Limits = [0 Inf];
            app.HighCutOffSpinner.Value = 30;
            app.CommonAverageReferenceCheckBox = uicheckbox(app.SignalProcessingTab);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.BaselineCorrectionCheckBox = uicheckbox(app.SignalProcessingTab);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineTimesSpinnerLabel = uilabel(app.SignalProcessingTab);
            app.BaselineTimesSpinnerLabel.Text = '▷ Baseline Time (s)';
            app.BaselineTimesSpinner = uispinner(app.SignalProcessingTab);
            app.BaselineTimesSpinner.Step = 0.1;
            app.BaselineTimesSpinner.Limits = [0 Inf];
            app.BaselineTimesSpinner.ValueDisplayFormat = '%.1f';
            app.DetrendCheckBox = uicheckbox(app.SignalProcessingTab);
            app.DetrendCheckBox.Text = 'Detrend';
            app.ICATab = uitab(app.TabGroup_2);
            app.ICATab.Title = 'ICA';
            app.ICAArtifactsRemovalCheckBox = uicheckbox(app.ICATab);
            app.ICAArtifactsRemovalCheckBox.Text = 'ICA +Artifacts Removal';
            app.MuscleButton = uibutton(app.ICATab, 'state');
            app.MuscleButton.Text = 'Muscle';
            app.MuscleButton.Value = true;
            app.MuscleEditField = uieditfield(app.ICATab, 'numeric');
            app.MuscleEditField.Limits = [0 100];
            app.MuscleEditField.ValueDisplayFormat = '%.0f';
            app.MuscleEditField.Value = 90;
            app.MuscleLabel = uilabel(app.ICATab);
            app.MuscleLabel.Text = '%';
            app.EyeButton = uibutton(app.ICATab, 'state');
            app.EyeButton.Text = 'Eye';
            app.EyeButton.Value = true;
            app.EyeLabel = uilabel(app.ICATab);
            app.EyeLabel.Text = '%';
            app.EyeEditField = uieditfield(app.ICATab, 'numeric');
            app.EyeEditField.Limits = [0 100];
            app.EyeEditField.ValueDisplayFormat = '%.0f';
            app.EyeEditField.Value = 90;
            app.HeartButton = uibutton(app.ICATab, 'state');
            app.HeartButton.Text = 'Heart';
            app.HeartEditField = uieditfield(app.ICATab, 'numeric');
            app.HeartEditField.Limits = [0 100];
            app.HeartEditField.ValueDisplayFormat = '%.0f';
            app.HeartEditField.Value = 90;
            app.MuscleLabel_2 = uilabel(app.ICATab);
            app.MuscleLabel_2.Text = '%';
            app.LineNoiseButton = uibutton(app.ICATab, 'state');
            app.LineNoiseButton.Text = 'Line Noise';
            app.LineNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.LineNoiseEditField.Limits = [0 100];
            app.LineNoiseEditField.ValueDisplayFormat = '%.0f';
            app.LineNoiseEditField.Value = 90;
            app.MuscleLabel_3 = uilabel(app.ICATab);
            app.MuscleLabel_3.Text = '%';
            app.ChannelNoiseButton = uibutton(app.ICATab, 'state');
            app.ChannelNoiseButton.Text = 'Channel Noise';
            app.ChannelNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.ChannelNoiseEditField.Limits = [0 100];
            app.ChannelNoiseEditField.ValueDisplayFormat = '%.0f';
            app.ChannelNoiseEditField.Value = 90;
            app.MuscleLabel_4 = uilabel(app.ICATab);
            app.MuscleLabel_4.Text = '%';
            app.DataFileNameTextAreaLabel = uilabel(app.BrainNetworkPanel);
            app.DataFileNameTextAreaLabel.Text = 'Data File Name';
            app.DataFileNameTextArea = uitextarea(app.BrainNetworkPanel);
            app.DataFileNameTextArea.Editable = 'off';
            app.FourierOptionPanel = uipanel(app.BrainNetworkPanel);
            app.FourierOptionPanel.Title = 'Fourier Option';
            app.WindowLengthEditField = uieditfield(app.FourierOptionPanel, 'numeric');
            app.WindowLengthEditField.Enable = 'off';
            app.WindowLengthEditField.Value = 2;
            app.WindowLengthEditFieldLabel2 = uilabel(app.FourierOptionPanel);
            app.WindowLengthEditFieldLabel2.Enable = 'off';
            app.WindowLengthEditFieldLabel2.Text = 's';
            app.WindowLengthEditFieldLabel = uilabel(app.FourierOptionPanel);
            app.WindowLengthEditFieldLabel.Enable = 'off';
            app.WindowLengthEditFieldLabel.Text = 'Window Length:';
            app.WindowOverlapEditField = uieditfield(app.FourierOptionPanel, 'numeric');
            app.WindowOverlapEditField.Enable = 'off';
            app.WindowOverlapEditField.Value = 50;
            app.WindowOverlapEditFieldLabel2 = uilabel(app.FourierOptionPanel);
            app.WindowOverlapEditFieldLabel2.Enable = 'off';
            app.WindowOverlapEditFieldLabel2.Text = '%';
            app.WindowOverlapEditFieldLabel = uilabel(app.FourierOptionPanel);
            app.WindowOverlapEditFieldLabel.Enable = 'off';
            app.WindowOverlapEditFieldLabel.Text = 'Window Overlap:';
            app.YAxisButtonGroup = uibuttongroup(app.BrainNetworkPanel);
            app.YAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisButtonGroupSelectionChanged2, true);
            app.YAxisButtonGroup.Title = 'Y-Axis';
            app.AmplitudeButton = uiradiobutton(app.YAxisButtonGroup);
            app.AmplitudeButton.Text = 'Amplitude';
            app.AmplitudeButton.Value = true;
            app.PowerButton = uiradiobutton(app.YAxisButtonGroup);
            app.PowerButton.Text = 'Power';
            app.PSDButton = uiradiobutton(app.YAxisButtonGroup);
            app.PSDButton.Enable = 'off';
            app.PSDButton.Text = 'PSD';
            app.LogPSDButton = uiradiobutton(app.YAxisButtonGroup);
            app.LogPSDButton.Enable = 'off';
            app.LogPSDButton.Text = 'Log(PSD)';
            app.TypeofFourierTransformButtonGroup = uibuttongroup(app.BrainNetworkPanel);
            app.TypeofFourierTransformButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofFourierTransformButtonGroupSelectionChanged, true);
            app.TypeofFourierTransformButtonGroup.Title = 'Type of Fourier Transform';
            app.FourierTransformFFTButton = uiradiobutton(app.TypeofFourierTransformButtonGroup);
            app.FourierTransformFFTButton.Text = 'Fourier Transform (FFT)';
            app.FourierTransformFFTButton.Value = true;
            app.PowerSpectrumDensityWelchButton = uiradiobutton(app.TypeofFourierTransformButtonGroup);
            app.PowerSpectrumDensityWelchButton.Text = 'Power Spectrum Density (Welch)';
            app.Tree = uitree(app.BrainNetworkPanel, 'checkbox');
            app.AllChannelNode = uitreenode(app.Tree);
            app.AllChannelNode.Text = 'All Channel';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.AllChannelNode];
            app.TabGroup = uitabgroup(app.BrainNetworkPanel);
            app.BrainActivityMapTab = uitab(app.TabGroup);
            app.BrainActivityMapTab.Title = 'Brain Activity Map';
            app.FrequencyRangeofValuesLabel = uilabel(app.BrainActivityMapTab);
            app.FrequencyRangeofValuesLabel.Text = 'Frequency Range of Values';
            app.FrequencyRangeofValuesButtonGroup = uibuttongroup(app.BrainActivityMapTab);
            app.FrequencyRangeofValuesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyRangeofValuesButtonGroupSelectionChanged, true);
            app.TypeofEEGButton = uiradiobutton(app.FrequencyRangeofValuesButtonGroup);
            app.TypeofEEGButton.Text = 'Type of EEG';
            app.TypeofEEGButton.Value = true;
            app.CustomizationButton2 = uiradiobutton(app.FrequencyRangeofValuesButtonGroup);
            app.CustomizationButton2.Text = 'Customization';
            app.AmplitudeLabel = uilabel(app.BrainActivityMapTab);
            app.AmplitudeLabel.Text = '(Amplitude)';
            app.ChannelDropDown = uidropdown(app.BrainActivityMapTab);
            app.ChannelDropDown.Items = {'Delta', 'Theta', 'Alpha', 'Smr', 'Beta', 'High Beta', 'Gamma'};
            app.ChannelDropDown.Value = 'Delta';
            app.TypeofEEGListBoxLabel = uilabel(app.BrainActivityMapTab);
            app.TypeofEEGListBoxLabel.Text = 'Type of EEG';
            app.LowRangeSpinner2 = uispinner(app.BrainActivityMapTab);
            app.LowRangeSpinner2.Limits = [0 Inf];
            app.LowRangeSpinner2.Enable = 'off';
            app.LowRangeSpinner2.Value = 1;
            app.HighRangeSpinner2 = uispinner(app.BrainActivityMapTab);
            app.HighRangeSpinner2.Limits = [0 Inf];
            app.HighRangeSpinner2.Enable = 'off';
            app.HighRangeSpinner2.Value = 11;
            app.CustomizationLabel = uilabel(app.BrainActivityMapTab);
            app.CustomizationLabel.Text = 'Customization';
            app.WindowLabel4 = uilabel(app.BrainActivityMapTab);
            app.WindowLabel4.Text = '~';
            app.FrequencySpectrumTab = uitab(app.TabGroup);
            app.FrequencySpectrumTab.Title = 'Frequency Spectrum';
            app.ByTypeofEEGButtonGroup = uibuttongroup(app.FrequencySpectrumTab);
            app.ByTypeofEEGButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ByTypeofEEGButtonGroupSelectionChanged, true);
            app.ByTypeofEEGButtonGroup.Title = 'By Type of EEG';
            app.CustomizationButton = uiradiobutton(app.ByTypeofEEGButtonGroup);
            app.CustomizationButton.Text = 'Customization';
            app.ByTypeofEEGButton = uiradiobutton(app.ByTypeofEEGButtonGroup);
            app.ByTypeofEEGButton.Text = 'By Type of EEG';
            app.ByTypeofEEGButton.Value = true;
            app.WindowLabel2 = uilabel(app.ByTypeofEEGButtonGroup);
            app.WindowLabel2.Text = '~';
            app.LowRangeSpinner = uispinner(app.ByTypeofEEGButtonGroup);
            app.LowRangeSpinner.Limits = [0 Inf];
            app.LowRangeSpinner.Enable = 'off';
            app.LowRangeSpinner.Value = 4;
            app.HighRangeSpinner = uispinner(app.ByTypeofEEGButtonGroup);
            app.HighRangeSpinner.Limits = [0 Inf];
            app.HighRangeSpinner.Enable = 'off';
            app.HighRangeSpinner.Value = 30;
            app.AllPanel = uipanel(app.FrequencySpectrumTab);
            app.AllPanel.Title = 'All';
            app.WindowLabel3 = uilabel(app.AllPanel);
            app.WindowLabel3.Text = '~';
            app.YlimSpinnerLabel = uilabel(app.AllPanel);
            app.YlimSpinnerLabel.Text = 'Ylim';
            app.YlimSpinner = uispinner(app.AllPanel);
            app.YlimSpinner.Limits = [0 Inf];
            app.YlimSpinner.Value = 100;
            app.YlimAutoCheckBox = uicheckbox(app.AllPanel);
            app.YlimAutoCheckBox.Text = '';
            app.XlimSpinnerLabel = uilabel(app.AllPanel);
            app.XlimSpinnerLabel.Text = 'Xlim';
            app.XlimSpinner2 = uispinner(app.AllPanel);
            app.XlimSpinner2.Limits = [0 Inf];
            app.XlimSpinner2.Value = 30;
            app.XlimSpinner = uispinner(app.AllPanel);
            app.XlimSpinner.Limits = [0 Inf];
            app.Slider = uislider(app.BrainNetworkPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.SlidingWindowPanel = uipanel(app.BrainNetworkPanel);
            app.SlidingWindowPanel.Enable = 'off';
            app.SlidingWindowPanel.Title = 'Sliding Window';
            app.SizesSpinnerLabel = uilabel(app.SlidingWindowPanel);
            app.SizesSpinnerLabel.Text = 'Size (s)';
            app.SizesSpinner = uispinner(app.SlidingWindowPanel);
            app.SizesSpinner.Limits = [0 Inf];
            app.SizesSpinner.ValueChangedFcn = createCallbackFcn(app, @SizesSpinnerValueChanged, true);
            app.SizesSpinner.Value = 10;
            app.OverlapsSpinnerLabel = uilabel(app.SlidingWindowPanel);
            app.OverlapsSpinnerLabel.Text = 'Overlap (s)';
            app.OverlapsSpinner = uispinner(app.SlidingWindowPanel);
            app.OverlapsSpinner.Limits = [0 Inf];
            app.OverlapsSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlapsSpinnerValueChanged, true);
            app.Button = uibutton(app.BrainNetworkPanel, 'push');
            app.Button.Text = '';
            app.TimeWindowCheckBox = uicheckbox(app.BrainNetworkPanel);
            app.TimeWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeWindowCheckBoxValueChanged, true);
            app.TimeWindowCheckBox.Text = '';
            app.TimeWindowCheckBox.Value = true;
            app.SlidingWindowCheckBox = uicheckbox(app.BrainNetworkPanel);
            app.SlidingWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlidingWindowCheckBoxValueChanged, true);
            app.SlidingWindowCheckBox.Text = '';
            app.PlotButton = uibutton(app.BrainNetworkPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ForwardButton = uibutton(app.BrainNetworkPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.BrainNetworkPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Enable = 'off';
            app.BackwardButton.Text = 'Backward';
            app.SlidingWindowEditField = uieditfield(app.BrainNetworkPanel, 'numeric');
            app.SlidingWindowEditField.Editable = 'off';
            app.SlidingWindowEditField.Enable = 'off';
            app.ColorMapButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.jetButton = uiradiobutton(app.ColorMapButtonGroup);
            app.jetButton.Text = 'jet';
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.turboButton = uiradiobutton(app.ColorMapButtonGroup);
            app.turboButton.Text = 'turbo';
            app.boneButton = uiradiobutton(app.ColorMapButtonGroup);
            app.boneButton.Text = 'bone';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frequency_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end