classdef Neurofeedback_toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OfflineGUIMenu                  matlab.ui.container.Menu
        TimeDomainMenu                  matlab.ui.container.Menu
        BrainActivityMenu               matlab.ui.container.Menu
        BrainNetworkMenu                matlab.ui.container.Menu
        SetupGUIMenu                    matlab.ui.container.Menu
        ChannelSelectionMenu            matlab.ui.container.Menu
        Panel_18                        matlab.ui.container.Panel
        TimeLabel                       matlab.ui.control.Label
        DisplayTimeCheckBox             matlab.ui.control.CheckBox
        UpdateInformationPanel          matlab.ui.container.Panel
        UpdateIntervalSpinner           matlab.ui.control.Spinner
        UpdateIntervalSpinnerLabel      matlab.ui.control.Label
        DisplayHighBetaDownProtocolCheckBox  matlab.ui.control.CheckBox
        DisplayAlphaAsymmetryProtocolCheckBox  matlab.ui.control.CheckBox
        DisplayBrainNetworkMapCheckBox  matlab.ui.control.CheckBox
        DisplayBrainActivityMapCheckBox  matlab.ui.control.CheckBox
        DisplayFrequencySpectrumCheckBox  matlab.ui.control.CheckBox
        ColorMapButtonGroup             matlab.ui.container.ButtonGroup
        grayButton                      matlab.ui.control.RadioButton
        boneButton                      matlab.ui.control.RadioButton
        turboButton                     matlab.ui.control.RadioButton
        hotButton                       matlab.ui.control.RadioButton
        jetButton                       matlab.ui.control.RadioButton
        parulaButton                    matlab.ui.control.RadioButton
        DataRecordingPanel              matlab.ui.container.Panel
        TriggerTextArea                 matlab.ui.control.TextArea
        TriggerButton                   matlab.ui.control.Button
        SetPathButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        SecondsSpinner                  matlab.ui.control.Spinner
        SecondsSpinnerLabel             matlab.ui.control.Label
        MinutesSpinner                  matlab.ui.control.Spinner
        MinutesSpinnerLabel             matlab.ui.control.Label
        HourSpinner                     matlab.ui.control.Spinner
        HourSpinnerLabel                matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        TFDomainSignalProcessingTab     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        FrequencyDomainTab              matlab.ui.container.Tab
        ChannelListBox                  matlab.ui.control.ListBox
        ChannelLabel_2                  matlab.ui.control.Label
        FrequencySpectrumLabel          matlab.ui.control.Label
        TypeofFrequencySpectrumButtonGroup  matlab.ui.container.ButtonGroup
        YlimAutoCheckBox                matlab.ui.control.CheckBox
        YlimSpinner                     matlab.ui.control.Spinner
        YlimSpinnerLabel                matlab.ui.control.Label
        XlimSpinner                     matlab.ui.control.Spinner
        XlimSpinnerLabel                matlab.ui.control.Label
        BarGraphCheckBox                matlab.ui.control.CheckBox
        ByTypeofEEGButton               matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        FourierTransformTab             matlab.ui.container.Tab
        TypeofFourierTransformButtonGroup  matlab.ui.container.ButtonGroup
        FourierTransformFFTButton       matlab.ui.control.RadioButton
        PowerSpectrumDensityWelchButton  matlab.ui.control.RadioButton
        YAxisButtonGroup                matlab.ui.container.ButtonGroup
        PSDButton                       matlab.ui.control.RadioButton
        LogPSDButton                    matlab.ui.control.RadioButton
        PowerButton                     matlab.ui.control.RadioButton
        AmplitudeButton                 matlab.ui.control.RadioButton
        FourierOptionPanel              matlab.ui.container.Panel
        WindowOverlapEditFieldLabel     matlab.ui.control.Label
        WindowOverlapEditFieldLabel2    matlab.ui.control.Label
        WindowOverlapEditField          matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowLengthEditFieldLabel2     matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        SignalProcessingPanel           matlab.ui.container.Panel
        MuscleLabel_4                   matlab.ui.control.Label
        ChannelNoiseEditField           matlab.ui.control.NumericEditField
        MuscleLabel_3                   matlab.ui.control.Label
        LineNoiseEditField              matlab.ui.control.NumericEditField
        MuscleLabel_2                   matlab.ui.control.Label
        HeartEditField                  matlab.ui.control.NumericEditField
        EyeLabel                        matlab.ui.control.Label
        EyeEditField                    matlab.ui.control.NumericEditField
        MuscleLabel                     matlab.ui.control.Label
        MuscleEditField                 matlab.ui.control.NumericEditField
        LineNoiseButton                 matlab.ui.control.StateButton
        HeartButton                     matlab.ui.control.StateButton
        EyeButton                       matlab.ui.control.StateButton
        ChannelNoiseButton              matlab.ui.control.StateButton
        MuscleButton                    matlab.ui.control.StateButton
        BaselineTimesSpinner            matlab.ui.control.Spinner
        BaselineTimesSpinnerLabel       matlab.ui.control.Label
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        FDomainBrainActivityandNetworkLabel  matlab.ui.control.Label
        ICAArtifactsRemovalCheckBox     matlab.ui.control.CheckBox
        DetrendCheckBox                 matlab.ui.control.CheckBox
        TimeDomainPanel                 matlab.ui.container.Panel
        ScalePanel_2                    matlab.ui.container.Panel
        ScaleSpinner                    matlab.ui.control.Spinner
        FilteredDataLabel               matlab.ui.control.Label
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        HighCutOffSpinner               matlab.ui.control.Spinner
        LowCutOffSpinner                matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        NotchFilterButton               matlab.ui.control.RadioButton
        BandpassFilterButton            matlab.ui.control.RadioButton
        BrainActivityandNetworkTab      matlab.ui.container.Tab
        BrainNetworkMapPanel            matlab.ui.container.Panel
        FrequencyBandPanel              matlab.ui.container.Panel
        RangeLabel2                     matlab.ui.control.Label
        HighRangeSpinner3               matlab.ui.control.Spinner
        LowRangeSpinner3                matlab.ui.control.Spinner
        WindowLabel4                    matlab.ui.control.Label
        ColormapRangePanel              matlab.ui.container.Panel
        ColormapRangeCheckBox           matlab.ui.control.CheckBox
        AbsoluteValuesCheckBox          matlab.ui.control.CheckBox
        ThresholdPanel                  matlab.ui.container.Panel
        ThresholdSpinner                matlab.ui.control.Spinner
        EdgePanel                       matlab.ui.container.Panel
        EdgeLabel4                      matlab.ui.control.Label
        EdgeCheckBox4                   matlab.ui.control.CheckBox
        NodeDropDown4_2                 matlab.ui.control.DropDown
        NodeDropDown4_1                 matlab.ui.control.DropDown
        EdgeLabel3                      matlab.ui.control.Label
        EdgeCheckBox3                   matlab.ui.control.CheckBox
        NodeDropDown3_2                 matlab.ui.control.DropDown
        NodeDropDown3_1                 matlab.ui.control.DropDown
        EdgeLabel2                      matlab.ui.control.Label
        EdgeCheckBox2                   matlab.ui.control.CheckBox
        NodeDropDown2_2                 matlab.ui.control.DropDown
        NodeDropDown2_1                 matlab.ui.control.DropDown
        EdgeLabel                       matlab.ui.control.Label
        EdgeCheckBox                    matlab.ui.control.CheckBox
        NodeDropDown1_2                 matlab.ui.control.DropDown
        NodeDropDown1_1                 matlab.ui.control.DropDown
        EdgeButtonGroup                 matlab.ui.container.ButtonGroup
        NodetoNodeButton                matlab.ui.control.RadioButton
        AllEdgeButton                   matlab.ui.control.RadioButton
        TypeofEdgeVisualizationLabel    matlab.ui.control.Label
        ConnectivityMetricLabel         matlab.ui.control.Label
        AnalysisIndexButtonGroup        matlab.ui.container.ButtonGroup
        PLVButton                       matlab.ui.control.RadioButton
        PLIButton                       matlab.ui.control.RadioButton
        CorrelationButton               matlab.ui.control.RadioButton
        CoherenceButton                 matlab.ui.control.RadioButton
        BrainActivityMapPanel           matlab.ui.container.Panel
        AmplitudeLabel                  matlab.ui.control.Label
        WindowLabel3                    matlab.ui.control.Label
        CustomizationLabel              matlab.ui.control.Label
        HighRangeSpinner2               matlab.ui.control.Spinner
        LowRangeSpinner2                matlab.ui.control.Spinner
        FrequencyRangeofValuesButtonGroup  matlab.ui.container.ButtonGroup
        CustomizationButton             matlab.ui.control.RadioButton
        TypeofEEGButton                 matlab.ui.control.RadioButton
        FrequencyRangeofValuesLabel     matlab.ui.control.Label
        TypeofEEGListBox                matlab.ui.control.ListBox
        TypeofEEGLabel                  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        FrequencySpectrumTab            matlab.ui.container.Tab
        FrequencySpectrumUIAxes         matlab.ui.control.UIAxes
        BrainNetworkTab                 matlab.ui.container.Tab
        BrainNetworkUIAxes              matlab.ui.control.UIAxes
        AlphaAsymmetryProtocolTab       matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        A1scoreEditField                matlab.ui.control.NumericEditField
        A1scoreEditFieldLabel           matlab.ui.control.Label
        AlphaAsymmetryLchannelListBox   matlab.ui.control.ListBox
        LchannelListBoxLabel            matlab.ui.control.Label
        AlphaAsymmetryRchannelListBox   matlab.ui.control.ListBox
        RchannelListBoxLabel            matlab.ui.control.Label
        ThresholdEditField_3            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label       matlab.ui.control.Label
        EEGTypeListBox                  matlab.ui.control.ListBox
        EEGTypeListBoxLabel             matlab.ui.control.Label
        XlimEditField                   matlab.ui.control.NumericEditField
        XlimEditFieldLabel              matlab.ui.control.Label
        AlphaAsymmetryProtocolUIAxes    matlab.ui.control.UIAxes
        HighBetaDownProtocolTab         matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        Panel_11                        matlab.ui.container.Panel
        Amplitude2Label                 matlab.ui.control.Label
        Amplitude1Label                 matlab.ui.control.Label
        HighBetaDownChannel2CheckBox    matlab.ui.control.CheckBox
        HighBetaDownValue2EditField     matlab.ui.control.NumericEditField
        ThresholdEditField_3Label_2     matlab.ui.control.Label
        HighBetaDownValue1EditField     matlab.ui.control.NumericEditField
        HighBetaDownChannel2ListBox     matlab.ui.control.ListBox
        Channel2Label                   matlab.ui.control.Label
        HighBetaDownChannel1ListBox     matlab.ui.control.ListBox
        Channel1Label                   matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditField_3Label       matlab.ui.control.Label
        EEGTypeListBox2                 matlab.ui.control.ListBox
        EEGTypeListBox_2Label           matlab.ui.control.Label
        YlimEditField                   matlab.ui.control.NumericEditField
        YlimEditFieldLabel              matlab.ui.control.Label
        HighBetaDownProtocolUIAxes      matlab.ui.control.UIAxes
        LossRateEditField               matlab.ui.control.NumericEditField
        lossrateLabel                   matlab.ui.control.Label
        PlotConnectivityUIAxes          matlab.ui.control.UIAxes
        PlotTopographyUIAxes            matlab.ui.control.UIAxes
        RawDataUIAxes                   matlab.ui.control.UIAxes
        FilteredDataUIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        stopFlag = false;

        locations
        sample_rate

        event_text = {};
        event_list = [];
    end

    properties (Access = private)
        timeseriesApp
        frequencyApp
        connApp
        channelselectionApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global locations
            global sample_rate

            % app name
            app.UIFigure.Name = 'Neurofeedback Tool';

            % public properties
            app.locations = locations;
            app.sample_rate = sample_rate;

            % channel list (list-box & drop-down)
            app.ChannelListBox.Items = transpose(locations.labels);
            app.HighBetaDownChannel1ListBox.Items=transpose(locations.labels); app.HighBetaDownChannel2ListBox.Items=transpose(locations.labels);
            app.NodeDropDown1_1.Items=transpose(locations.labels); app.NodeDropDown1_2.Items=transpose(locations.labels);
            app.NodeDropDown2_1.Items=transpose(locations.labels); app.NodeDropDown2_2.Items=transpose(locations.labels);
            app.NodeDropDown3_1.Items=transpose(locations.labels); app.NodeDropDown3_2.Items=transpose(locations.labels);
            app.NodeDropDown4_1.Items=transpose(locations.labels); app.NodeDropDown4_2.Items=transpose(locations.labels);

            i = 1;  j = 1;
            while i <= length(app.AlphaAsymmetryRchannelListBox.Items)
                if isempty(intersect(app.AlphaAsymmetryRchannelListBox.Items{i}, locations.labels))
                    app.AlphaAsymmetryRchannelListBox.Items(i) = [];
                else
                    i = i + 1;
                end
            end

            while j <= length(app.AlphaAsymmetryLchannelListBox.Items)
                if isempty(intersect(app.AlphaAsymmetryLchannelListBox.Items{j}, locations.labels))
                    app.AlphaAsymmetryLchannelListBox.Items(j) = [];
                else
                    j = j + 1;
                end
            end

            % first selected channel
            app.ChannelListBox.Value = locations.labels{1};
            app.HighBetaDownChannel1ListBox.Value = locations.labels{1};
            app.HighBetaDownChannel2ListBox.Value = locations.labels{1};

            if length(app.AlphaAsymmetryRchannelListBox.Items) >= 1
                app.AlphaAsymmetryRchannelListBox.Value = app.AlphaAsymmetryRchannelListBox.Items{1};
            end
            if length(app.AlphaAsymmetryLchannelListBox.Items) >= 1
                app.AlphaAsymmetryLchannelListBox.Value = app.AlphaAsymmetryLchannelListBox.Items{1};
            end

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global rawdata

            %%% Parameters or variables
            % EEG struct
            EEG.setname = 'matfile'; EEG.filename = ''; EEG.filepath = ''; EEG.subject = '';
            EEG.nbchan = size(app.locations,1); EEG.srate = app.sample_rate;
            EEG.trials = 1; EEG.pnts = []; EEG.xmin = 0; EEG.xmax = []; EEG.data = [];
            EEG.icaact = []; EEG.icawinv = []; EEG.icasphere = []; EEG.icaweights = []; EEG.icachansind = [];
            location_struct = table2struct(app.locations); EEG.chanlocs = location_struct;
            EEG.urchanlocs = []; EEG.chaninfo = [];
            EEG.event = []; EEG.urevent = []; EEG.epoch = [];
            EEG.reject = []; EEG.specdata = []; EEG.specicaact = [];
            EEG.etc = [];

            % loss rate
            lr_i = 1;
            lr_j = EEG.srate;

            % data update interval
            update_interval = app.UpdateIntervalSpinner.Value;

            % pre-processing
            baseline_data = [];
            b_i = 0;

            % frequency spectrum
            f_i = 1;
            f_j = update_interval * EEG.srate;
            f_j2 = f_j;

            % rawdata, timesum
            rawdata = []; % channel X data (matrix)
            timesum = []; % vector for total measurement time

            % trigger
            app.event_text = {};
            app.event_list = [];
            app.TriggerTextArea.Value = "";

            % channel list
            ch_list = transpose(app.locations.labels);

            % time
            h = app.HourSpinner.Value;
            m = app.MinutesSpinner.Value;
            s = app.SecondsSpinner.Value;
            time = h*3600 + m*60 + s;

            % save file
            complete_record = "yes";


            %%% Recording
            % instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();

            % resolve a stream...
            disp('Resolving an EEG stream...');
            stream = {};
            while isempty(stream)
                stream = lsl_resolve_byprop(lib,'type','EEG');
                if isempty(stream)
                    error('There is no stream visible on the network.');
                end
            end

            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(stream{1});

            while true
                if length(timesum) > 1
                    if timesum(1,end) - timesum(1,1) >= time
                        break % end of record
                    end
                end

                % loss rate & total time
                if length(timesum) >= EEG.srate*lr_i
                    lr_t = timesum(lr_j) - timesum(lr_j-(EEG.srate-1));
                    real_sample_rate = EEG.srate/lr_t;
                    loss_rate = (EEG.srate-real_sample_rate)/EEG.srate * 100;
                    app.LossRateEditField.Value =  loss_rate;

                    if app.DisplayTimeCheckBox.Value == 1
                        total_t = lr_i;
                        total_h = floor(total_t / 3600);
                        total_m = floor((total_t - total_h * 3600) / 60);
                        total_s = mod(total_t, 60);
                        app.TimeLabel.Text = sprintf('%02d : %02d : %02d', total_h, total_m, total_s);
                    end

                    lr_i = lr_i + 1;
                    lr_j = lr_j + EEG.srate;
                end

                % data acqusition
                [chunk, timestamps] = inlet.pull_chunk();
                timesum = [timesum, timestamps];
                rawdata = [rawdata, chunk]; % channel X data (matrix)

                plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)
                plot_rawdata_T = transpose(plot_rawdata); % data X channel (matrix)
                i = size(plot_rawdata, 2);

                % time domain UIAxes (rawdata)
                plot_rawdata_T2 = (plot_rawdata_T-mean(plot_rawdata_T))*app.ScaleSpinner.Value; % data X channel (matrix)
                for j = 1:1:size(app.locations,1)
                    plot_rawdata_T2(:,j) = plot_rawdata_T2(:,j) + (500-10*j)*3000; % data X channel (matrix)
                end

                if i < EEG.srate*4
                    plot(app.RawDataUIAxes, plot_rawdata_T2)
                    xlim(app.RawDataUIAxes, [0 EEG.srate*4]); xticks(app.RawDataUIAxes, [0 EEG.srate*4/2 EEG.srate*4]);
                    xticklabels(app.RawDataUIAxes, {'0','2','4'})
                    drawnow;
                elseif i >= EEG.srate*4
                    plot(app.RawDataUIAxes, plot_rawdata_T2(i-(EEG.srate*4-1):i,:))
                    xlim(app.RawDataUIAxes, [0 EEG.srate*4]); xticks(app.RawDataUIAxes, [0 EEG.srate*4/2 EEG.srate*4]);
                    xticklabels(app.RawDataUIAxes, {'0','2','4'})
                    drawnow;
                end

                % additional EEG struct
                EEG.nbchan = size(plot_rawdata, 1); EEG.pnts = size(plot_rawdata, 2);
                EEG.data = plot_rawdata; % channel X data (matrix)
                EEG.xmax = (EEG.pnts-1)/EEG.srate;

                % filtered data
                if i > 0
                    EEG2 = pop_eegfiltnew(EEG, app.LowCutOffSpinner.Value, app.HighCutOffSpinner.Value, [], app.NotchFilterButton.Value, [], 0); % bandpass filtering function

                    % parameters or variables
                    filtered_data_T = transpose(EEG2.data); % data X channel (matrix)

                    % time domain UIAxes (filtered data)
                    filtered_data_T2 = filtered_data_T*app.ScaleSpinner.Value;
                    for j = 1:1:size(app.locations, 1)
                        filtered_data_T2(:,j) = filtered_data_T2(:,j) + (500-10*j)*3000;
                    end

                    if i < EEG2.srate*4
                        plot(app.FilteredDataUIAxes, filtered_data_T2)
                        xlim(app.FilteredDataUIAxes, [0 EEG2.srate*4]); xticks(app.FilteredDataUIAxes, [0 EEG2.srate*4/2 EEG2.srate*4]);
                        xticklabels(app.FilteredDataUIAxes, {'0','2','4'})
                        drawnow;
                    elseif i >= EEG2.srate*4
                        plot(app.FilteredDataUIAxes, filtered_data_T2(i-(EEG2.srate*4-1):i,:))
                        xlim(app.FilteredDataUIAxes, [0 EEG2.srate*4]); xticks(app.FilteredDataUIAxes, [0 EEG2.srate*4/2 EEG2.srate*4]);
                        xticklabels(app.FilteredDataUIAxes, {'0','2','4'})
                        drawnow;
                    end

                    % frequency domain
                    if i >= f_j2*f_i
                        % selected channel
                        channel = strcmp(ch_list, app.ChannelListBox.Value);

                        %%% pre-processing
                        recent_dataSample = transpose(filtered_data_T(f_j-(f_j2-1) : f_j, :)); % channel X data (matrix)

                        % EEG struct
                        EEG3 = EEG2;
                        EEG3.nbchan = size(recent_dataSample, 1); EEG3.pnts = size(recent_dataSample, 2);
                        EEG3.data = recent_dataSample; % channel X data (matrix)
                        EEG3.xmax = (EEG3.pnts-1)/EEG3.srate;

                        % common average reference
                        if app.CommonAverageReferenceCheckBox.Value == 1
                            EEG3 = pop_reref(EEG3,[]); % channel X data (matrix)

                            if class(EEG3.data) ~= "double"
                                EEG3.data = double(EEG3.data);
                            end
                        end

                        % detend
                        if app.DetrendCheckBox.Value == 1
                            detrend_data = detrend(transpose(EEG3.data)); % data X channel (matrix)
                            EEG3.data = transpose(detrend_data); % channel X data (matrix)
                        end

                        % baseline correction
                        if app.BaselineCorrectionCheckBox.Value == 1
                            if app.BaselineTimesSpinner.Value > b_i
                                % create baseline data
                                baseline_data = [baseline_data, EEG3.data]; % channel X data (matrix)
                                b_i = b_i + update_interval;

                                if app.BaselineTimesSpinner.Value <= b_i
                                    baseline_data  = baseline_data(:, 1:app.BaselineTimesSpinner.Value*EEG3.srate);
                                end
                            end
                            if app.BaselineTimesSpinner.Value <= b_i
                                baseline_mean = mean(baseline_data, 2);
                                corrected_data = EEG3.data - baseline_mean;
                                EEG3.data = corrected_data; % channel X data (matrix)
                            end
                        end

                        % ICA -> artifacts removal
                        if app.ICAArtifactsRemovalCheckBox.Value == true
                            brain_value = [NaN NaN];
                            other_value = [NaN NaN];

                            if app.MuscleButton.Value == 1
                                muscle_value = [app.MuscleEditField.Value/100 1];
                            else, muscle_value = [NaN NaN];
                            end

                            if app.EyeButton.Value == 1
                                eye_value = [app.EyeEditField.Value/100 1];
                            else, eye_value = [NaN NaN];
                            end

                            if app.HeartButton.Value == 1
                                heart_value = [app.HeartEditField.Value/100 1];
                            else, heart_value = [NaN NaN];
                            end

                            if app.LineNoiseButton.Value == 1
                                lineNoise_value = [app.LineNoiseEditField.Value/100 1];
                            else, lineNoise_value = [NaN NaN];
                            end

                            if app.ChannelNoiseButton.Value == 1
                                channelNoise_value = [app.ChannelNoiseEditField.Value/100 1];
                            else, channelNoise_value = [NaN NaN];
                            end

                            EEG3 = pop_runica(EEG3, 'icatype', 'runica', 'extended',1, 'rndreset','yes', 'interrupt','off');
                            EEG3 = pop_iclabel(EEG3, 'default');
                            EEG3 = pop_icflag(EEG3, [brain_value;muscle_value;eye_value;heart_value;lineNoise_value;channelNoise_value;other_value]);
                            EEG3 = pop_subcomp(EEG3, find(EEG3.reject.gcompreject)', 0);

                            if class(EEG3.data) ~= "double"
                                EEG3.data = double(EEG3.data);
                            end
                        end

                        recent_dataSample_T = transpose(EEG3.data); % data X channel (matrix)

                        % fourier transform algorithm (FFT of Welch)
                        if app.FourierTransformFFTButton.Value == 1
                            all_fftdata = fft(recent_dataSample_T);
                            N = f_j2;
                            n = 0:N-1;
                            f_axis = app.sample_rate*n/N;
                            cutoff = ceil(N/2);
                            all_fftdata = all_fftdata(1:cutoff,:);
                            f_axis = f_axis(1:cutoff);

                            % amplitude or power
                            if app.AmplitudeButton.Value == 1
                                all_fftdata = (1/N)*2*abs(all_fftdata); % all channel
                                fftdata = all_fftdata(:, channel); % selected channel
                            elseif app.PowerButton.Value == 1
                                all_fftdata = (1/N^2)*2*abs(all_fftdata).^2; % all channel
                                fftdata = all_fftdata(:, channel); % selected channel
                            end

                        elseif app.PowerSpectrumDensityWelchButton.Value == 1
                            % welch method parameters
                            W_length = round(app.WindowLengthEditField.Value*app.sample_rate);
                            W = hamming(W_length);
                            NoverLap = floor(app.WindowOverlapEditField.Value/100 * W_length);
                            nFFT = 2^nextpow2(W_length*2);

                            % amplitude or power or psd or log(psd)
                            if app.AmplitudeButton.Value == 1
                                [all_fftdata, f_axis] = pwelch(recent_dataSample_T,W,NoverLap,nFFT,app.sample_rate,'power'); % all channel
                                all_fftdata = sqrt(2*all_fftdata);
                                fftdata = all_fftdata(:, channel); % selected channel
                            elseif app.PowerButton.Value == 1
                                [all_fftdata, f_axis] = pwelch(recent_dataSample_T,W,NoverLap,nFFT,app.sample_rate,'power'); % all channel
                                fftdata = all_fftdata(:, channel); % selected channel
                            elseif app.PSDButton.Value == 1
                                [all_fftdata, f_axis] = pwelch(recent_dataSample_T,W,NoverLap,nFFT,app.sample_rate,'psd'); % all channel
                                fftdata = all_fftdata(:, channel); % selected channel
                            elseif app.LogPSDButton.Value == 1
                                [all_fftdata, f_axis] = pwelch(recent_dataSample_T,W,NoverLap,nFFT,app.sample_rate,'psd'); % all channel
                                all_fftdata = pow2db(all_fftdata);
                                fftdata = all_fftdata(:, channel); % selected channel
                            end
                        end

                        % frequency spectrum (all or by type of EEG)
                        if app.DisplayFrequencySpectrumCheckBox.Value == 1
                            if app.AllButton.Value == 1
                                % bar or plot
                                if app.BarGraphCheckBox.Value == 1
                                    bar(app.FrequencySpectrumUIAxes, f_axis, fftdata);
                                    xticklabels(app.FrequencySpectrumUIAxes, 'auto')
                                elseif app.BarGraphCheckBox.Value == 0
                                    plot(app.FrequencySpectrumUIAxes, f_axis, fftdata);
                                    xticklabels(app.FrequencySpectrumUIAxes, 'auto')
                                end
                                yticks(app.FrequencySpectrumUIAxes, 'auto'); ylim(app.FrequencySpectrumUIAxes, 'auto');
                                xlim(app.FrequencySpectrumUIAxes, [0 app.XlimSpinner.Value]);
                                if app.YlimAutoCheckBox.Value == 1
                                    ylim(app.FrequencySpectrumUIAxes, [0 app.YlimSpinner.Value]);
                                end
                            elseif app.ByTypeofEEGButton.Value == 1
                                delta = mean(fftdata((f_axis >= 1) & (f_axis <= 3)));
                                theta = mean(fftdata((f_axis >= 4) & (f_axis <= 8)));
                                alpha = mean(fftdata((f_axis >= 8) & (f_axis <= 13)));
                                smr = mean(fftdata((f_axis >= 13) & (f_axis <= 15)));
                                beta = mean(fftdata((f_axis >= 13) & (f_axis <= 30)));
                                highbeta = mean(fftdata((f_axis >= 22) & (f_axis <= 30)));
                                gamma = mean(fftdata((f_axis >= 30) & (f_axis <= 50)));

                                bar(app.FrequencySpectrumUIAxes, 1:7, [delta, theta, alpha, smr, beta, highbeta, gamma], 'FaceColor',[.5 .5 .5], 'LineWidth',1);
                                xticklabels(app.FrequencySpectrumUIAxes, {'Delta', 'Theta', 'Alpha', 'Smr', 'Beta', 'High Beta', 'Gamma'});
                                xlim(app.FrequencySpectrumUIAxes, [-0.2    8.2]); set(app.FrequencySpectrumUIAxes ,'ytick', []);

                                selected_fftdata_values = [delta, theta, alpha, smr, beta, highbeta, gamma];
                                for i = 1:length(selected_fftdata_values)
                                    text(app.FrequencySpectrumUIAxes, i, selected_fftdata_values(i), num2str(selected_fftdata_values(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                                end
                            end
                        end


                        %%% Brain Activity
                        % type of EEG or customization
                        if app.LogPSDButton.Value == 1
                            all_fftdata2 = db2pow(all_fftdata);
                        elseif app.LogPSDButton.Value == 0
                            all_fftdata2 = all_fftdata;
                        end

                        if app.DisplayBrainActivityMapCheckBox.Value == 1
                            if app.TypeofEEGButton.Value == 1
                                switch app.TypeofEEGListBox.Value
                                    case 'Delta', activity_values = mean(all_fftdata2((f_axis >= 1) & (f_axis <= 3),:), 1);
                                    case 'Theta', activity_values = mean(all_fftdata2((f_axis >= 4) & (f_axis <= 8),:), 1);
                                    case 'Alpha', activity_values = mean(all_fftdata2((f_axis >= 8) & (f_axis <= 13),:), 1);
                                    case 'Smr', activity_values = mean(all_fftdata2((f_axis >= 13) & (f_axis <= 15),:), 1);
                                    case 'Beta', activity_values = mean(all_fftdata2((f_axis >= 13) & (f_axis <= 30),:), 1);
                                    case 'High Beta', activity_values = mean(all_fftdata2((f_axis >= 22) & (f_axis <= 30),:), 1);
                                    case 'Gamma', activity_values = mean(all_fftdata2((f_axis >= 30) & (f_axis <= 50),:), 1);
                                end
                            elseif app.CustomizationButton.Value == 1
                                activity_values = mean(all_fftdata2((f_axis >= app.LowRangeSpinner2.Value) & (f_axis <= app.HighRangeSpinner2.Value),:), 1);
                            end

                            % normalization
                            activity_values = (activity_values-min(activity_values))/(max(activity_values)-min(activity_values));

                            % colormap for brain activity
                            switch app.ColorMapButtonGroup.SelectedObject.Text
                                case 'jet',colormap(app.PlotTopographyUIAxes,'jet'); case 'hot',colormap(app.PlotTopographyUIAxes,'hot');
                                case 'parula',colormap(app.PlotTopographyUIAxes,'parula'); case 'turbo',colormap(app.PlotTopographyUIAxes,'turbo');
                                case 'gray',colormap(app.PlotTopographyUIAxes,'gray'); case 'bone',colormap(app.PlotTopographyUIAxes,'bone');
                            end

                            % brain activity map
                            plot_topography(app, ch_list, activity_values, true, app.locations, false, true, 200);
                            colorbar(app.PlotTopographyUIAxes);
                            clim(app.PlotTopographyUIAxes, [0, 1]);
                        end


                        %%% Brain Network
                        if app.DisplayBrainNetworkMapCheckBox.Value == 1
                            % node to node
                            node_to_node = zeros(1,2); node_to_node2 = zeros(1,2);
                            node_to_node3 = zeros(1,2); node_to_node4 = zeros(1,2);

                            if app.NodetoNodeButton.Value == 1
                                if app.EdgeCheckBox.Value == 1
                                    node_to_node = [find(strcmp(ch_list, app.NodeDropDown1_1.Value)), find(strcmp(ch_list, app.NodeDropDown1_2.Value))];
                                end
                                if app.EdgeCheckBox2.Value == 1
                                    node_to_node2 = [find(strcmp(ch_list, app.NodeDropDown2_1.Value)), find(strcmp(ch_list, app.NodeDropDown2_2.Value))];
                                end
                                if app.EdgeCheckBox3.Value == 1
                                    node_to_node3 = [find(strcmp(ch_list, app.NodeDropDown3_1.Value)), find(strcmp(ch_list, app.NodeDropDown3_2.Value))];
                                end
                                if app.EdgeCheckBox4.Value == 1
                                    node_to_node4 = [find(strcmp(ch_list, app.NodeDropDown4_1.Value)), find(strcmp(ch_list, app.NodeDropDown4_2.Value))];
                                end
                            end
                            selected_nodes = [node_to_node, node_to_node2, node_to_node3, node_to_node4];

                            % frequency band, threshold
                            f_min = app.LowRangeSpinner3.Value;
                            f_max = app.HighRangeSpinner3.Value;
                            threshold = app.ThresholdSpinner.Value;

                            % analysis index (correlation, coherence, PLV, PLI)
                            if app.CoherenceButton.Value == 1
                                if app.AllEdgeButton.Value == 1
                                    Coher = Coherence(recent_dataSample_T, app.sample_rate, [f_min, f_max]);
                                    analysis_index = Coher.Coher;
                                elseif app.NodetoNodeButton.Value == 1
                                    Coher = Coherence(recent_dataSample_T, app.sample_rate, [f_min, f_max]);
                                    ntn_matrix = eye(size(Coher.Coher, 1), size(Coher.Coher, 2));

                                    for i = 1:2:length(selected_nodes)
                                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                                            continue
                                        end
                                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = Coher.Coher(selected_nodes(i), selected_nodes(i+1));
                                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = Coher.Coher(selected_nodes(i+1), selected_nodes(i));
                                    end
                                    Coher.Coher = ntn_matrix;
                                    analysis_index = Coher.Coher;
                                end

                            elseif app.CorrelationButton.Value == 1
                                if app.AllEdgeButton.Value == 1
                                    Corr = Correlations(recent_dataSample_T);
                                    analysis_index = Corr.Corr;
                                elseif app.NodetoNodeButton.Value == 1
                                    Corr = Correlations(recent_dataSample_T);
                                    ntn_matrix = eye(size(Corr.Corr, 1), size(Corr.Corr, 2));

                                    for i = 1:2:length(selected_nodes)
                                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                                            continue
                                        end
                                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = Corr.Corr(selected_nodes(i), selected_nodes(i+1));
                                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = Corr.Corr(selected_nodes(i+1), selected_nodes(i));
                                    end
                                    Corr.Corr = ntn_matrix;
                                    analysis_index = Corr.Corr;
                                end

                            elseif app.PLIButton.Value == 1
                                if app.AllEdgeButton.Value == 1
                                    PLI = PhaseLagIndex(recent_dataSample_T);
                                    analysis_index = PLI.PLI;
                                elseif app.NodetoNodeButton.Value == 1
                                    PLI = PhaseLagIndex(recent_dataSample_T);
                                    ntn_matrix = eye(size(PLI.PLI, 1), size(PLI.PLI, 2));

                                    for i = 1:2:length(selected_nodes)
                                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                                            continue
                                        end
                                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = PLI.PLI(selected_nodes(i), selected_nodes(i+1));
                                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = PLI.PLI(selected_nodes(i+1), selected_nodes(i));
                                    end
                                    PLI.PLI = ntn_matrix;
                                    analysis_index = PLI.PLI;
                                end

                            elseif app.PLVButton.Value == 1
                                if app.AllEdgeButton.Value == 1
                                    PLV = PhaseLockingValue(recent_dataSample_T, app.sample_rate, [f_min, f_max]);
                                    analysis_index = PLV.PLV;
                                elseif app.NodetoNodeButton.Value == 1
                                    PLV = PhaseLockingValue(recent_dataSample_T, app.sample_rate, [f_min, f_max]);
                                    ntn_matrix = eye(size(PLV.PLV, 1), size(PLV.PLV, 2));

                                    for i = 1:2:length(selected_nodes)
                                        if selected_nodes(i) == 0 || selected_nodes(i+1) == 0
                                            continue
                                        end
                                        ntn_matrix(selected_nodes(i), selected_nodes(i+1)) = PLV.PLV(selected_nodes(i), selected_nodes(i+1));
                                        ntn_matrix(selected_nodes(i+1), selected_nodes(i)) = PLV.PLV(selected_nodes(i+1), selected_nodes(i));
                                    end
                                    PLV.PLV = ntn_matrix;
                                    analysis_index = PLV.PLV;
                                end
                            end
                            if app.AbsoluteValuesCheckBox.Value == 1
                                analysis_index = abs(analysis_index);
                            end

                            % colormap for brain network
                            switch app.ColorMapButtonGroup.SelectedObject.Text
                                case 'jet',colormap(app.PlotConnectivityUIAxes,'jet'); case 'hot',colormap(app.PlotConnectivityUIAxes,'hot');
                                case 'parula',colormap(app.PlotConnectivityUIAxes,'parula'); case 'turbo',colormap(app.PlotConnectivityUIAxes,'turbo');
                                case 'gray',colormap(app.PlotConnectivityUIAxes,'gray'); case 'bone',colormap(app.PlotConnectivityUIAxes,'bone');
                            end

                            % displayStruct for brain network
                            adjMat = analysis_index >= threshold;
                            clear ds;
                            ds.connectStrength = analysis_index(adjMat);
                            [ds.chanPairs(:, 1), ds.chanPairs(:, 2)] = ind2sub(size(adjMat), find(adjMat));

                            % brain network map
                            topoplot_connect(app, ds, location_struct);
                            colorbar(app.PlotConnectivityUIAxes);
                            if app.ColormapRangeCheckBox.Value == 1
                                clim(app.PlotConnectivityUIAxes, [0, 1]);
                            else
                                clim(app.PlotConnectivityUIAxes, [-1, 1]);
                            end

                            % brain network bar graph
                            if app.NodetoNodeButton.Value == 1
                                connectivity_values = zeros(1,4);
                                b_k = 0;

                                for i = 1:2:8
                                    if selected_nodes(i) ~= 0
                                        connectivity_values(i-b_k) = analysis_index(selected_nodes(i),selected_nodes(i+1));
                                    else
                                        connectivity_values(i-b_k) = 0;
                                    end
                                    b_k = b_k+1;
                                end

                                if any(connectivity_values)
                                    xtick_labels = cell(1, length(connectivity_values));

                                    if connectivity_values(1) ~= 0
                                        xtick_labels{1} = strcat(app.NodeDropDown1_1.Value,'-',app.NodeDropDown1_2.Value);
                                    end
                                    if connectivity_values(2) ~= 0
                                        xtick_labels{2} = strcat(app.NodeDropDown2_1.Value,'-',app.NodeDropDown2_2.Value);
                                    end
                                    if connectivity_values(3) ~= 0
                                        xtick_labels{3} = strcat(app.NodeDropDown3_1.Value,'-',app.NodeDropDown3_2.Value);
                                    end
                                    if connectivity_values(4) ~= 0
                                        xtick_labels{4} = strcat(app.NodeDropDown4_1.Value,'-',app.NodeDropDown4_2.Value);
                                    end

                                    selected_connectivity_values = nonzeros(connectivity_values);
                                    bar(app.BrainNetworkUIAxes, selected_connectivity_values, 'FaceColor',[.5 .5 .5], 'LineWidth',.5);
                                    ylim(app.BrainNetworkUIAxes, [0, 1]); set(app.BrainNetworkUIAxes ,'ytick',[]);
                                    xticklabels(app.BrainNetworkUIAxes, xtick_labels(~cellfun('isempty', xtick_labels)));

                                    for i = 1:length(selected_connectivity_values)
                                        text(app.BrainNetworkUIAxes, i, selected_connectivity_values(i), num2str(selected_connectivity_values(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                                    end
                                end
                            end
                        end

                        % alpha asymmetry protocol
                        if app.DisplayAlphaAsymmetryProtocolCheckBox.Value == 1
                            alpha_Rchannel = find(strcmp(app.locations.labels, app.AlphaAsymmetryRchannelListBox.Value), 1);
                            alpha_Lchannel = find(strcmp(app.locations.labels, app.AlphaAsymmetryLchannelListBox.Value), 1);

                            if app.AmplitudeButton.Value == 1
                                all_fftdata3 = (all_fftdata2).^2/2;
                                alphaAsymmetry_value = mean(all_fftdata3((f_axis >= 8 & f_axis <= 13),:));
                            elseif app.PowerButton.Value == 1
                                alphaAsymmetry_value = mean(all_fftdata2((f_axis >= 8 & f_axis <= 13),:));
                            elseif app.PSDButton.Value == 1
                                alphaAsymmetry_value = mean(all_fftdata2((f_axis >= 8 & f_axis <= 13),:));
                            end

                            A1_score = log10(alphaAsymmetry_value(:, alpha_Rchannel)) - log10(alphaAsymmetry_value(:, alpha_Lchannel));
                            app.A1scoreEditField.Value = A1_score;

                            if A1_score >= app.ThresholdEditField_3.Value
                                barh(app.AlphaAsymmetryProtocolUIAxes, A1_score, 'FaceColor',[.3 .9 .3], 'EdgeColor', 'none', 'BaseValue',app.ThresholdEditField_3.Value);
                            elseif A1_score < app.ThresholdEditField_3.Value
                                barh(app.AlphaAsymmetryProtocolUIAxes, A1_score, 'FaceColor',[1 .4 .3], 'EdgeColor', 'none', 'BaseValue',app.ThresholdEditField_3.Value);
                            end

                            if A1_score <= app.XlimEditField.Value
                                xlim(app.AlphaAsymmetryProtocolUIAxes,[-app.XlimEditField.Value+app.ThresholdEditField_3.Value, app.XlimEditField.Value+app.ThresholdEditField_3.Value])
                                set(app.AlphaAsymmetryProtocolUIAxes ,'ytick',[])
                            elseif A1_score > app.XlimEditField.Value
                                xlim(app.AlphaAsymmetryProtocolUIAxes,[-A1_score+app.ThresholdEditField_3.Value, A1_score+app.ThresholdEditField_3.Value]);
                                set(app.AlphaAsymmetryProtocolUIAxes ,'ytick',[])
                            end
                        end

                        % high beta down protocol
                        if app.DisplayHighBetaDownProtocolCheckBox.Value == 1
                            highBeta_channel1 = find(strcmp(app.locations.labels, app.HighBetaDownChannel1ListBox.Value));
                            highBeta_channel2 = find(strcmp(app.locations.labels, app.HighBetaDownChannel2ListBox.Value));

                            highBetaDown_value = mean(all_fftdata2((f_axis >= 22 & f_axis <= 30),:));

                            if app.HighBetaDownChannel2CheckBox.Value == 1
                                app.HighBetaDownValue1EditField.Value = highBetaDown_value(:, highBeta_channel1);
                                app.HighBetaDownValue2EditField.Value = highBetaDown_value(:, highBeta_channel2);

                                b = bar(app.HighBetaDownProtocolUIAxes, [app.HighBetaDownValue1EditField.Value 0 app.HighBetaDownValue2EditField.Value], 'EdgeColor', 'none');
                                b.FaceColor = 'flat';
                                xticklabels(app.HighBetaDownProtocolUIAxes, {'ch1','','ch2'})

                                if app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value*1.8
                                    b.CData(1,:) = [1 0 0];
                                elseif app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value*1.3
                                    b.CData(1,:) = [1 .4 .3];
                                elseif app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value
                                    b.CData(1,:) = [0.9290 0.6940 0.1250];
                                elseif app.HighBetaDownValue1EditField.Value <= app.ThresholdEditField.Value && app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value*0.5
                                    b.CData(1,:) = [.4 .8 .3];
                                elseif app.HighBetaDownValue1EditField.Value <= app.ThresholdEditField.Value*0.5
                                    b.CData(1,:) = [.3 .9 .3];
                                end

                                if app.HighBetaDownValue2EditField.Value > app.ThresholdEditField.Value*1.8
                                    b.CData(3,:) = [1 0 0];
                                elseif app.HighBetaDownValue2EditField.Value > app.ThresholdEditField.Value*1.3
                                    b.CData(3,:) = [1 .4 .3];
                                elseif app.HighBetaDownValue2EditField.Value > app.ThresholdEditField.Value
                                    b.CData(3,:) = [0.9290 0.6940 0.1250];
                                elseif app.HighBetaDownValue2EditField.Value <= app.ThresholdEditField.Value && app.HighBetaDownValue2EditField.Value > app.ThresholdEditField.Value*0.5
                                    b.CData(3,:) = [.4 .8 .3];
                                elseif app.HighBetaDownValue2EditField.Value <= app.ThresholdEditField.Value*0.5
                                    b.CData(3,:) = [.3 .9 .3];
                                end

                                if app.YlimEditField.Value >= app.HighBetaDownValue1EditField.Value && app.YlimEditField.Value >= app.HighBetaDownValue2EditField.Value
                                    ylim(app.HighBetaDownProtocolUIAxes,[0 app.YlimEditField.Value]);
                                elseif app.YlimEditField.Value < app.HighBetaDownValue1EditField.Value || app.YlimEditField.Value < app.HighBetaDownValue2EditField.Value
                                    if app.HighBetaDownValue1EditField.Value >= app.HighBetaDownValue2EditField.Value
                                        ylim(app.HighBetaDownProtocolUIAxes,[0 app.HighBetaDownValue1EditField.Value]);
                                    else
                                        ylim(app.HighBetaDownProtocolUIAxes,[0 app.HighBetaDownValue2EditField.Value]);
                                    end
                                end
                            else
                                app.HighBetaDownValue1EditField.Value = highBetaDown_value(:, highBeta_channel1);
                                app.HighBetaDownValue2EditField.Value = 0;

                                b = bar(app.HighBetaDownProtocolUIAxes, [app.HighBetaDownValue1EditField.Value], 'EdgeColor', 'none');
                                b.FaceColor = 'flat';
                                xticklabels(app.HighBetaDownProtocolUIAxes, {'ch1'})

                                if app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value*1.5
                                    b.CData(1,:) = [1 0 0];
                                elseif app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value
                                    b.CData(1,:) = [1 .4 .3];
                                elseif app.HighBetaDownValue1EditField.Value <= app.ThresholdEditField.Value && app.HighBetaDownValue1EditField.Value > app.ThresholdEditField.Value*0.5
                                    b.CData(1,:) = [.4 .8 .3];
                                elseif app.HighBetaDownValue1EditField.Value <= app.ThresholdEditField.Value*0.5
                                    b.CData(1,:) = [.3 .9 .3];
                                end

                                if app.YlimEditField.Value >= app.HighBetaDownValue1EditField.Value
                                    ylim(app.HighBetaDownProtocolUIAxes,[0 app.YlimEditField.Value]);
                                elseif app.YlimEditField.Value < app.HighBetaDownValue1EditField.Value
                                    ylim(app.HighBetaDownProtocolUIAxes,[0 app.HighBetaDownValue1EditField.Value]);
                                end
                            end
                        end

                        % stop recording
                        pause(10^-323)
                        if app.stopFlag == true
                            complete_record = "no";
                            break
                        end

                        f_i = f_i + 1;
                        f_j = f_j + f_j2;
                    end
                end
            end

            % save file
            if complete_record == "yes"
                event_text = app.event_text;

                try uisave({'rawdata', 'event_text'},'')
                catch, disp('Try again')
                end
            end

            % clear
            app.stopFlag = false;
            app.TriggerTextArea.Value = "";
            app.event_text = {};
            app.event_list = [];
            app.LossRateEditField.Value = 0;
            app.A1scoreEditField.Value = 0;
            app.HighBetaDownValue1EditField.Value = 0;
            app.HighBetaDownValue2EditField.Value = 0;
            app.TimeLabel.Text = '00 : 00 : 00';
            cla(app.RawDataUIAxes)
            cla(app.FilteredDataUIAxes)
            cla(app.FrequencySpectrumUIAxes)
            cla(app.BrainNetworkUIAxes)
            cla(app.AlphaAsymmetryProtocolUIAxes)
            cla(app.HighBetaDownProtocolUIAxes)
            cla(app.PlotTopographyUIAxes)
            cla(app.PlotConnectivityUIAxes)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;

        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            try
                selectedFolder = uigetdir('C:\', 'Select a file');
                addpath(genpath(selectedFolder));
            catch
            end

        end

        % Button pushed function: TriggerButton
        function TriggerButtonPushed(app, event)
            global rawdata

            app.event_text = {};
            app.event_list(end+1) = length(rawdata);
            for k = 1:1:length(app.event_list)
                app.event_text{end+1} = strcat(num2str(k), ':', 'time', '[', num2str(app.event_list(k)/app.sample_rate), ']', '_', 'sample', '[', num2str(app.event_list(k)), ']');
            end
            app.TriggerTextArea.Value = strjoin(app.event_text, newline);

        end

        % Selection changed function: FrequencyRangeofValuesButtonGroup
        function FrequencyRangeofValuesButtonGroupSelectionChanged(app, event)
            if app.TypeofEEGButton.Value == 1
                app.TypeofEEGListBox.Enable = "on";
                app.LowRangeSpinner2.Enable = "off"; app.HighRangeSpinner2.Enable = "off";
            elseif app.CustomizationButton.Value == 1
                app.TypeofEEGListBox.Enable = "off";
                app.LowRangeSpinner2.Enable = "on"; app.HighRangeSpinner2.Enable = "on";
            end

        end

        % Selection changed function: EdgeButtonGroup
        function EdgeButtonGroupSelectionChanged(app, event)
            if app.NodetoNodeButton.Value == true
                app.EdgeCheckBox.Enable = 'on'; app.EdgeCheckBox2.Enable = 'on';
                app.EdgeCheckBox3.Enable = 'on'; app.EdgeCheckBox4.Enable = 'on';
            else
                app.EdgeCheckBox.Value = false; app.EdgeCheckBox2.Value = false;
                app.EdgeCheckBox3.Value = false; app.EdgeCheckBox4.Value = false;
                app.EdgeCheckBox.Enable = 'off'; app.EdgeCheckBox2.Enable = 'off';
                app.EdgeCheckBox3.Enable = 'off'; app.EdgeCheckBox4.Enable = 'off';
            end

        end

        % Selection changed function: TypeofFourierTransformButtonGroup
        function TypeofFourierTransformButtonGroupSelectionChanged(app, event)
            if app.FourierTransformFFTButton.Value == 1
                app.PSDButton.Enable = "off";
                app.LogPSDButton.Enable = "off";
                app.WindowOverlapEditFieldLabel.Enable = "off";
                app.WindowLengthEditFieldLabel.Enable = "off";
                app.WindowOverlapEditFieldLabel2.Enable = "off";
                app.WindowOverlapEditField.Enable = "off";
                app.WindowLengthEditFieldLabel2.Enable = "off";
                app.WindowLengthEditField.Enable = "off";
                if app.PSDButton.Value == 1 || app.LogPSDButton.Value == 1
                    app.AmplitudeButton.Value = 1;
                    app.AmplitudeLabel.Text = "(Amplitude)";
                    app.AmplitudeLabel.FontColor = [0.07,0.62,1.00];
                    ylabel(app.FrequencySpectrumUIAxes, 'Amplitude (µV)')
                    ylabel(app.HighBetaDownProtocolUIAxes, 'Amplitude (µV)')
                    app.Amplitude1Label.Text = "Amplitude 1";
                    app.Amplitude2Label.Text = "Amplitude 2";
                end
            elseif app.PowerSpectrumDensityWelchButton.Value == 1
                app.PSDButton.Enable = "on";
                app.LogPSDButton.Enable = "on";
                app.WindowOverlapEditFieldLabel.Enable = "on";
                app.WindowLengthEditFieldLabel.Enable = "on";
                app.WindowOverlapEditFieldLabel2.Enable = "on";
                app.WindowOverlapEditField.Enable = "on";
                app.WindowLengthEditFieldLabel2.Enable = "on";
                app.WindowLengthEditField.Enable = "on";
            end

        end

        % Selection changed function: YAxisButtonGroup
        function YAxisButtonGroupSelectionChanged(app, event)
            if app.AmplitudeButton.Value == 1
                app.AmplitudeLabel.Text = "(Amplitude)";
                app.AmplitudeLabel.FontColor = [0.07,0.62,1.00];
                ylabel(app.FrequencySpectrumUIAxes, 'Amplitude (µV)')
                ylabel(app.HighBetaDownProtocolUIAxes, 'Amplitude (µV)')
                app.Amplitude1Label.Text = "Amplitude 1";
                app.Amplitude2Label.Text = "Amplitude 2";
            elseif app.PowerButton.Value == 1
                app.AmplitudeLabel.Text = "(Power)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.FrequencySpectrumUIAxes, 'Power (µV^2)')
                ylabel(app.HighBetaDownProtocolUIAxes, 'Power (µV^2)')
                app.Amplitude1Label.Text = "Power 1";
                app.Amplitude2Label.Text = "Power 2";
            elseif app.PSDButton.Value == 1
                app.AmplitudeLabel.Text = "(PSD)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.FrequencySpectrumUIAxes, 'PSD (µV^2/Hz)')
                ylabel(app.HighBetaDownProtocolUIAxes, 'PSD (µV^2/Hz)')
                app.Amplitude1Label.Text = "PSD 1";
                app.Amplitude2Label.Text = "PSD 2";
            elseif app.LogPSDButton.Value == 1
                app.AmplitudeLabel.Text = "(PSD)";
                app.AmplitudeLabel.FontColor = [1.00,0.41,0.16];
                ylabel(app.FrequencySpectrumUIAxes, 'Log PSD 10*log_{10} (µV^2/Hz)')
                app.Amplitude1Label.Text = "PSD 1";
                app.Amplitude2Label.Text = "PSD 2";
            end

        end

        % Selection changed function: AnalysisIndexButtonGroup
        function AnalysisIndexButtonGroupSelectionChanged(app, event)
            if app.CorrelationButton.Value == 1
                app.RangeLabel2.Enable = "off"; app.WindowLabel4.Enable = "off";
                app.LowRangeSpinner3.Enable = "off"; app.HighRangeSpinner3.Enable = "off";
            elseif app.CoherenceButton.Value == 1
                app.RangeLabel2.Enable = "on"; app.WindowLabel4.Enable = "on";
                app.LowRangeSpinner3.Enable = "on"; app.HighRangeSpinner3.Enable = "on";
            elseif app.PLVButton.Value == 1
                app.RangeLabel2.Enable = "on"; app.WindowLabel4.Enable = "on";
                app.LowRangeSpinner3.Enable = "on"; app.HighRangeSpinner3.Enable = "on";
            elseif app.PLIButton.Value == 1
                app.RangeLabel2.Enable = "off"; app.WindowLabel4.Enable = "off";
                app.LowRangeSpinner3.Enable = "off"; app.HighRangeSpinner3.Enable = "off";
            end
        end

        % Menu selected function: TimeDomainMenu
        function TimeDomainMenuSelected(app, event)
            app.timeseriesApp = Timeseries_plot;

        end

        % Menu selected function: BrainActivityMenu
        function BrainActivityMenuSelected(app, event)
            app.frequencyApp = Frequency_plot;

        end

        % Menu selected function: BrainNetworkMenu
        function BrainNetworkMenuSelected(app, event)
            app.connApp = Connectivity_plot;

        end

        % Menu selected function: ChannelSelectionMenu
        function ChannelSelectionMenuSelected(app, event)
            app.channelselectionApp = Channel_selection;
            close(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OfflineGUIMenu = uimenu(app.UIFigure);
            app.OfflineGUIMenu.Text = '  Offline GUI';
            app.TimeDomainMenu = uimenu(app.OfflineGUIMenu);
            app.TimeDomainMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeDomainMenuSelected, true);
            app.TimeDomainMenu.Text = 'Time Domain';
            app.BrainActivityMenu = uimenu(app.OfflineGUIMenu);
            app.BrainActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainActivityMenuSelected, true);
            app.BrainActivityMenu.Text = 'Brain Activity';
            app.BrainNetworkMenu = uimenu(app.OfflineGUIMenu);
            app.BrainNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainNetworkMenuSelected, true);
            app.BrainNetworkMenu.Text = 'Brain Network';
            app.SetupGUIMenu = uimenu(app.UIFigure);
            app.SetupGUIMenu.Text = '  Setup GUI';
            app.ChannelSelectionMenu = uimenu(app.SetupGUIMenu);
            app.ChannelSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ChannelSelectionMenuSelected, true);
            app.ChannelSelectionMenu.Text = 'Channel Selection';
            app.FilteredDataUIAxes = uiaxes(app.UIFigure);
            title(app.FilteredDataUIAxes, 'Filtered Data')
            zlabel(app.FilteredDataUIAxes, 'Z')
            app.FilteredDataUIAxes.XTick = [];
            app.FilteredDataUIAxes.XTickLabel = {''; ''};
            app.FilteredDataUIAxes.YTick = [];
            app.RawDataUIAxes = uiaxes(app.UIFigure);
            title(app.RawDataUIAxes, 'Raw Data')
            zlabel(app.RawDataUIAxes, 'Z')
            app.RawDataUIAxes.XTick = [];
            app.RawDataUIAxes.XTickLabel = '';
            app.RawDataUIAxes.YTick = [];
            app.PlotTopographyUIAxes = uiaxes(app.UIFigure);
            title(app.PlotTopographyUIAxes, 'Brain Activity Map')
            zlabel(app.PlotTopographyUIAxes, 'Z')
            app.PlotTopographyUIAxes.XTick = [];
            app.PlotTopographyUIAxes.YTick = [];
            app.PlotConnectivityUIAxes = uiaxes(app.UIFigure);
            title(app.PlotConnectivityUIAxes, 'Brain Network Map')
            zlabel(app.PlotConnectivityUIAxes, 'Z')
            app.PlotConnectivityUIAxes.XTick = [];
            app.PlotConnectivityUIAxes.YTick = [];
            app.lossrateLabel = uilabel(app.UIFigure);
            app.lossrateLabel.Text = 'Loss Rate (%)';
            app.LossRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.LossRateEditField.ValueDisplayFormat = '%.3f';
            app.LossRateEditField.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FrequencySpectrumTab = uitab(app.TabGroup2);
            app.FrequencySpectrumTab.Title = 'Frequency Spectrum';
            app.FrequencySpectrumUIAxes = uiaxes(app.FrequencySpectrumTab);
            xlabel(app.FrequencySpectrumUIAxes, 'Hz')
            ylabel(app.FrequencySpectrumUIAxes, 'Amplitude (µV)')
            zlabel(app.FrequencySpectrumUIAxes, 'Z')
            app.FrequencySpectrumUIAxes.XTick = [];
            app.FrequencySpectrumUIAxes.YTick = [];
            app.BrainNetworkTab = uitab(app.TabGroup2);
            app.BrainNetworkTab.Title = 'Brain Network';
            app.BrainNetworkUIAxes = uiaxes(app.BrainNetworkTab);
            xlabel(app.BrainNetworkUIAxes, 'Ch 1 -- Ch 2')
            ylabel(app.BrainNetworkUIAxes, 'Connectivity Value')
            zlabel(app.BrainNetworkUIAxes, 'Z')
            app.BrainNetworkUIAxes.XTick = [];
            app.BrainNetworkUIAxes.YTick = [];
            app.AlphaAsymmetryProtocolTab = uitab(app.TabGroup2);
            app.AlphaAsymmetryProtocolTab.Title = 'Alpha Asymmetry Protocol';
            app.Panel_8 = uipanel(app.AlphaAsymmetryProtocolTab);
            app.AlphaAsymmetryProtocolUIAxes = uiaxes(app.Panel_8);
            title(app.AlphaAsymmetryProtocolUIAxes, 'Alpha asymmetry protocol')
            xlabel(app.AlphaAsymmetryProtocolUIAxes, 'A1 score')
            zlabel(app.AlphaAsymmetryProtocolUIAxes, 'Z')
            app.AlphaAsymmetryProtocolUIAxes.XTick = [];
            app.AlphaAsymmetryProtocolUIAxes.YTick = [];
            app.Panel_9 = uipanel(app.Panel_8);
            app.XlimEditFieldLabel = uilabel(app.Panel_9);
            app.XlimEditFieldLabel.Text = 'Xlim';
            app.XlimEditField = uieditfield(app.Panel_9, 'numeric');
            app.XlimEditField.Limits = [0 Inf];
            app.XlimEditField.Value = 2;
            app.EEGTypeListBoxLabel = uilabel(app.Panel_9);
            app.EEGTypeListBoxLabel.Text = 'EEG Type';
            app.EEGTypeListBox = uilistbox(app.Panel_9);
            app.EEGTypeListBox.Items = {'Alpha'};
            app.EEGTypeListBox.Value = 'Alpha';
            app.ThresholdEditField_2Label = uilabel(app.Panel_9);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField_3 = uieditfield(app.Panel_9, 'numeric');
            app.RchannelListBoxLabel = uilabel(app.Panel_9);
            app.RchannelListBoxLabel.Text = 'Rchannel';
            app.AlphaAsymmetryRchannelListBox = uilistbox(app.Panel_9);
            app.AlphaAsymmetryRchannelListBox.Items = {'FP2', 'F2', 'F4', 'F6', 'F8', 'F10', 'FC2', 'FC4', 'FC6', 'FT8', 'FT10'};
            app.AlphaAsymmetryRchannelListBox.Value = {};
            app.LchannelListBoxLabel = uilabel(app.Panel_9);
            app.LchannelListBoxLabel.Text = 'Lchannel';
            app.AlphaAsymmetryLchannelListBox = uilistbox(app.Panel_9);
            app.AlphaAsymmetryLchannelListBox.Items = {'FP1', 'F1', 'F3', 'F5', 'F7', 'F9', 'FC1', 'FC3', 'FC5', 'FT7', 'FT9'};
            app.AlphaAsymmetryLchannelListBox.Value = {};
            app.A1scoreEditFieldLabel = uilabel(app.Panel_9);
            app.A1scoreEditFieldLabel.Text = 'A1 score';
            app.A1scoreEditField = uieditfield(app.Panel_9, 'numeric');
            app.A1scoreEditField.Editable = 'off';
            app.HighBetaDownProtocolTab = uitab(app.TabGroup2);
            app.HighBetaDownProtocolTab.Title = 'High Beta Down Protocol';
            app.Panel_10 = uipanel(app.HighBetaDownProtocolTab);
            app.HighBetaDownProtocolUIAxes = uiaxes(app.Panel_10);
            title(app.HighBetaDownProtocolUIAxes, 'High beta down protocol')
            ylabel(app.HighBetaDownProtocolUIAxes, 'Amplitude (µV)')
            zlabel(app.HighBetaDownProtocolUIAxes, 'Z')
            app.HighBetaDownProtocolUIAxes.XTick = [];
            app.HighBetaDownProtocolUIAxes.YTick = [];
            app.Panel_11 = uipanel(app.Panel_10);
            app.YlimEditFieldLabel = uilabel(app.Panel_11);
            app.YlimEditFieldLabel.Text = 'Ylim';
            app.YlimEditField = uieditfield(app.Panel_11, 'numeric');
            app.YlimEditField.Limits = [0 Inf];
            app.YlimEditField.ValueDisplayFormat = '%.0f';
            app.YlimEditField.Value = 30;
            app.EEGTypeListBox_2Label = uilabel(app.Panel_11);
            app.EEGTypeListBox_2Label.Text = 'EEG Type';
            app.EEGTypeListBox2 = uilistbox(app.Panel_11);
            app.EEGTypeListBox2.Items = {'High beta'};
            app.EEGTypeListBox2.Value = 'High beta';
            app.ThresholdEditField_3Label = uilabel(app.Panel_11);
            app.ThresholdEditField_3Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel_11, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 15;
            app.Channel1Label = uilabel(app.Panel_11);
            app.Channel1Label.Text = 'Channel 1';
            app.HighBetaDownChannel1ListBox = uilistbox(app.Panel_11);
            app.HighBetaDownChannel1ListBox.Items = {};
            app.HighBetaDownChannel1ListBox.Value = {};
            app.Channel2Label = uilabel(app.Panel_11);
            app.Channel2Label.Text = 'Channel 2';
            app.HighBetaDownChannel2ListBox = uilistbox(app.Panel_11);
            app.HighBetaDownChannel2ListBox.Items = {};
            app.HighBetaDownChannel2ListBox.Value = {};
            app.HighBetaDownValue1EditField = uieditfield(app.Panel_11, 'numeric');
            app.HighBetaDownValue1EditField.Editable = 'off';
            app.ThresholdEditField_3Label_2 = uilabel(app.Panel_11);
            app.ThresholdEditField_3Label_2.Text = 'µV';
            app.HighBetaDownValue2EditField = uieditfield(app.Panel_11, 'numeric');
            app.HighBetaDownValue2EditField.Editable = 'off';
            app.HighBetaDownChannel2CheckBox = uicheckbox(app.Panel_11);
            app.HighBetaDownChannel2CheckBox.Text = '';
            app.HighBetaDownChannel2CheckBox.Value = true;
            app.Amplitude1Label = uilabel(app.Panel_11);
            app.Amplitude1Label.Text = 'Amplitude 1';
            app.Amplitude2Label = uilabel(app.Panel_11);
            app.Amplitude2Label.Text = 'Amplitude 2';
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.TFDomainSignalProcessingTab = uitab(app.TabGroup4);
            app.TFDomainSignalProcessingTab.Title = 'T - F Domain & Signal Processing';
            app.TimeDomainPanel = uipanel(app.TFDomainSignalProcessingTab);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.FilterButtonGroup = uibuttongroup(app.TimeDomainPanel);
            app.FilterButtonGroup.Title = 'Filtering';
            app.BandpassFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.BandpassFilterButton.Value = true;
            app.NotchFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.WindowLabel = uilabel(app.FilterButtonGroup);
            app.WindowLabel.Text = '~';
            app.LowCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.LowCutOffSpinner.Limits = [0 Inf];
            app.LowCutOffSpinner.Value = 4;
            app.HighCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.HighCutOffSpinner.Limits = [0 Inf];
            app.HighCutOffSpinner.Value = 30;
            app.FilteredDataLabel = uilabel(app.TimeDomainPanel);
            app.FilteredDataLabel.Text = 'Filtered Data';
            app.ScalePanel_2 = uipanel(app.TimeDomainPanel);
            app.ScalePanel_2.Title = 'Scale';
            app.ScaleSpinner = uispinner(app.ScalePanel_2);
            app.ScaleSpinner.Limits = [1 99];
            app.ScaleSpinner.Value = 1;
            app.SignalProcessingPanel = uipanel(app.TFDomainSignalProcessingTab);
            app.SignalProcessingPanel.Title = 'Signal Processing';
            app.DetrendCheckBox = uicheckbox(app.SignalProcessingPanel);
            app.DetrendCheckBox.Text = 'Detrend';
            app.ICAArtifactsRemovalCheckBox = uicheckbox(app.SignalProcessingPanel);
            app.ICAArtifactsRemovalCheckBox.Text = 'ICA +Artifacts Removal';
            app.FDomainBrainActivityandNetworkLabel = uilabel(app.SignalProcessingPanel);
            app.FDomainBrainActivityandNetworkLabel.Text = 'F Domain & Brain Activity and Network';
            app.CommonAverageReferenceCheckBox = uicheckbox(app.SignalProcessingPanel);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.BaselineCorrectionCheckBox = uicheckbox(app.SignalProcessingPanel);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineTimesSpinnerLabel = uilabel(app.SignalProcessingPanel);
            app.BaselineTimesSpinnerLabel.Text = '▷ Baseline Time (s)';
            app.BaselineTimesSpinner = uispinner(app.SignalProcessingPanel);
            app.BaselineTimesSpinner.Step = 0.1;
            app.BaselineTimesSpinner.Limits = [0 Inf];
            app.BaselineTimesSpinner.ValueDisplayFormat = '%.1f';
            app.BaselineTimesSpinner.Value = 10;
            app.MuscleButton = uibutton(app.SignalProcessingPanel, 'state');
            app.MuscleButton.Text = 'Muscle';
            app.MuscleButton.Value = true;
            app.ChannelNoiseButton = uibutton(app.SignalProcessingPanel, 'state');
            app.ChannelNoiseButton.Text = 'Channel Noise';
            app.EyeButton = uibutton(app.SignalProcessingPanel, 'state');
            app.EyeButton.Text = 'Eye';
            app.EyeButton.Value = true;
            app.HeartButton = uibutton(app.SignalProcessingPanel, 'state');
            app.HeartButton.Text = 'Heart';
            app.LineNoiseButton = uibutton(app.SignalProcessingPanel, 'state');
            app.LineNoiseButton.Text = 'Line Noise';
            app.MuscleEditField = uieditfield(app.SignalProcessingPanel, 'numeric');
            app.MuscleEditField.Limits = [0 100];
            app.MuscleEditField.ValueDisplayFormat = '%.0f';
            app.MuscleEditField.Value = 90;
            app.MuscleLabel = uilabel(app.SignalProcessingPanel);
            app.MuscleLabel.Text = '%';
            app.EyeEditField = uieditfield(app.SignalProcessingPanel, 'numeric');
            app.EyeEditField.Limits = [0 100];
            app.EyeEditField.ValueDisplayFormat = '%.0f';
            app.EyeEditField.Value = 90;
            app.EyeLabel = uilabel(app.SignalProcessingPanel);
            app.EyeLabel.Text = '%';
            app.HeartEditField = uieditfield(app.SignalProcessingPanel, 'numeric');
            app.HeartEditField.Limits = [0 100];
            app.HeartEditField.ValueDisplayFormat = '%.0f';
            app.HeartEditField.Value = 90;
            app.MuscleLabel_2 = uilabel(app.SignalProcessingPanel);
            app.MuscleLabel_2.Text = '%';
            app.LineNoiseEditField = uieditfield(app.SignalProcessingPanel, 'numeric');
            app.LineNoiseEditField.Limits = [0 100];
            app.LineNoiseEditField.ValueDisplayFormat = '%.0f';
            app.LineNoiseEditField.Value = 90;
            app.MuscleLabel_3 = uilabel(app.SignalProcessingPanel);
            app.MuscleLabel_3.Text = '%';
            app.ChannelNoiseEditField = uieditfield(app.SignalProcessingPanel, 'numeric');
            app.ChannelNoiseEditField.Limits = [0 100];
            app.ChannelNoiseEditField.ValueDisplayFormat = '%.0f';
            app.ChannelNoiseEditField.Value = 90;
            app.MuscleLabel_4 = uilabel(app.SignalProcessingPanel);
            app.MuscleLabel_4.Text = '%';
            app.TabGroup5 = uitabgroup(app.TFDomainSignalProcessingTab);
            app.FrequencyDomainTab = uitab(app.TabGroup5);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.TypeofFrequencySpectrumButtonGroup = uibuttongroup(app.FrequencyDomainTab);
            app.TypeofFrequencySpectrumButtonGroup.Title = 'Type of Frequency Spectrum';
            app.AllButton = uiradiobutton(app.TypeofFrequencySpectrumButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.ByTypeofEEGButton = uiradiobutton(app.TypeofFrequencySpectrumButtonGroup);
            app.ByTypeofEEGButton.Text = 'By Type of EEG';
            app.BarGraphCheckBox = uicheckbox(app.TypeofFrequencySpectrumButtonGroup);
            app.BarGraphCheckBox.Text = 'Bar Graph';
            app.BarGraphCheckBox.Value = true;
            app.XlimSpinnerLabel = uilabel(app.TypeofFrequencySpectrumButtonGroup);
            app.XlimSpinnerLabel.Text = 'Xlim';
            app.XlimSpinner = uispinner(app.TypeofFrequencySpectrumButtonGroup);
            app.XlimSpinner.Limits = [0 Inf];
            app.XlimSpinner.Value = 100;
            app.YlimSpinnerLabel = uilabel(app.TypeofFrequencySpectrumButtonGroup);
            app.YlimSpinnerLabel.Text = 'Ylim';
            app.YlimSpinner = uispinner(app.TypeofFrequencySpectrumButtonGroup);
            app.YlimSpinner.Limits = [0 Inf];
            app.YlimSpinner.Value = 100;
            app.YlimAutoCheckBox = uicheckbox(app.TypeofFrequencySpectrumButtonGroup);
            app.YlimAutoCheckBox.Text = '';
            app.FrequencySpectrumLabel = uilabel(app.FrequencyDomainTab);
            app.FrequencySpectrumLabel.Text = 'Frequency Spectrum';
            app.ChannelLabel_2 = uilabel(app.FrequencyDomainTab);
            app.ChannelLabel_2.Text = 'Channel';
            app.ChannelListBox = uilistbox(app.FrequencyDomainTab);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Value = {};
            app.FourierTransformTab = uitab(app.TabGroup5);
            app.FourierTransformTab.Title = 'Fourier Transform';
            app.FourierOptionPanel = uipanel(app.FourierTransformTab);
            app.FourierOptionPanel.Title = 'Fourier Option';
            app.WindowLengthEditField = uieditfield(app.FourierOptionPanel, 'numeric');
            app.WindowLengthEditField.Enable = 'off';
            app.WindowLengthEditField.Value = 2;
            app.WindowLengthEditFieldLabel2 = uilabel(app.FourierOptionPanel);
            app.WindowLengthEditFieldLabel2.Enable = 'off';
            app.WindowLengthEditFieldLabel2.Text = 's';
            app.WindowLengthEditFieldLabel = uilabel(app.FourierOptionPanel);
            app.WindowLengthEditFieldLabel.Enable = 'off';
            app.WindowLengthEditFieldLabel.Text = 'Window Length:';
            app.WindowOverlapEditField = uieditfield(app.FourierOptionPanel, 'numeric');
            app.WindowOverlapEditField.Enable = 'off';
            app.WindowOverlapEditField.Value = 50;
            app.WindowOverlapEditFieldLabel2 = uilabel(app.FourierOptionPanel);
            app.WindowOverlapEditFieldLabel2.Enable = 'off';
            app.WindowOverlapEditFieldLabel2.Text = '%';
            app.WindowOverlapEditFieldLabel = uilabel(app.FourierOptionPanel);
            app.WindowOverlapEditFieldLabel.Enable = 'off';
            app.WindowOverlapEditFieldLabel.Text = 'Window Overlap:';
            app.YAxisButtonGroup = uibuttongroup(app.FourierTransformTab);
            app.YAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisButtonGroupSelectionChanged, true);
            app.YAxisButtonGroup.Title = 'Y-Axis';
            app.AmplitudeButton = uiradiobutton(app.YAxisButtonGroup);
            app.AmplitudeButton.Text = 'Amplitude';
            app.AmplitudeButton.Value = true;
            app.PowerButton = uiradiobutton(app.YAxisButtonGroup);
            app.PowerButton.Text = 'Power';
            app.LogPSDButton = uiradiobutton(app.YAxisButtonGroup);
            app.LogPSDButton.Enable = 'off';
            app.LogPSDButton.Text = 'Log(PSD)';
            app.PSDButton = uiradiobutton(app.YAxisButtonGroup);
            app.PSDButton.Enable = 'off';
            app.PSDButton.Text = 'PSD';
            app.TypeofFourierTransformButtonGroup = uibuttongroup(app.FourierTransformTab);
            app.TypeofFourierTransformButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofFourierTransformButtonGroupSelectionChanged, true);
            app.TypeofFourierTransformButtonGroup.Title = 'Type of Fourier Transform';
            app.PowerSpectrumDensityWelchButton = uiradiobutton(app.TypeofFourierTransformButtonGroup);
            app.PowerSpectrumDensityWelchButton.Text = 'Power Spectrum Density (Welch)';
            app.FourierTransformFFTButton = uiradiobutton(app.TypeofFourierTransformButtonGroup);
            app.FourierTransformFFTButton.Text = 'Fourier Transform (FFT)';
            app.FourierTransformFFTButton.Value = true;
            app.BrainActivityandNetworkTab = uitab(app.TabGroup4);
            app.BrainActivityandNetworkTab.Title = 'Brain Activity and Network';
            app.BrainActivityMapPanel = uipanel(app.BrainActivityandNetworkTab);
            app.BrainActivityMapPanel.Title = 'Brain Activity Map';
            app.TypeofEEGLabel = uilabel(app.BrainActivityMapPanel);
            app.TypeofEEGLabel.Text = 'Type of EEG';
            app.TypeofEEGListBox = uilistbox(app.BrainActivityMapPanel);
            app.TypeofEEGListBox.Items = {'Delta', 'Theta', 'Alpha', 'Smr', 'Beta', 'High Beta', 'Gamma'};
            app.TypeofEEGListBox.Value = 'Delta';
            app.FrequencyRangeofValuesLabel = uilabel(app.BrainActivityMapPanel);
            app.FrequencyRangeofValuesLabel.Text = 'Frequency Range of Values';
            app.FrequencyRangeofValuesButtonGroup = uibuttongroup(app.BrainActivityMapPanel);
            app.FrequencyRangeofValuesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyRangeofValuesButtonGroupSelectionChanged, true);
            app.TypeofEEGButton = uiradiobutton(app.FrequencyRangeofValuesButtonGroup);
            app.TypeofEEGButton.Text = 'Type of EEG';
            app.TypeofEEGButton.Value = true;
            app.CustomizationButton = uiradiobutton(app.FrequencyRangeofValuesButtonGroup);
            app.CustomizationButton.Text = 'Customization';
            app.LowRangeSpinner2 = uispinner(app.BrainActivityMapPanel);
            app.LowRangeSpinner2.Limits = [0 Inf];
            app.LowRangeSpinner2.Enable = 'off';
            app.LowRangeSpinner2.Value = 1;
            app.HighRangeSpinner2 = uispinner(app.BrainActivityMapPanel);
            app.HighRangeSpinner2.Limits = [0 Inf];
            app.HighRangeSpinner2.Enable = 'off';
            app.HighRangeSpinner2.Value = 11;
            app.CustomizationLabel = uilabel(app.BrainActivityMapPanel);
            app.CustomizationLabel.Text = 'Customization';
            app.WindowLabel3 = uilabel(app.BrainActivityMapPanel);
            app.WindowLabel3.Text = '~';
            app.AmplitudeLabel = uilabel(app.BrainActivityMapPanel);
            app.AmplitudeLabel.Text = '(Amplitude)';
            app.BrainNetworkMapPanel = uipanel(app.BrainActivityandNetworkTab);
            app.BrainNetworkMapPanel.Title = 'Brain Network Map';
            app.AnalysisIndexButtonGroup = uibuttongroup(app.BrainNetworkMapPanel);
            app.AnalysisIndexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisIndexButtonGroupSelectionChanged, true);
            app.CoherenceButton = uiradiobutton(app.AnalysisIndexButtonGroup);
            app.CoherenceButton.Text = 'Coherence';
            app.CorrelationButton = uiradiobutton(app.AnalysisIndexButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.PLIButton = uiradiobutton(app.AnalysisIndexButtonGroup);
            app.PLIButton.Text = 'PLI';
            app.PLVButton = uiradiobutton(app.AnalysisIndexButtonGroup);
            app.PLVButton.Text = 'PLV';
            app.ConnectivityMetricLabel = uilabel(app.BrainNetworkMapPanel);
            app.ConnectivityMetricLabel.Text = 'Connectivity Metric';
            app.TypeofEdgeVisualizationLabel = uilabel(app.BrainNetworkMapPanel);
            app.TypeofEdgeVisualizationLabel.Text = 'Type of Edge Visualization';
            app.EdgeButtonGroup = uibuttongroup(app.BrainNetworkMapPanel);
            app.EdgeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgeButtonGroupSelectionChanged, true);
            app.AllEdgeButton = uiradiobutton(app.EdgeButtonGroup);
            app.AllEdgeButton.Text = 'All Edge';
            app.AllEdgeButton.Value = true;
            app.NodetoNodeButton = uiradiobutton(app.EdgeButtonGroup);
            app.NodetoNodeButton.Text = 'Node to Node';
            app.EdgePanel = uipanel(app.BrainNetworkMapPanel);
            app.EdgePanel.Title = 'Node to Node';
            app.NodeDropDown1_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown1_1.Items = {};
            app.NodeDropDown1_1.Value = {};
            app.NodeDropDown1_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown1_2.Items = {};
            app.NodeDropDown1_2.Value = {};
            app.EdgeCheckBox = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox.Enable = 'off';
            app.EdgeCheckBox.Text = '';
            app.EdgeLabel = uilabel(app.EdgePanel);
            app.EdgeLabel.Text = '↔';
            app.NodeDropDown2_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown2_1.Items = {};
            app.NodeDropDown2_1.Value = {};
            app.NodeDropDown2_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown2_2.Items = {};
            app.NodeDropDown2_2.Value = {};
            app.EdgeCheckBox2 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox2.Enable = 'off';
            app.EdgeCheckBox2.Text = '';
            app.EdgeLabel2 = uilabel(app.EdgePanel);
            app.EdgeLabel2.Text = '↔';
            app.NodeDropDown3_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown3_1.Items = {};
            app.NodeDropDown3_1.Value = {};
            app.NodeDropDown3_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown3_2.Items = {};
            app.NodeDropDown3_2.Value = {};
            app.EdgeCheckBox3 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox3.Enable = 'off';
            app.EdgeCheckBox3.Text = '';
            app.EdgeLabel3 = uilabel(app.EdgePanel);
            app.EdgeLabel3.Text = '↔';
            app.NodeDropDown4_1 = uidropdown(app.EdgePanel);
            app.NodeDropDown4_1.Items = {};
            app.NodeDropDown4_1.Value = {};
            app.NodeDropDown4_2 = uidropdown(app.EdgePanel);
            app.NodeDropDown4_2.Items = {};
            app.NodeDropDown4_2.Value = {};
            app.EdgeCheckBox4 = uicheckbox(app.EdgePanel);
            app.EdgeCheckBox4.Enable = 'off';
            app.EdgeCheckBox4.Text = '';
            app.EdgeLabel4 = uilabel(app.EdgePanel);
            app.EdgeLabel4.Text = '↔';
            app.ThresholdPanel = uipanel(app.BrainNetworkMapPanel);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdSpinner = uispinner(app.ThresholdPanel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.Value = 0.8;
            app.ColormapRangePanel = uipanel(app.BrainNetworkMapPanel);
            app.ColormapRangePanel.Title = 'Colormap Range';
            app.AbsoluteValuesCheckBox = uicheckbox(app.ColormapRangePanel);
            app.AbsoluteValuesCheckBox.Text = 'Absolute Values';
            app.ColormapRangeCheckBox = uicheckbox(app.ColormapRangePanel);
            app.ColormapRangeCheckBox.Text = 'Range [0 1] <= [-1 1]';
            app.ColormapRangeCheckBox.Value = true;
            app.FrequencyBandPanel = uipanel(app.BrainNetworkMapPanel);
            app.FrequencyBandPanel.Title = 'Frequency Band';
            app.WindowLabel4 = uilabel(app.FrequencyBandPanel);
            app.WindowLabel4.Enable = 'off';
            app.WindowLabel4.Text = '~';
            app.LowRangeSpinner3 = uispinner(app.FrequencyBandPanel);
            app.LowRangeSpinner3.Limits = [0 Inf];
            app.LowRangeSpinner3.Enable = 'off';
            app.LowRangeSpinner3.Value = 1;
            app.HighRangeSpinner3 = uispinner(app.FrequencyBandPanel);
            app.HighRangeSpinner3.Limits = [0 Inf];
            app.HighRangeSpinner3.Enable = 'off';
            app.HighRangeSpinner3.Value = 4;
            app.RangeLabel2 = uilabel(app.FrequencyBandPanel);
            app.RangeLabel2.Enable = 'off';
            app.RangeLabel2.Text = 'Range:';
            app.DataRecordingPanel = uipanel(app.UIFigure);
            app.DataRecordingPanel.Title = 'Data Recording';
            app.Panel_6 = uipanel(app.DataRecordingPanel);
            app.HourSpinnerLabel = uilabel(app.Panel_6);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.Panel_6);
            app.HourSpinner.Limits = [0 Inf];
            app.MinutesSpinnerLabel = uilabel(app.Panel_6);
            app.MinutesSpinnerLabel.Text = 'Minutes';
            app.MinutesSpinner = uispinner(app.Panel_6);
            app.MinutesSpinner.Limits = [0 Inf];
            app.MinutesSpinner.Value = 1;
            app.SecondsSpinnerLabel = uilabel(app.Panel_6);
            app.SecondsSpinnerLabel.Text = 'Seconds';
            app.SecondsSpinner = uispinner(app.Panel_6);
            app.SecondsSpinner.Limits = [0 Inf];
            app.SecondsSpinner.Value = 15;
            app.RecordButton = uibutton(app.DataRecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.DataRecordingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetPathButton = uibutton(app.DataRecordingPanel, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            app.TriggerButton = uibutton(app.DataRecordingPanel, 'push');
            app.TriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerButtonPushed, true);
            app.TriggerButton.Text = 'Trigger';
            app.TriggerTextArea = uitextarea(app.DataRecordingPanel);
            app.TriggerTextArea.Editable = 'off';
            app.ColorMapButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.jetButton = uiradiobutton(app.ColorMapButtonGroup);
            app.jetButton.Text = 'jet';
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.turboButton = uiradiobutton(app.ColorMapButtonGroup);
            app.turboButton.Text = 'turbo';
            app.boneButton = uiradiobutton(app.ColorMapButtonGroup);
            app.boneButton.Text = 'bone';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            app.UpdateInformationPanel = uipanel(app.UIFigure);
            app.UpdateInformationPanel.Title = 'Update Information';
            app.DisplayFrequencySpectrumCheckBox = uicheckbox(app.UpdateInformationPanel);
            app.DisplayFrequencySpectrumCheckBox.Text = 'Display Frequency Spectrum';
            app.DisplayFrequencySpectrumCheckBox.Value = true;
            app.DisplayBrainActivityMapCheckBox = uicheckbox(app.UpdateInformationPanel);
            app.DisplayBrainActivityMapCheckBox.Text = 'Display Brain Activity Map';
            app.DisplayBrainActivityMapCheckBox.Value = true;
            app.DisplayBrainNetworkMapCheckBox = uicheckbox(app.UpdateInformationPanel);
            app.DisplayBrainNetworkMapCheckBox.Text = 'Display Brain Network Map';
            app.DisplayBrainNetworkMapCheckBox.Value = true;
            app.DisplayAlphaAsymmetryProtocolCheckBox = uicheckbox(app.UpdateInformationPanel);
            app.DisplayAlphaAsymmetryProtocolCheckBox.Text = 'Display Alpha Asymmetry Protocol';
            app.DisplayAlphaAsymmetryProtocolCheckBox.Value = true;
            app.DisplayHighBetaDownProtocolCheckBox = uicheckbox(app.UpdateInformationPanel);
            app.DisplayHighBetaDownProtocolCheckBox.Text = 'Display High Beta Down Protocol';
            app.DisplayHighBetaDownProtocolCheckBox.Value = true;
            app.UpdateIntervalSpinnerLabel = uilabel(app.UpdateInformationPanel);
            app.UpdateIntervalSpinnerLabel.Text = 'Update Interval';
            app.UpdateIntervalSpinner = uispinner(app.UpdateInformationPanel);
            app.UpdateIntervalSpinner.Step = 0.1;
            app.UpdateIntervalSpinner.Limits = [0 Inf];
            app.UpdateIntervalSpinner.ValueDisplayFormat = '%.1f';
            app.UpdateIntervalSpinner.Value = 4;
            app.Panel_18 = uipanel(app.UIFigure);
            app.DisplayTimeCheckBox = uicheckbox(app.Panel_18);
            app.DisplayTimeCheckBox.Text = 'Display Time';
            app.DisplayTimeCheckBox.Value = true;
            app.TimeLabel = uilabel(app.Panel_18);
            app.TimeLabel.Text = '00 : 00 : 00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neurofeedback_toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end