classdef P300_toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OfflineGUIMenu                  matlab.ui.container.Menu
        BrainNetworkMenu                matlab.ui.container.Menu
        SetupGUIMenu                    matlab.ui.container.Menu
        ChannelSelectionMenu            matlab.ui.container.Menu
        TabGroup8                       matlab.ui.container.TabGroup
        MeasurementAnalysisTab          matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        MeasurementPanel                matlab.ui.container.Panel
        TabGroup7                       matlab.ui.container.TabGroup
        DelayCompensationPassiveTab     matlab.ui.container.Tab
        DelayCompensationEditField      matlab.ui.control.NumericEditField
        DelayCompensationmsEditFieldLabel  matlab.ui.control.Label
        PassiveCheckBox                 matlab.ui.control.CheckBox
        ActiveTab                       matlab.ui.container.Tab
        TCPPortNumberEditField          matlab.ui.control.NumericEditField
        TCPPortNumberEditField_2Label   matlab.ui.control.Label
        ActiveCheckBox                  matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        ToneTab                         matlab.ui.container.Tab
        DistracterSpinnerLabel          matlab.ui.control.Label
        DistracterSpinner               matlab.ui.control.Spinner
        TargetSpinnerLabel              matlab.ui.control.Label
        TargetSpinner                   matlab.ui.control.Spinner
        StandardSpinnerLabel            matlab.ui.control.Label
        StandardSpinner                 matlab.ui.control.Spinner
        SoundFrequencyLabel             matlab.ui.control.Label
        SpeechEnglishTab                matlab.ui.container.Tab
        SpeedLabel                      matlab.ui.control.Label
        SpeedSpinner                    matlab.ui.control.Spinner
        DistracterSpinnerLabel_2        matlab.ui.control.Label
        TargetSpinnerLabel_2            matlab.ui.control.Label
        StandardSpinnerLabel_2          matlab.ui.control.Label
        DistracterEditField             matlab.ui.control.EditField
        TargetEditField                 matlab.ui.control.EditField
        StandardEditField               matlab.ui.control.EditField
        TextLabel                       matlab.ui.control.Label
        SpeechKoreanTab                 matlab.ui.container.Tab
        DistracterEditField2            matlab.ui.control.EditField
        DistracterSpinnerLabel_3        matlab.ui.control.Label
        TargetEditField2                matlab.ui.control.EditField
        TargetSpinnerLabel_3            matlab.ui.control.Label
        StandardSpinnerLabel_3          matlab.ui.control.Label
        StandardEditField2              matlab.ui.control.EditField
        SpeedLabel2                     matlab.ui.control.Label
        SpeedSpinner2                   matlab.ui.control.Spinner
        TextLabel2                      matlab.ui.control.Label
        Panel_22                        matlab.ui.container.Panel
        Task2EditField                  matlab.ui.control.EditField
        Task2EditFieldLabel             matlab.ui.control.Label
        Task1EditField                  matlab.ui.control.EditField
        Task1EditFieldLabel             matlab.ui.control.Label
        HourSpinner                     matlab.ui.control.Spinner
        HourSpinnerLabel                matlab.ui.control.Label
        NumofDistracterSpinnerLabel     matlab.ui.control.Label
        NumofDistracterSpinner          matlab.ui.control.Spinner
        NumofStandardSpinnerLabel       matlab.ui.control.Label
        NumofStandardSpinner            matlab.ui.control.Spinner
        NumofTargetSpinnerLabel         matlab.ui.control.Label
        NumofTargetSpinner              matlab.ui.control.Spinner
        InterStimulusIntervalmsSpinner_2Label  matlab.ui.control.Label
        InterStimulusIntervalmsSpinner  matlab.ui.control.Spinner
        NumofStimuliSpinner_2Label      matlab.ui.control.Label
        NumofStimuliSpinner             matlab.ui.control.Spinner
        Task1Task2CheckBox              matlab.ui.control.CheckBox
        Task1CheckBox                   matlab.ui.control.CheckBox
        SecondsSpinner                  matlab.ui.control.Spinner
        SecondsSpinnerLabel             matlab.ui.control.Label
        MinutesSpinner                  matlab.ui.control.Spinner
        MinutesSpinnerLabel             matlab.ui.control.Label
        RestingLabel                    matlab.ui.control.Label
        OddballThreeStimuliLabel        matlab.ui.control.Label
        TypeofAuditoryStimulationButtonGroup  matlab.ui.container.ButtonGroup
        SpeechKoreanButton              matlab.ui.control.RadioButton
        SpeechEnglishButton             matlab.ui.control.RadioButton
        ToneButton                      matlab.ui.control.RadioButton
        DistracterSoundButton           matlab.ui.control.Button
        StandardSoundButton             matlab.ui.control.Button
        TargetSoundButton               matlab.ui.control.Button
        TriggerTextArea                 matlab.ui.control.TextArea
        TriggerButton                   matlab.ui.control.Button
        SetPathButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        TimePanel                       matlab.ui.container.Panel
        TimeLabel2                      matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        ShufflePanel                    matlab.ui.container.Panel
        BarEdgeCheckBox                 matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        ShuffleButton                   matlab.ui.control.Button
        StimulusArrayUIAxes             matlab.ui.control.UIAxes
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        RestingButton                   matlab.ui.control.RadioButton
        ThreeStimuliButton              matlab.ui.control.RadioButton
        OddballButton                   matlab.ui.control.RadioButton
        AnalysisPanel                   matlab.ui.container.Panel
        RejectSignalsExceedingThresholdVSpinner  matlab.ui.control.Spinner
        RejectSignalsExceedingThresholdVSpinnerLabel  matlab.ui.control.Label
        ArtificialDetectionandRejectionCheckBox  matlab.ui.control.CheckBox
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        HighCutOffSpinner               matlab.ui.control.Spinner
        LowCutOffSpinner                matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        NotchFilterButton               matlab.ui.control.RadioButton
        BandpassFilterButton            matlab.ui.control.RadioButton
        FilteringCheckBox               matlab.ui.control.CheckBox
        BaselineTimesSpinner            matlab.ui.control.Spinner
        BaselineTimesSpinnerLabel       matlab.ui.control.Label
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        DetrendCheckBox                 matlab.ui.control.CheckBox
        Panel_8                         matlab.ui.container.Panel
        DistracterLabel_6               matlab.ui.control.Label
        TargetLabel3                    matlab.ui.control.Label
        DistracterLabel_5               matlab.ui.control.Label
        TargetLabel2                    matlab.ui.control.Label
        DistracterLabel_4               matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        DistracterLatencyEditField3     matlab.ui.control.NumericEditField
        LatencymsEditField_6Label       matlab.ui.control.Label
        DistracterAmplitudeEditField3   matlab.ui.control.NumericEditField
        AmplitudeVEditField_6Label      matlab.ui.control.Label
        DistracterLatencyEditField2     matlab.ui.control.NumericEditField
        LatencymsEditField_4Label       matlab.ui.control.Label
        DistracterAmplitudeEditField2   matlab.ui.control.NumericEditField
        AmplitudeVEditField_5Label      matlab.ui.control.Label
        DistracterLatencyEditField      matlab.ui.control.NumericEditField
        LatencymsEditField_2Label       matlab.ui.control.Label
        DistracterAmplitudeEditField    matlab.ui.control.NumericEditField
        AmplitudeVEditField_4Label      matlab.ui.control.Label
        TargetLatencyEditField3         matlab.ui.control.NumericEditField
        LatencymsEditField_5Label       matlab.ui.control.Label
        TargetAmplitudeEditField3       matlab.ui.control.NumericEditField
        AmplitudeVEditField_3Label      matlab.ui.control.Label
        TargetLatencyEditField2         matlab.ui.control.NumericEditField
        LatencymsEditField_3Label       matlab.ui.control.Label
        TargetAmplitudeEditField2       matlab.ui.control.NumericEditField
        AmplitudeVEditField_2Label      matlab.ui.control.Label
        TargetLatencyEditField          matlab.ui.control.NumericEditField
        LatencymsEditFieldLabel         matlab.ui.control.Label
        TargetAmplitudeEditField        matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel        matlab.ui.control.Label
        ChannelListBox3                 matlab.ui.control.ListBox
        ChannelLabel_3                  matlab.ui.control.Label
        ChannelListBox2                 matlab.ui.control.ListBox
        ChannelLabel_2                  matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        ChannelLabel                    matlab.ui.control.Label
        P300UIAxes3                     matlab.ui.control.UIAxes
        P300UIAxes2                     matlab.ui.control.UIAxes
        P300UIAxes                      matlab.ui.control.UIAxes
        WholeChannelPlottingTab         matlab.ui.container.Tab
    end


    properties (Access = public)
        stopFlag = false;

        locations
        sample_rate

        event_text = {};
        event_list = [];

        shuffle = []
        S_or_T_or_D = [];

        wholeChannelPlotting
    end

    properties (Access = private)
        connApp
        channelselectionApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global locations
            global sample_rate

            % app name
            app.UIFigure.Name = 'P300 Tool';

            % public properties
            app.locations = locations;
            app.sample_rate = sample_rate;

            % channel list (list-box)
            app.ChannelListBox.Items = transpose(locations.labels);
            app.ChannelListBox2.Items=transpose(locations.labels);
            app.ChannelListBox3.Items=transpose(locations.labels);

            % first selected channel
            app.ChannelListBox.Value = locations.labels{1};
            app.ChannelListBox2.Value = locations.labels{1};
            app.ChannelListBox3.Value = locations.labels{1};

            % P300 whole channel plotting
            app.wholeChannelPlotting = load('wholeChannelPlotting.mat');
            app.wholeChannelPlotting = app.wholeChannelPlotting.wholeChannelPlotting;

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global rawdata

            %%% Parameters or variables
            % rawdata, timesum
            rawdata = []; % channel X data (matrix)
            timesum = []; % vector for total measurement time

            % trigger
            trigger_i = 0; trigger_i2 = true;
            sound_i = 1;
            rest_i = 0; rest_i2 = true;
            rest_j = 0; rest_j2 = true;
            app.event_text = {};
            app.event_list = [];
            app.TriggerTextArea.Value = "";

            % audioDeviceReader
            aDR_i = true;

            % auditory stimulation
            sound_pace = 0.05;
            ramp_dur = 0.005;
            language = 'Microsoft Zira Desktop';
            language2 = 'Microsoft Heami Desktop';

            if app.ToneButton.Value == 1
                S_T_D_tone = [repmat(app.StandardSpinner.Value, 1, app.NumofStandardSpinner.Value), repmat(app.TargetSpinner.Value, 1, app.NumofTargetSpinner.Value), repmat(app.DistracterSpinner.Value, 1, app.NumofDistracterSpinner.Value)];
                S_T_D_tone = S_T_D_tone(app.shuffle);
            elseif app.SpeechEnglishButton.Value == 1
                S_T_D_speechEnglish = [repmat({app.StandardEditField.Value}, 1, app.NumofStandardSpinner.Value), repmat({app.TargetEditField.Value}, 1, app.NumofTargetSpinner.Value), repmat({app.DistracterEditField.Value}, 1, app.NumofDistracterSpinner.Value)];
                S_T_D_speechEnglish = S_T_D_speechEnglish(app.shuffle);
            elseif app.SpeechKoreanButton.Value == 1
                S_T_D_speechKorean = [repmat({app.StandardEditField2.Value}, 1, app.NumofStandardSpinner.Value), repmat({app.TargetEditField2.Value}, 1, app.NumofTargetSpinner.Value), repmat({app.DistracterEditField2.Value}, 1, app.NumofDistracterSpinner.Value)];
                S_T_D_speechKorean = S_T_D_speechKorean(app.shuffle);
            end

            % time
            start_t = 3;
            t_i = 1;
            if app.RestingButton.Value == 1
                h = app.HourSpinner.Value; m = app.MinutesSpinner.Value; s = app.SecondsSpinner.Value;
                time = h*3600 + m*60 + s + 6;
            elseif app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value == 1
                time = app.InterStimulusIntervalmsSpinner.Value/1000 * app.NumofStimuliSpinner.Value + start_t+1;
            end
            total_h2 = floor(time / 3600);
            total_m2 = floor((time - total_h2 * 3600) / 60);
            total_s2 = floor(mod(time, 60));
            app.TimeLabel2.Text = sprintf('%02d : %02d : %02d', total_h2, total_m2, total_s2);

            % save file
            complete_record = "yes";
            isi = app.InterStimulusIntervalmsSpinner.Value/1000;
            S_T_D = app.S_or_T_or_D;

            % delay compensation
            if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value
                if app.PassiveCheckBox.Value == 1
                    % passive
                    active_compensation = app.DelayCompensationEditField.Value;
                elseif app.ActiveCheckBox.Value == 1
                    % active
                    % TCP sender
                    TCP_writer = tcpclient("localhost", app.TCPPortNumberEditField.Value);

                    % audioDeviceReader
                    deviceReader = audioDeviceReader;
                    deviceReader.Driver = 'DirectSound';
                    deviceReader.Device = '스테레오 믹스(Realtek(R) Audio)';
                    deviceReader.SamplesPerFrame = 1600;
                    deviceReader.SampleRate = 8000;
                    fprintf('Latency due to device buffer: %f seconds.\n',deviceReader.SamplesPerFrame/deviceReader.SampleRate)
                    setup(deviceReader)
                    fileWriter = dsp.AudioFileWriter('delay_check.wav','FileFormat','WAV');
                end
            end


            %%% Recording
            % instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();

            % resolve a stream...
            disp('Resolving an EEG stream...');
            stream = {};
            while isempty(stream)
                stream = lsl_resolve_byprop(lib,'type','EEG');
                if isempty(stream)
                    error('There is no stream visible on the network.');
                end
            end

            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(stream{1});

            while true
                if length(timesum) > 1
                    count = timesum(1,end) - timesum(1,1);
                    if count >= time
                        break % end of record
                    end

                    % audioDeviceReader
                    if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value && aDR_i
                        if app.ActiveCheckBox.Value == 1
                            acquiredAudio = deviceReader();
                            fileWriter(acquiredAudio);

                            aDR_i = false;
                        end
                    end

                    % trigger & stimuli (oddball & three-stimuli or resting)
                    if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value == 1
                        if count >= start_t + isi * trigger_i && trigger_i2
                            % trigger
                            app.event_list(end+1) = length(rawdata);

                            % delay compensation (active or passive)
                            if app.ActiveCheckBox.Value == 1
                                write(TCP_writer, uint8(1))
                            end

                            % type of auditory stimulation
                            if app.ToneButton.Value == 1
                                auditory_stimulus(sound_pace, ramp_dur, S_T_D_tone(sound_i))
                            elseif app.SpeechEnglishButton.Value == 1
                                text_to_speech(S_T_D_speechEnglish{sound_i}, app.SpeedSpinner.Value, language)
                            elseif app.SpeechKoreanButton.Value == 1
                                text_to_speech(S_T_D_speechKorean{sound_i}, app.SpeedSpinner2.Value, language2)
                            end

                            trigger_i = trigger_i + 1;
                            sound_i = sound_i + 1;
                            if trigger_i == app.NumofStimuliSpinner.Value
                                trigger_i2 = false;
                            end
                        end

                    elseif app.RestingButton.Value == 1
                        % task1 or tast1 => tast2
                        if app.Task1CheckBox.Value == 1
                            % task1 (start - end)
                            if count >= 3 + (time-6) * rest_i && rest_i2
                                % task1 UIAxes
                                set(app.StimulusArrayUIAxes, 'Color', 'white')
                                xticks(app.StimulusArrayUIAxes, [])
                                fill(app.StimulusArrayUIAxes, [-10, 10, 10, -10], [-1, -1, 1, 1], [0.7 0.7 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                text(app.StimulusArrayUIAxes, 0, 0, "[Task1] "+app.Task1EditField.Value, 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                                xlim(app.StimulusArrayUIAxes, [-10, 10]);
                                ylim(app.StimulusArrayUIAxes, [-1, 1]);

                                % trigger
                                app.event_text = {};
                                app.event_list(end+1) = length(rawdata);
                                for k = 1:1:length(app.event_list)
                                    app.event_text{end+1} = strcat(num2str(k), ':', 'time', '[', num2str(app.event_list(k)/app.sample_rate), ']', '_', 'sample', '[', num2str(app.event_list(k)), ']');
                                end
                                app.TriggerTextArea.Value = strjoin(app.event_text, newline);

                                % resting sound
                                auditory_stimulus(sound_pace, ramp_dur, 1500)

                                rest_i = rest_i + 1;
                                if rest_i == 2
                                    rest_i2 = false;
                                end
                            end

                        elseif app.Task1Task2CheckBox.Value == 1
                            % task1 (start - end)
                            if count >= 2 + (time-6)/2 * rest_i && rest_i2
                                % task1 UIAxes
                                set(app.StimulusArrayUIAxes, 'Color', 'white')
                                xticks(app.StimulusArrayUIAxes, [])
                                fill(app.StimulusArrayUIAxes, [-10, 0, 0, -10], [-1, -1, 1, 1], [0.7 0.7 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                text(app.StimulusArrayUIAxes, -5, 0, "[Task1] "+app.Task1EditField.Value, 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                                xlim(app.StimulusArrayUIAxes, [-10, 10]);
                                ylim(app.StimulusArrayUIAxes, [-1, 1]);

                                % trigger
                                app.event_text = {};
                                app.event_list(end+1) = length(rawdata);
                                for k = 1:1:length(app.event_list)
                                    app.event_text{end+1} = strcat(num2str(k), ':', 'time', '[', num2str(app.event_list(k)/app.sample_rate), ']', '_', 'sample', '[', num2str(app.event_list(k)), ']');
                                end
                                app.TriggerTextArea.Value = strjoin(app.event_text, newline);

                                % resting sound
                                auditory_stimulus(sound_pace, ramp_dur, 1500)

                                rest_i = rest_i + 1;
                                if rest_i == 2
                                    rest_i2 = false;
                                end
                            end

                            % task2 (start - end)
                            if count >= 4 + (time-6)/2 + (time-6)/2 * rest_j && rest_j2
                                % task2 UIAxes
                                set(app.StimulusArrayUIAxes, 'Color', 'white')
                                xticks(app.StimulusArrayUIAxes, [])
                                fill(app.StimulusArrayUIAxes, [0, 10, 10, 0], [-1, -1, 1, 1], [0.7 0.7 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                                text(app.StimulusArrayUIAxes, 5, 0, "[Task2] "+app.Task2EditField.Value, 'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                                xlim(app.StimulusArrayUIAxes, [-10, 10]);
                                ylim(app.StimulusArrayUIAxes, [-1, 1]);

                                % trigger
                                app.event_text = {};
                                app.event_list(end+1) = length(rawdata);
                                for k = 1:1:length(app.event_list)
                                    app.event_text{end+1} = strcat(num2str(k), ':', 'time', '[', num2str(app.event_list(k)/app.sample_rate), ']', '_', 'sample', '[', num2str(app.event_list(k)), ']');
                                end
                                app.TriggerTextArea.Value = strjoin(app.event_text, newline);

                                % resting sound
                                auditory_stimulus(sound_pace, ramp_dur, 1700)

                                rest_j = rest_j + 1;
                                if rest_j == 2
                                    rest_j2 = false;
                                end
                            end
                        end
                    end
                end

                % total time
                if length(timesum) >= app.sample_rate*t_i
                    total_t = t_i;
                    total_h = floor(total_t / 3600);
                    total_m = floor((total_t - total_h * 3600) / 60);
                    total_s = mod(total_t, 60);
                    app.TimeLabel.Text = sprintf('%02d : %02d : %02d', total_h, total_m, total_s);

                    t_i = t_i + 1;
                end

                % data acqusition
                [chunk, timestamps] = inlet.pull_chunk();
                timesum = [timesum, timestamps];
                rawdata = [rawdata, chunk]; % channel X data (matrix)

                % stop recording
                pause(10^-323)
                if app.stopFlag == true
                    complete_record = "no";
                    break
                end
            end

            % save file
            if complete_record == "yes"
                % delay compensation
                if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value
                    if app.PassiveCheckBox.Value == 1
                        app.event_list = app.event_list + (active_compensation/1000)*app.sample_rate;

                    elseif app.ActiveCheckBox.Value == 1
                        % audio events
                        [data,~]=audioread('delay_check.WAV');
                        audioThreshold = max(data) * 0.0001; % adjust threshold as necessary
                        above_taudioThreshold = data > audioThreshold; % find points exceeding the threshold

                        % filter to ensure only start points (events) are selected
                        start_indices = find(above_taudioThreshold == 1);
                        sorted_indices = sort(diff(start_indices), 'descend');
                        min_separation = min(sorted_indices(1:app.NumofStimuliSpinner.Value-1))-1; % minimum separation between signals
                        filtered_indices = start_indices([true; diff(start_indices) > min_separation]);

                        audio_events = filtered_indices';
                        audio_events = audio_events/deviceReader.SampleRate - deviceReader.SamplesPerFrame/deviceReader.SampleRate;
                        audio_events = round(audio_events*app.sample_rate);

                        % MATLAB events
                        LSL_events = app.event_list;

                        % TCP events
                        index = rawdata(:,1:2); index2 = rawdata(:,end-1:end);
                        cond1 = find(index(:, 1) == 0); cond2 = find(index(:, 2) == 0);
                        cond3 = find(index2(:, 1) == 0); cond4 = find(index2(:, 2) == 0);
                        common_indices = cond1;
                        common_indices = intersect(common_indices, cond2);
                        common_indices = intersect(common_indices, cond3);
                        common_indices = intersect(common_indices, cond4);
                        common_indices = common_indices(end);

                        TCP_events = find(rawdata(common_indices, :));

                        % delay compensation
                        app.event_list = audio_events + (TCP_events - LSL_events);

                        % audioDeviceReader
                        release(deviceReader)
                        release(fileWriter)
                    end
                end

                events = app.event_list;
                event_text = app.event_text;

                if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value == 1
                    try uisave({'rawdata', 'events', 'isi', 'S_T_D'},'')
                    catch, disp('Try again')
                    end
                elseif app.RestingButton.Value == 1
                    try uisave({'rawdata', 'events', 'event_text',},'')
                    catch, disp('Try again')
                    end
                end
            end

            % clear
            app.stopFlag = false;
            app.TriggerTextArea.Value = "";
            app.event_text = {};
            app.event_list = [];
            app.TimeLabel.Text = '00 : 00 : 00';
            app.TimeLabel2.Text = '00 : 00 : 00';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;

        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            try
                selectedFolder = uigetdir('C:\', 'Select a file');
                addpath(genpath(selectedFolder));
            catch
            end

        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            % standard, target, distracter
            S = 1; T = 2; D = 3;
            S_T_D = [S*ones(1, app.NumofStandardSpinner.Value), T*ones(1, app.NumofTargetSpinner.Value), D*ones(1, app.NumofDistracterSpinner.Value)];

            % shuffle
            stopFlag2 = false;
            tic;
            while ~stopFlag2
                app.shuffle = randperm(length(S_T_D)); stopFlag2 = true;
                for i = 1:(length(S_T_D)-1)
                    if S_T_D(app.shuffle(i))==T && S_T_D(app.shuffle(i+1))==T
                        stopFlag2 = false;
                        break
                    elseif S_T_D(app.shuffle(i))==D && S_T_D(app.shuffle(i+1))==D
                        stopFlag2 = false;
                        break;
                    elseif S_T_D(app.shuffle(i))==T && S_T_D(app.shuffle(i+1))==D
                        stopFlag2 = false;
                        break;
                    elseif S_T_D(app.shuffle(i))==D && S_T_D(app.shuffle(i+1))==T
                        stopFlag2 = false;
                        break;
                    end
                end
                if toc > 2
                    break
                end
            end
            S_T_D = S_T_D(app.shuffle);

            b_map = bar(app.StimulusArrayUIAxes, S_T_D', 'FaceColor',[1 .4 .3]);
            b_map.FaceColor = 'flat';
            if app.NumofDistracterSpinner.Value == 0
                b_map.CData(find(S_T_D == 1),:) = repmat([1 .4 .3], app.NumofStandardSpinner.Value, 1);
                b_map.CData(find(S_T_D == 2),:) = repmat([.3 .9 .3], app.NumofTargetSpinner.Value, 1);
            else
                b_map.CData(find(S_T_D == 1),:) = repmat([1 .4 .3], app.NumofStandardSpinner.Value, 1);
                b_map.CData(find(S_T_D == 2),:) = repmat([.3 .9 .3], app.NumofTargetSpinner.Value, 1);
                b_map.CData(find(S_T_D == 3),:) = repmat([.3 .4 .95], app.NumofDistracterSpinner.Value, 1);
            end
            set(app.StimulusArrayUIAxes, 'color', [1 .4 .3])
            if app.BarEdgeCheckBox.Value == 0
                set(b_map, 'EdgeColor', 'none')
            end
            xticks(app.StimulusArrayUIAxes, [1 app.NumofStimuliSpinner.Value])
            yticks(app.StimulusArrayUIAxes, [])
            xlim(app.StimulusArrayUIAxes, [0.5, app.NumofStimuliSpinner.Value+0.5])
            ylim(app.StimulusArrayUIAxes, [0, 1])

            % public properties
            app.shuffle;
            app.S_or_T_or_D = S_T_D;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % EEG struct
            EEG.setname = 'matfile'; EEG.filename = ''; EEG.filepath = ''; EEG.subject = '';
            EEG.nbchan = size(app.locations,1); EEG.srate = app.sample_rate;
            EEG.trials = 1; EEG.pnts = []; EEG.xmin = 0; EEG.xmax = []; EEG.data = [];
            EEG.icaact = []; EEG.icawinv = []; EEG.icasphere = []; EEG.icaweights = []; EEG.icachansind = [];
            location_struct = table2struct(app.locations); EEG.chanlocs = location_struct;
            EEG.urchanlocs = []; EEG.chaninfo = [];
            EEG.event = []; EEG.urevent = []; EEG.epoch = [];
            EEG.reject = []; EEG.specdata = []; EEG.specicaact = [];
            EEG.etc = [];

            % load data
            [filename, filepath] = uigetfile('*.mat');
            if (ischar(filepath))
                file_name_path = [filepath filename];
                data = importdata(file_name_path);
            end

            if isstruct(data)
                rawdata = data.rawdata; % channel X data (matrix)
                events = data.events;
                isi = data.isi;
                S_T_D = data.S_T_D;
%                 S_T_D = data.plot_S_or_T;
            end
            plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)

            % additional EEG struct
            EEG.nbchan = size(plot_rawdata, 1); EEG.pnts = size(plot_rawdata, 2);
            EEG.data = plot_rawdata; % channel X data (matrix)
            EEG.xmax = (EEG.pnts-1)/EEG.srate;


            %%% Pre-processing
            % filtering
            if app.FilteringCheckBox.Value == 1
                EEG = pop_eegfiltnew(EEG, app.LowCutOffSpinner.Value, app.HighCutOffSpinner.Value, [], app.NotchFilterButton.Value, [], 0);
            end

            % common average reference
            if app.CommonAverageReferenceCheckBox.Value == 1
                EEG = pop_reref(EEG,[]); % channel X data (matrix)

                if class(EEG.data) ~= "double"
                    EEG.data = double(EEG.data);
                end
            end

            % detrend
            if app.DetrendCheckBox.Value == 1
                detrend_data = detrend(transpose(EEG.data)); % data X channel (matrix)
                EEG.data = transpose(detrend_data); % channel X data (matrix)
            end
            
            % baseline correction
            if app.BaselineCorrectionCheckBox.Value == 1
                baseline_data  = EEG.data (:, 1:app.BaselineTimesSpinner.Value*EEG.srate); % channel X data (matrix)

                baseline_mean = mean(baseline_data, 2);
                corrected_data = EEG.data - baseline_mean;

                EEG.data = corrected_data; % channel X data (matrix)
            end

            % epoching
            wnd_ft=[0 isi];
            for tr=1:size(events,2) % Trials number
                epoch_data(:,:,tr) = EEG.data(:,round(events(tr)+(wnd_ft(1)*EEG.srate)):round(events(tr)+(wnd_ft(2)*EEG.srate)));
            end

            % classification (target, standard, distracter)
            plot_S = S_T_D == 1; plot_T = S_T_D == 2; plot_D = S_T_D == 3;
            S_data = epoch_data(:,:,plot_S);
            T_data = epoch_data(:,:,plot_T);
            D_data = epoch_data(:,:,plot_D);

            % artificial detection and rejection
            if app.ArtificialDetectionandRejectionCheckBox.Value == 1
                T_num = size(S_T_D(plot_T),2);
                D_num = size(S_T_D(plot_D),2);
                [T_data, D_data] = artificial_detection_rejection(T_data, T_num, D_data, D_num, app.RejectSignalsExceedingThresholdVSpinner.Value);
            end

            % averaging
            S_meandata = mean(S_data, 3);
            T_meandata = mean(T_data, 3);
            if ~isempty(D_data)
                D_meandata = mean(D_data, 3);
            end

            % channel selection
            selected_ch = find(strcmp(app.locations.labels, app.ChannelListBox.Value));
            selected_ch2 = find(strcmp(app.locations.labels, app.ChannelListBox2.Value));
            selected_ch3 = find(strcmp(app.locations.labels, app.ChannelListBox3.Value));

            % amplitude and latency
            T_f_time=[0.3 0.7]; % P300 detection range
            T_dur=ceil((T_f_time(1)-wnd_ft(1))*EEG.srate) : ceil((T_f_time(2)-wnd_ft(1))*EEG.srate);
            [T_pks, T_locs] = findpeaks(T_meandata(selected_ch,T_dur),'SortStr', 'descend', 'NPeaks', 1);
            [T_pks2, T_locs2] = findpeaks(T_meandata(selected_ch2,T_dur),'SortStr', 'descend', 'NPeaks', 1);
            [T_pks3, T_locs3] = findpeaks(T_meandata(selected_ch3,T_dur),'SortStr', 'descend', 'NPeaks', 1);
            T_latency = T_dur(T_locs)/EEG.srate*1000; T_amplitude = T_pks;
            T_latency2 = T_dur(T_locs2)/EEG.srate*1000; T_amplitude2 = T_pks2;
            T_latency3 = T_dur(T_locs3)/EEG.srate*1000; T_amplitude3 = T_pks3;
            app.TargetAmplitudeEditField.Value = T_amplitude; app.TargetLatencyEditField.Value = T_latency;
            app.TargetAmplitudeEditField2.Value = T_amplitude2; app.TargetLatencyEditField2.Value = T_latency2;
            app.TargetAmplitudeEditField3.Value = T_amplitude3; app.TargetLatencyEditField3.Value = T_latency3;
            app.DistracterAmplitudeEditField.Value = 0; app.DistracterLatencyEditField.Value = 0;
            app.DistracterAmplitudeEditField2.Value = 0; app.DistracterLatencyEditField2.Value = 0;
            app.DistracterAmplitudeEditField3.Value = 0; app.DistracterLatencyEditField3.Value = 0;

            if ~isempty(D_data)
                D_f_time=[0.25 0.7];
                D_dur=ceil((D_f_time(1)-wnd_ft(1))*EEG.srate) : ceil((D_f_time(2)-wnd_ft(1))*EEG.srate);
                [D_pks, D_locs] = findpeaks(D_meandata(selected_ch,D_dur),'SortStr', 'descend', 'NPeaks', 1);
                [D_pks2, D_locs2] = findpeaks(D_meandata(selected_ch2,D_dur),'SortStr', 'descend', 'NPeaks', 1);
                [D_pks3, D_locs3] = findpeaks(D_meandata(selected_ch3,D_dur),'SortStr', 'descend', 'NPeaks', 1);
                D_latency = D_dur(D_locs)/EEG.srate*1000; D_amplitude = D_pks;
                D_latency2 = D_dur(D_locs2)/EEG.srate*1000; D_amplitude2 = D_pks2;
                D_latency3 = D_dur(D_locs3)/EEG.srate*1000; D_amplitude3 = D_pks3;
                app.DistracterAmplitudeEditField.Value = D_amplitude; app.DistracterLatencyEditField.Value = D_latency;
                app.DistracterAmplitudeEditField2.Value = D_amplitude2; app.DistracterLatencyEditField2.Value = D_latency2;
                app.DistracterAmplitudeEditField3.Value = D_amplitude3; app.DistracterLatencyEditField3.Value = D_latency3;
            end

            % P300 UIAxes
            t = (wnd_ft(1):1/EEG.srate:wnd_ft(2))*1000;
            cla(app.P300UIAxes, 'reset'); cla(app.P300UIAxes2, 'reset'); cla(app.P300UIAxes3, 'reset')
            hold(app.P300UIAxes, 'on'); hold(app.P300UIAxes2, 'on'); hold(app.P300UIAxes3, 'on')
            plot(app.P300UIAxes, t, S_meandata(selected_ch,:), 'color',[1 .4 .3], 'LineWidth',1.5, 'DisplayName','Standard')
            plot(app.P300UIAxes2, t, S_meandata(selected_ch2,:), 'color',[1 .4 .3], 'LineWidth',1.5, 'DisplayName','Standard')
            plot(app.P300UIAxes3, t, S_meandata(selected_ch3,:), 'color',[1 .4 .3], 'LineWidth',1.5, 'DisplayName','Standard')
            plot(app.P300UIAxes, t, T_meandata(selected_ch,:), 'color',[.3 .9 .3], 'LineWidth',1.5, 'DisplayName','Target')
            plot(app.P300UIAxes2, t, T_meandata(selected_ch2,:), 'color',[.3 .9 .3], 'LineWidth',1.5, 'DisplayName','Target')
            plot(app.P300UIAxes3, t, T_meandata(selected_ch3,:), 'color',[.3 .9 .3], 'LineWidth',1.5, 'DisplayName','Target')
            plot(app.P300UIAxes, T_latency, T_amplitude, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
            plot(app.P300UIAxes2, T_latency2, T_amplitude2, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
            plot(app.P300UIAxes3, T_latency3, T_amplitude3, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
            
            if ~isempty(D_data)
                plot(app.P300UIAxes, t, D_meandata(selected_ch,:), 'color',[.3 .4 .95], 'LineWidth',1.5, 'DisplayName','Distracter')
                plot(app.P300UIAxes2, t, D_meandata(selected_ch2,:), 'color',[.3 .4 .95], 'LineWidth',1.5, 'DisplayName','Distracter')
                plot(app.P300UIAxes3, t, D_meandata(selected_ch3,:), 'color',[.3 .4 .95], 'LineWidth',1.5, 'DisplayName','Distracter')
                plot(app.P300UIAxes, D_latency, D_amplitude, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
                plot(app.P300UIAxes2, D_latency2, D_amplitude2, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
                plot(app.P300UIAxes3, D_latency3, D_amplitude3, '*', 'color','k', 'LineWidth',1, 'HandleVisibility','off')
            end

            legend(app.P300UIAxes, 'off'); legend(app.P300UIAxes, 'Location','northwest')
            legend(app.P300UIAxes2, 'off'); legend(app.P300UIAxes2, 'Location','northwest')
            legend(app.P300UIAxes3, 'off'); legend(app.P300UIAxes3, 'Location','northwest')
            title(app.P300UIAxes, ['< ' app.ChannelListBox.Value ' >'], 'FontSize',16)
            title(app.P300UIAxes2, ['< ' app.ChannelListBox2.Value ' >'], 'FontSize',16)
            title(app.P300UIAxes3, ['< ' app.ChannelListBox3.Value ' >'], 'FontSize',16)
            xlim(app.P300UIAxes, [0 1000]); xlabel(app.P300UIAxes, 'ms'); ylabel(app.P300UIAxes, 'µV')
            xlim(app.P300UIAxes2, [0 1000]); xlabel(app.P300UIAxes2, 'ms'); ylabel(app.P300UIAxes2, 'µV')
            xlim(app.P300UIAxes3, [0 1000]); xlabel(app.P300UIAxes3, 'ms'); ylabel(app.P300UIAxes3, 'µV')
            hold(app.P300UIAxes, 'off'); hold(app.P300UIAxes2, 'off'); hold(app.P300UIAxes3, 'off')

            % P300 whole channel plotting
            persistent P300AxesArray;
            if ~isempty(P300AxesArray)
                for j = 1:length(P300AxesArray)
                    delete(P300AxesArray(j));
                end
            end
            P300AxesArray = [];
            
            for i = 1:size(app.wholeChannelPlotting,1)
                if ismember(app.wholeChannelPlotting.labels(i), app.locations.labels)
                    P300Axes = uiaxes(app.WholeChannelPlottingTab);
                    hold(P300Axes, 'on')
                    P300Axes.Position = [app.wholeChannelPlotting.position{i}];
                    P300Axes.Title.String = app.wholeChannelPlotting.labels(i);
                    P300Axes.FontSize = 8;
                    xlim(P300Axes, [0 1000]);
                    plot(P300Axes, t, S_meandata(find(strcmp(app.locations.labels, app.wholeChannelPlotting.labels(i)), 1),:), 'color',[1 .4 .3], 'LineWidth',1.0, 'DisplayName','Standard')
                    plot(P300Axes, t, T_meandata(find(strcmp(app.locations.labels, app.wholeChannelPlotting.labels(i)), 1),:), 'color',[.3 .9 .3], 'LineWidth',1.0, 'DisplayName','Target')
                    if ~isempty(D_data)
                        plot(P300Axes, t, D_meandata(find(strcmp(app.locations.labels, app.wholeChannelPlotting.labels(i)), 1),:), 'color',[.3 .4 .95], 'LineWidth',1.0, 'DisplayName','Distracter')
                    end
                    hold(P300Axes, 'off')

                    P300AxesArray = [P300AxesArray, P300Axes];
                end
            end

        end

        % Button pushed function: StandardSoundButton
        function StandardSoundButtonPushed(app, event)
            sound_pace = 0.05;
            ramp_dur = 0.005;
            language = 'Microsoft Zira Desktop';
            language2 = 'Microsoft Heami Desktop';

            if app.ToneButton.Value == 1
                auditory_stimulus(sound_pace, ramp_dur, app.StandardSpinner.Value)
            elseif app.SpeechEnglishButton.Value == 1
                text_to_speech(app.StandardEditField.Value, app.SpeedSpinner.Value, language)
            elseif app.SpeechKoreanButton.Value == 1
                text_to_speech(app.StandardEditField2.Value, app.SpeedSpinner.Value, language2)
            end

        end

        % Button pushed function: TargetSoundButton
        function TargetSoundButtonPushed(app, event)
            sound_pace = 0.05;
            ramp_dur = 0.005;
            language = 'Microsoft Zira Desktop';
            language2 = 'Microsoft Heami Desktop';

            if app.ToneButton.Value == 1
                auditory_stimulus(sound_pace, ramp_dur, app.TargetSpinner.Value)
            elseif app.SpeechEnglishButton.Value == 1
                text_to_speech(app.TargetEditField.Value, app.SpeedSpinner.Value, language)
            elseif app.SpeechKoreanButton.Value == 1
                text_to_speech(app.TargetEditField2.Value, app.SpeedSpinner.Value, language2)
            end

        end

        % Button pushed function: DistracterSoundButton
        function DistracterSoundButtonPushed(app, event)
            sound_pace = 0.05;
            ramp_dur = 0.005;
            language = 'Microsoft Zira Desktop';
            language2 = 'Microsoft Heami Desktop';

            if app.ToneButton.Value == 1
                auditory_stimulus(sound_pace, ramp_dur, app.DistracterSpinner.Value)
            elseif app.SpeechEnglishButton.Value == 1
                text_to_speech(app.DistracterEditField.Value, app.SpeedSpinner.Value, language)
            elseif app.SpeechKoreanButton.Value == 1
                text_to_speech(app.DistracterEditField2.Value, app.SpeedSpinner2.Value, language2)
            end

        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            if app.OddballButton.Value == 1 || app.ThreeStimuliButton.Value == 1
                % oddball or thre-stimuli
                app.OddballThreeStimuliLabel.Enable = "on"; app.NumofStimuliSpinner.Enable = "on"; app.InterStimulusIntervalmsSpinner.Enable = "on";
                app.NumofTargetSpinner.Enable = "on"; app.NumofStandardSpinner.Enable = "on";
                app.NumofDistracterSpinner.Enable = "off"; app.DistracterSoundButton.Enable = "off";
                app.StimulusArrayUIAxes.Title.String = "Oddball";

                % resting
                app.RestingLabel.Enable = "off"; app.Task1CheckBox.Enable = "off"; app.Task1Task2CheckBox.Enable = "off";
                app.Task1EditField.Enable = "off"; app.Task2EditField.Enable = "off";
                app.HourSpinner.Enable = "off"; app.MinutesSpinner.Enable = "off"; app.SecondsSpinner.Enable = "off";

                % thre-stimuli
                if app.ThreeStimuliButton.Value == 1
                    app.NumofDistracterSpinner.Enable = "on"; app.DistracterSoundButton.Enable = "on";
                    app.StimulusArrayUIAxes.Title.String = "Three-Stimuli";
                end

            elseif app.RestingButton.Value == 1
                % oddball and thre-stimuli
                app.OddballThreeStimuliLabel.Enable = "off"; app.NumofStimuliSpinner.Enable = "off"; app.InterStimulusIntervalmsSpinner.Enable = "off";
                app.NumofTargetSpinner.Enable = "off"; app.NumofStandardSpinner.Enable = "off";
                app.NumofDistracterSpinner.Enable = "off"; app.DistracterSoundButton.Enable = "off";

                % resting
                app.RestingLabel.Enable = "on"; app.Task1CheckBox.Enable = "on"; app.Task1Task2CheckBox.Enable = "on";
                app.Task1EditField.Enable = "on"; app.Task2EditField.Enable = "on";
                app.HourSpinner.Enable = "on"; app.MinutesSpinner.Enable = "on"; app.SecondsSpinner.Enable = "on";
                app.StimulusArrayUIAxes.Title.String = "Resting";
            end

        end

        % Value changed function: Task1CheckBox
        function Task1CheckBoxValueChanged(app, event)
            if app.Task1CheckBox.Value == 1
                app.Task1Task2CheckBox.Value = 0;
            elseif app.Task1CheckBox.Value == 0
                app.Task1Task2CheckBox.Value = 1;
            end

        end

        % Value changed function: Task1Task2CheckBox
        function Task1Task2CheckBoxValueChanged(app, event)
            if app.Task1Task2CheckBox.Value == 1
                app.Task1CheckBox.Value = 0;
            elseif app.Task1Task2CheckBox.Value == 0
                app.Task1CheckBox.Value = 1;
            end

        end

        % Value changed function: PassiveCheckBox
        function PassiveCheckBoxValueChanged(app, event)
            if app.PassiveCheckBox.Value == 1
                app.ActiveCheckBox.Value = 0;
            elseif app.PassiveCheckBox.Value == 0
                app.ActiveCheckBox.Value = 1;
            end

        end

        % Value changed function: ActiveCheckBox
        function ActiveCheckBoxValueChanged(app, event)
            if app.ActiveCheckBox.Value == 1
                app.PassiveCheckBox.Value = 0;
            elseif app.ActiveCheckBox.Value == 0
                app.PassiveCheckBox.Value = 1;
            end

        end

        % Value changed function: NumofStimuliSpinner
        function NumofStimuliSpinnerValueChanged(app, event)
            if mod(app.NumofStimuliSpinner.Value, 2) == 1
                app.NumofStimuliSpinner.Value = app.NumofStimuliSpinner.Value + 1;
            end

            if app.OddballButton.Value == 1
                if app.NumofStimuliSpinner.Value < app.NumofStandardSpinner.Value
                    app.NumofTargetSpinner.Value = 0;
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value;
                elseif app.NumofStimuliSpinner.Value < app.NumofTargetSpinner.Value
                    app.NumofStandardSpinner.Value = 0;
                    app.NumofTargetSpinner.Value = app.NumofStimuliSpinner.Value;
                else
                    app.NumofDistracterSpinner.Value = 0;
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofTargetSpinner.Value;
                end
            elseif app.ThreeStimuliButton.Value == 1
                if app.NumofStimuliSpinner.Value < app.NumofStandardSpinner.Value
                    app.NumofTargetSpinner.Value = 0; app.NumofDistracterSpinner.Value = 0;
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value;
                elseif app.NumofStimuliSpinner.Value < app.NumofTargetSpinner.Value
                    app.NumofStandardSpinner.Value = 0; app.NumofDistracterSpinner.Value = 0;
                    app.NumofTargetSpinner.Value = app.NumofStimuliSpinner.Value;
                elseif app.NumofStimuliSpinner.Value < app.NumofDistracterSpinner.Value
                    app.NumofStandardSpinner.Value = 0; app.NumofTargetSpinner.Value = 0;
                    app.NumofDistracterSpinner.Value = app.NumofStimuliSpinner.Value;
                else
                    app.NumofDistracterSpinner.Value = 0;
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofTargetSpinner.Value - app.NumofDistracterSpinner.Value;
                end
            end

        end

        % Value changed function: NumofTargetSpinner
        function NumofTargetSpinnerValueChanged(app, event)
            try
                if app.OddballButton.Value == 1
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofTargetSpinner.Value;
                elseif app.ThreeStimuliButton.Value == 1
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofTargetSpinner.Value - app.NumofDistracterSpinner.Value;
                end
            catch
            end

        end

        % Value changed function: NumofStandardSpinner
        function NumofStandardSpinnerValueChanged(app, event)
            try
                if app.OddballButton.Value == 1
                    app.NumofTargetSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofStandardSpinner.Value;
                elseif app.ThreeStimuliButton.Value == 1
                    app.NumofTargetSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofStandardSpinner.Value - app.NumofDistracterSpinner.Value;
                end
            catch
            end

        end

        % Value changed function: NumofDistracterSpinner
        function NumofDistracterSpinnerValueChanged(app, event)
            try
                if app.NumofTargetSpinner.Value ~= 0
                    app.NumofTargetSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofStandardSpinner.Value - app.NumofDistracterSpinner.Value;
                else
                    app.NumofStandardSpinner.Value = app.NumofStimuliSpinner.Value - app.NumofTargetSpinner.Value - app.NumofDistracterSpinner.Value;
                end
            catch
            end

        end

        % Menu selected function: BrainNetworkMenu
        function BrainNetworkMenuSelected(app, event)
            app.connApp = Connectivity_plot;

        end

        % Menu selected function: ChannelSelectionMenu
        function ChannelSelectionMenuSelected(app, event)
            app.channelselectionApp = Channel_selection;
            close(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OfflineGUIMenu = uimenu(app.UIFigure);
            app.OfflineGUIMenu.Text = '  Offline GUI';
            app.BrainNetworkMenu = uimenu(app.OfflineGUIMenu);
            app.BrainNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @BrainNetworkMenuSelected, true);
            app.BrainNetworkMenu.Text = 'Brain Network';
            app.SetupGUIMenu = uimenu(app.UIFigure);
            app.SetupGUIMenu.Text = '  Setup GUI';
            app.ChannelSelectionMenu = uimenu(app.SetupGUIMenu);
            app.ChannelSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ChannelSelectionMenuSelected, true);
            app.ChannelSelectionMenu.Text = 'Channel Selection';
            app.TabGroup8 = uitabgroup(app.UIFigure);
            app.MeasurementAnalysisTab = uitab(app.TabGroup8);
            app.MeasurementAnalysisTab.Title = 'Measurement & Analysis';
            app.Panel_8 = uipanel(app.MeasurementAnalysisTab);
            app.P300UIAxes = uiaxes(app.Panel_8);
            title(app.P300UIAxes, 'Channel')
            xlabel(app.P300UIAxes, 'ms')
            ylabel(app.P300UIAxes, 'µV')
            zlabel(app.P300UIAxes, 'Z')
            app.P300UIAxes.XTick = [];
            app.P300UIAxes.YTick = [];
            app.P300UIAxes2 = uiaxes(app.Panel_8);
            title(app.P300UIAxes2, 'Channel')
            xlabel(app.P300UIAxes2, 'ms')
            ylabel(app.P300UIAxes2, 'µV')
            zlabel(app.P300UIAxes2, 'Z')
            app.P300UIAxes2.XTick = [];
            app.P300UIAxes2.YTick = [];
            app.P300UIAxes3 = uiaxes(app.Panel_8);
            title(app.P300UIAxes3, 'Channel')
            xlabel(app.P300UIAxes3, 'ms')
            ylabel(app.P300UIAxes3, 'µV')
            zlabel(app.P300UIAxes3, 'Z')
            app.P300UIAxes3.XTick = [];
            app.P300UIAxes3.YTick = [];
            app.ChannelLabel = uilabel(app.Panel_8);
            app.ChannelLabel.Text = 'Channel';
            app.ChannelListBox = uilistbox(app.Panel_8);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Value = {};
            app.ChannelLabel_2 = uilabel(app.Panel_8);
            app.ChannelLabel_2.Text = 'Channel';
            app.ChannelListBox2 = uilistbox(app.Panel_8);
            app.ChannelListBox2.Items = {};
            app.ChannelListBox2.Value = {};
            app.ChannelLabel_3 = uilabel(app.Panel_8);
            app.ChannelLabel_3.Text = 'Channel';
            app.ChannelListBox3 = uilistbox(app.Panel_8);
            app.ChannelListBox3.Items = {};
            app.ChannelListBox3.Value = {};
            app.AmplitudeVEditFieldLabel = uilabel(app.Panel_8);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (µV)';
            app.TargetAmplitudeEditField = uieditfield(app.Panel_8, 'numeric');
            app.TargetAmplitudeEditField.Editable = 'off';
            app.LatencymsEditFieldLabel = uilabel(app.Panel_8);
            app.LatencymsEditFieldLabel.Text = 'Latency (ms)';
            app.TargetLatencyEditField = uieditfield(app.Panel_8, 'numeric');
            app.TargetLatencyEditField.Editable = 'off';
            app.AmplitudeVEditField_2Label = uilabel(app.Panel_8);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude (µV)';
            app.TargetAmplitudeEditField2 = uieditfield(app.Panel_8, 'numeric');
            app.TargetAmplitudeEditField2.Editable = 'off';
            app.LatencymsEditField_3Label = uilabel(app.Panel_8);
            app.LatencymsEditField_3Label.Text = 'Latency (ms)';
            app.TargetLatencyEditField2 = uieditfield(app.Panel_8, 'numeric');
            app.TargetLatencyEditField2.Editable = 'off';
            app.AmplitudeVEditField_3Label = uilabel(app.Panel_8);
            app.AmplitudeVEditField_3Label.Text = 'Amplitude (µV)';
            app.TargetAmplitudeEditField3 = uieditfield(app.Panel_8, 'numeric');
            app.TargetAmplitudeEditField3.Editable = 'off';
            app.LatencymsEditField_5Label = uilabel(app.Panel_8);
            app.LatencymsEditField_5Label.Text = 'Latency (ms)';
            app.TargetLatencyEditField3 = uieditfield(app.Panel_8, 'numeric');
            app.TargetLatencyEditField3.Editable = 'off';
            app.AmplitudeVEditField_4Label = uilabel(app.Panel_8);
            app.AmplitudeVEditField_4Label.Text = 'Amplitude (µV)';
            app.DistracterAmplitudeEditField = uieditfield(app.Panel_8, 'numeric');
            app.DistracterAmplitudeEditField.Editable = 'off';
            app.LatencymsEditField_2Label = uilabel(app.Panel_8);
            app.LatencymsEditField_2Label.Text = 'Latency (ms)';
            app.DistracterLatencyEditField = uieditfield(app.Panel_8, 'numeric');
            app.DistracterLatencyEditField.Editable = 'off';
            app.AmplitudeVEditField_5Label = uilabel(app.Panel_8);
            app.AmplitudeVEditField_5Label.Text = 'Amplitude (µV)';
            app.DistracterAmplitudeEditField2 = uieditfield(app.Panel_8, 'numeric');
            app.DistracterAmplitudeEditField2.Editable = 'off';
            app.LatencymsEditField_4Label = uilabel(app.Panel_8);
            app.LatencymsEditField_4Label.Text = 'Latency (ms)';
            app.DistracterLatencyEditField2 = uieditfield(app.Panel_8, 'numeric');
            app.DistracterLatencyEditField2.Editable = 'off';
            app.AmplitudeVEditField_6Label = uilabel(app.Panel_8);
            app.AmplitudeVEditField_6Label.Text = 'Amplitude (µV)';
            app.DistracterAmplitudeEditField3 = uieditfield(app.Panel_8, 'numeric');
            app.DistracterAmplitudeEditField3.Editable = 'off';
            app.LatencymsEditField_6Label = uilabel(app.Panel_8);
            app.LatencymsEditField_6Label.Text = 'Latency (ms)';
            app.DistracterLatencyEditField3 = uieditfield(app.Panel_8, 'numeric');
            app.DistracterLatencyEditField3.Editable = 'off';
            app.TargetLabel = uilabel(app.Panel_8);
            app.TargetLabel.Text = 'Target';
            app.DistracterLabel_4 = uilabel(app.Panel_8);
            app.DistracterLabel_4.Text = 'Distracter';
            app.TargetLabel2 = uilabel(app.Panel_8);
            app.TargetLabel2.Text = 'Target';
            app.DistracterLabel_5 = uilabel(app.Panel_8);
            app.DistracterLabel_5.Text = 'Distracter';
            app.TargetLabel3 = uilabel(app.Panel_8);
            app.TargetLabel3.Text = 'Target';
            app.DistracterLabel_6 = uilabel(app.Panel_8);
            app.DistracterLabel_6.Text = 'Distracter';
            app.AnalysisPanel = uipanel(app.MeasurementAnalysisTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.DetrendCheckBox = uicheckbox(app.AnalysisPanel);
            app.DetrendCheckBox.Text = 'Detrend';
            app.CommonAverageReferenceCheckBox = uicheckbox(app.AnalysisPanel);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.BaselineCorrectionCheckBox = uicheckbox(app.AnalysisPanel);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineTimesSpinnerLabel = uilabel(app.AnalysisPanel);
            app.BaselineTimesSpinnerLabel.Text = '▷ Baseline Time (s)';
            app.BaselineTimesSpinner = uispinner(app.AnalysisPanel);
            app.BaselineTimesSpinner.Step = 0.1;
            app.BaselineTimesSpinner.Limits = [0 3];
            app.BaselineTimesSpinner.ValueDisplayFormat = '%.1f';
            app.BaselineTimesSpinner.Value = 2;
            app.FilteringCheckBox = uicheckbox(app.AnalysisPanel);
            app.FilteringCheckBox.Text = 'Filtering';
            app.FilteringCheckBox.Value = true;
            app.FilterButtonGroup = uibuttongroup(app.AnalysisPanel);
            app.BandpassFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.BandpassFilterButton.Value = true;
            app.NotchFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.WindowLabel = uilabel(app.FilterButtonGroup);
            app.WindowLabel.Text = '~';
            app.LowCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.LowCutOffSpinner.Limits = [0 Inf];
            app.LowCutOffSpinner.Value = 4;
            app.HighCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.HighCutOffSpinner.Limits = [0 Inf];
            app.HighCutOffSpinner.Value = 30;
            app.ArtificialDetectionandRejectionCheckBox = uicheckbox(app.AnalysisPanel);
            app.ArtificialDetectionandRejectionCheckBox.Text = 'Artificial Detection and Rejection';
            app.RejectSignalsExceedingThresholdVSpinnerLabel = uilabel(app.AnalysisPanel);
            app.RejectSignalsExceedingThresholdVSpinnerLabel.Text = '▷ Reject: Signals Exceeding Threshold (μV)';
            app.RejectSignalsExceedingThresholdVSpinner = uispinner(app.AnalysisPanel);
            app.RejectSignalsExceedingThresholdVSpinner.Limits = [0 Inf];
            app.RejectSignalsExceedingThresholdVSpinner.Value = 100;
            app.MeasurementPanel = uipanel(app.MeasurementAnalysisTab);
            app.MeasurementPanel.Title = 'Measurement';
            app.ModeButtonGroup = uibuttongroup(app.MeasurementPanel);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.OddballButton = uiradiobutton(app.ModeButtonGroup);
            app.OddballButton.Text = 'Oddball';
            app.OddballButton.Value = true;
            app.ThreeStimuliButton = uiradiobutton(app.ModeButtonGroup);
            app.ThreeStimuliButton.Text = 'Three-Stimuli';
            app.RestingButton = uiradiobutton(app.ModeButtonGroup);
            app.RestingButton.Text = 'Resting';
            app.ShufflePanel = uipanel(app.MeasurementPanel);
            app.StimulusArrayUIAxes = uiaxes(app.ShufflePanel);
            title(app.StimulusArrayUIAxes, 'Oddball')
            zlabel(app.StimulusArrayUIAxes, 'Z')
            app.StimulusArrayUIAxes.XTick = [];
            app.StimulusArrayUIAxes.YTick = [];
            app.ShuffleButton = uibutton(app.ShufflePanel, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.Panel = uipanel(app.ShufflePanel);
            app.BarEdgeCheckBox = uicheckbox(app.ShufflePanel);
            app.BarEdgeCheckBox.Text = 'Bar Edge';
            app.TimePanel = uipanel(app.MeasurementPanel);
            app.TimeLabel = uilabel(app.TimePanel);
            app.TimeLabel.Text = '00 : 00 : 00';
            app.TimeLabel2 = uilabel(app.TimePanel);
            app.TimeLabel2.Text = '00 : 00 : 00';
            app.RecordButton = uibutton(app.MeasurementPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.MeasurementPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetPathButton = uibutton(app.MeasurementPanel, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            app.TriggerButton = uibutton(app.MeasurementPanel, 'push');
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TriggerTextArea = uitextarea(app.MeasurementPanel);
            app.TriggerTextArea.Editable = 'off';
            app.TargetSoundButton = uibutton(app.MeasurementPanel, 'push');
            app.TargetSoundButton.ButtonPushedFcn = createCallbackFcn(app, @TargetSoundButtonPushed, true);
            app.TargetSoundButton.Text = {'Target'; 'Sound'};
            app.StandardSoundButton = uibutton(app.MeasurementPanel, 'push');
            app.StandardSoundButton.ButtonPushedFcn = createCallbackFcn(app, @StandardSoundButtonPushed, true);
            app.StandardSoundButton.Text = {'Standard'; 'Sound'};
            app.DistracterSoundButton = uibutton(app.MeasurementPanel, 'push');
            app.DistracterSoundButton.ButtonPushedFcn = createCallbackFcn(app, @DistracterSoundButtonPushed, true);
            app.DistracterSoundButton.Enable = 'off';
            app.DistracterSoundButton.Text = {'Distracter'; 'Sound'};
            app.TypeofAuditoryStimulationButtonGroup = uibuttongroup(app.MeasurementPanel);
            app.TypeofAuditoryStimulationButtonGroup.Title = 'Type of Auditory Stimulation';
            app.ToneButton = uiradiobutton(app.TypeofAuditoryStimulationButtonGroup);
            app.ToneButton.Text = 'Tone';
            app.ToneButton.Value = true;
            app.SpeechEnglishButton = uiradiobutton(app.TypeofAuditoryStimulationButtonGroup);
            app.SpeechEnglishButton.Text = 'Speech (English)';
            app.SpeechKoreanButton = uiradiobutton(app.TypeofAuditoryStimulationButtonGroup);
            app.SpeechKoreanButton.Text = 'Speech (Korean)';
            app.Panel_22 = uipanel(app.MeasurementPanel);
            app.OddballThreeStimuliLabel = uilabel(app.Panel_22);
            app.OddballThreeStimuliLabel.Text = 'Oddball & Three-Stimuli';
            app.RestingLabel = uilabel(app.Panel_22);
            app.RestingLabel.Enable = 'off';
            app.RestingLabel.Text = 'Resting';
            app.MinutesSpinnerLabel = uilabel(app.Panel_22);
            app.MinutesSpinnerLabel.Text = 'Minutes';
            app.MinutesSpinner = uispinner(app.Panel_22);
            app.MinutesSpinner.Limits = [0 59];
            app.MinutesSpinner.Enable = 'off';
            app.MinutesSpinner.Value = 10;
            app.SecondsSpinnerLabel = uilabel(app.Panel_22);
            app.SecondsSpinnerLabel.Text = 'Seconds';
            app.SecondsSpinner = uispinner(app.Panel_22);
            app.SecondsSpinner.Limits = [0 59];
            app.SecondsSpinner.Enable = 'off';
            app.Task1CheckBox = uicheckbox(app.Panel_22);
            app.Task1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Task1CheckBoxValueChanged, true);
            app.Task1CheckBox.Enable = 'off';
            app.Task1CheckBox.Text = '[Task1]';
            app.Task1CheckBox.Value = true;
            app.Task1Task2CheckBox = uicheckbox(app.Panel_22);
            app.Task1Task2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Task1Task2CheckBoxValueChanged, true);
            app.Task1Task2CheckBox.Enable = 'off';
            app.Task1Task2CheckBox.Text = '[Task1] => [Task2]';
            app.NumofStimuliSpinner = uispinner(app.Panel_22);
            app.NumofStimuliSpinner.Step = 2;
            app.NumofStimuliSpinner.Limits = [10 300];
            app.NumofStimuliSpinner.ValueDisplayFormat = '%.0f';
            app.NumofStimuliSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofStimuliSpinnerValueChanged, true);
            app.NumofStimuliSpinner.Value = 100;
            app.NumofStimuliSpinner_2Label = uilabel(app.Panel_22);
            app.NumofStimuliSpinner_2Label.Text = 'Num of Stimuli';
            app.InterStimulusIntervalmsSpinner = uispinner(app.Panel_22);
            app.InterStimulusIntervalmsSpinner.Step = 100;
            app.InterStimulusIntervalmsSpinner.Limits = [1000 3000];
            app.InterStimulusIntervalmsSpinner.ValueDisplayFormat = '%.0f';
            app.InterStimulusIntervalmsSpinner.Value = 1500;
            app.InterStimulusIntervalmsSpinner_2Label = uilabel(app.Panel_22);
            app.InterStimulusIntervalmsSpinner_2Label.Text = 'Inter Stimulus Interval (ms)';
            app.NumofTargetSpinner = uispinner(app.Panel_22);
            app.NumofTargetSpinner.Limits = [0 300];
            app.NumofTargetSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofTargetSpinnerValueChanged, true);
            app.NumofTargetSpinner.Value = 20;
            app.NumofTargetSpinnerLabel = uilabel(app.Panel_22);
            app.NumofTargetSpinnerLabel.Text = 'Num of Target';
            app.NumofStandardSpinner = uispinner(app.Panel_22);
            app.NumofStandardSpinner.Limits = [0 300];
            app.NumofStandardSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofStandardSpinnerValueChanged, true);
            app.NumofStandardSpinner.Value = 80;
            app.NumofStandardSpinnerLabel = uilabel(app.Panel_22);
            app.NumofStandardSpinnerLabel.Text = 'Num of Standard';
            app.NumofDistracterSpinner = uispinner(app.Panel_22);
            app.NumofDistracterSpinner.Limits = [0 300];
            app.NumofDistracterSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofDistracterSpinnerValueChanged, true);
            app.NumofDistracterSpinner.Enable = 'off';
            app.NumofDistracterSpinnerLabel = uilabel(app.Panel_22);
            app.NumofDistracterSpinnerLabel.Text = 'Num of Distracter';
            app.HourSpinnerLabel = uilabel(app.Panel_22);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.Panel_22);
            app.HourSpinner.Limits = [0 59];
            app.HourSpinner.Enable = 'off';
            app.Task1EditFieldLabel = uilabel(app.Panel_22);
            app.Task1EditFieldLabel.Text = '[Task1]';
            app.Task1EditField = uieditfield(app.Panel_22, 'text');
            app.Task1EditField.Enable = 'off';
            app.Task1EditField.Value = 'eyes-open';
            app.Task2EditFieldLabel = uilabel(app.Panel_22);
            app.Task2EditFieldLabel.Text = '[Task2]';
            app.Task2EditField = uieditfield(app.Panel_22, 'text');
            app.Task2EditField.Enable = 'off';
            app.Task2EditField.Value = 'eyes-closed';
            app.TabGroup = uitabgroup(app.MeasurementPanel);
            app.ToneTab = uitab(app.TabGroup);
            app.ToneTab.Title = 'Tone';
            app.SoundFrequencyLabel = uilabel(app.ToneTab);
            app.SoundFrequencyLabel.Text = 'Sound Frequency';
            app.StandardSpinner = uispinner(app.ToneTab);
            app.StandardSpinner.Step = 100;
            app.StandardSpinner.Limits = [500 3500];
            app.StandardSpinner.Value = 1000;
            app.StandardSpinnerLabel = uilabel(app.ToneTab);
            app.StandardSpinnerLabel.Text = 'Standard';
            app.TargetSpinner = uispinner(app.ToneTab);
            app.TargetSpinner.Step = 100;
            app.TargetSpinner.Limits = [500 3500];
            app.TargetSpinner.Value = 2000;
            app.TargetSpinnerLabel = uilabel(app.ToneTab);
            app.TargetSpinnerLabel.Text = 'Target';
            app.DistracterSpinner = uispinner(app.ToneTab);
            app.DistracterSpinner.Step = 100;
            app.DistracterSpinner.Limits = [500 3500];
            app.DistracterSpinner.Value = 500;
            app.DistracterSpinnerLabel = uilabel(app.ToneTab);
            app.DistracterSpinnerLabel.Text = 'Distracter';
            app.SpeechEnglishTab = uitab(app.TabGroup);
            app.SpeechEnglishTab.Title = 'Speech (English)';
            app.TextLabel = uilabel(app.SpeechEnglishTab);
            app.TextLabel.Text = 'Text';
            app.StandardEditField = uieditfield(app.SpeechEnglishTab, 'text');
            app.StandardEditField.Value = 'A';
            app.TargetEditField = uieditfield(app.SpeechEnglishTab, 'text');
            app.TargetEditField.Value = 'B';
            app.DistracterEditField = uieditfield(app.SpeechEnglishTab, 'text');
            app.DistracterEditField.Value = 'C';
            app.StandardSpinnerLabel_2 = uilabel(app.SpeechEnglishTab);
            app.StandardSpinnerLabel_2.Text = 'Standard';
            app.TargetSpinnerLabel_2 = uilabel(app.SpeechEnglishTab);
            app.TargetSpinnerLabel_2.Text = 'Target';
            app.DistracterSpinnerLabel_2 = uilabel(app.SpeechEnglishTab);
            app.DistracterSpinnerLabel_2.Text = 'Distracter';
            app.SpeedSpinner = uispinner(app.SpeechEnglishTab);
            app.SpeedSpinner.Limits = [5 10];
            app.SpeedSpinner.Value = 7;
            app.SpeedLabel = uilabel(app.SpeechEnglishTab);
            app.SpeedLabel.Text = 'Speed';
            app.SpeechKoreanTab = uitab(app.TabGroup);
            app.SpeechKoreanTab.Title = 'Speech (Korean)';
            app.TextLabel2 = uilabel(app.SpeechKoreanTab);
            app.TextLabel2.Text = 'Text';
            app.SpeedSpinner2 = uispinner(app.SpeechKoreanTab);
            app.SpeedSpinner2.Limits = [5 10];
            app.SpeedSpinner2.Value = 7;
            app.SpeedLabel2 = uilabel(app.SpeechKoreanTab);
            app.SpeedLabel2.Text = 'Speed';
            app.StandardEditField2 = uieditfield(app.SpeechKoreanTab, 'text');
            app.StandardEditField2.Value = '가';
            app.StandardSpinnerLabel_3 = uilabel(app.SpeechKoreanTab);
            app.StandardSpinnerLabel_3.Text = 'Standard';
            app.TargetSpinnerLabel_3 = uilabel(app.SpeechKoreanTab);
            app.TargetSpinnerLabel_3.Text = 'Target';
            app.TargetEditField2 = uieditfield(app.SpeechKoreanTab, 'text');
            app.TargetEditField2.Value = '나';
            app.DistracterSpinnerLabel_3 = uilabel(app.SpeechKoreanTab);
            app.DistracterSpinnerLabel_3.Text = 'Distracter';
            app.DistracterEditField2 = uieditfield(app.SpeechKoreanTab, 'text');
            app.DistracterEditField2.Value = '다';
            app.TabGroup7 = uitabgroup(app.MeasurementPanel);
            app.DelayCompensationPassiveTab = uitab(app.TabGroup7);
            app.DelayCompensationPassiveTab.Title = 'Delay Compensation (Passive)';
            app.PassiveCheckBox = uicheckbox(app.DelayCompensationPassiveTab);
            app.PassiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PassiveCheckBoxValueChanged, true);
            app.PassiveCheckBox.Text = 'Passive';
            app.PassiveCheckBox.Value = true;
            app.DelayCompensationmsEditFieldLabel = uilabel(app.DelayCompensationPassiveTab);
            app.DelayCompensationmsEditFieldLabel.Text = 'Delay Compensation (ms)';
            app.DelayCompensationEditField = uieditfield(app.DelayCompensationPassiveTab, 'numeric');
            app.DelayCompensationEditField.Limits = [0 Inf];
            app.DelayCompensationEditField.ValueDisplayFormat = '%.0f';
            app.DelayCompensationEditField.Value = 220;
            app.ActiveTab = uitab(app.TabGroup7);
            app.ActiveTab.Title = '(Active)';
            app.ActiveCheckBox = uicheckbox(app.ActiveTab);
            app.ActiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCheckBoxValueChanged, true);
            app.ActiveCheckBox.Text = 'Active';
            app.TCPPortNumberEditField_2Label = uilabel(app.ActiveTab);
            app.TCPPortNumberEditField_2Label.Text = 'TCP Port Number';
            app.TCPPortNumberEditField = uieditfield(app.ActiveTab, 'numeric');
            app.TCPPortNumberEditField.ValueDisplayFormat = '%.0f';
            app.TCPPortNumberEditField.Value = 50000;
            app.PlotButton = uibutton(app.MeasurementAnalysisTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WholeChannelPlottingTab = uitab(app.TabGroup8);
            app.WholeChannelPlottingTab.Title = 'Whole Channel Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P300_toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end