classdef Timeseries_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SampleCheckBox                  matlab.ui.control.CheckBox
        TimeCheckBox                    matlab.ui.control.CheckBox
        SampleEditField                 matlab.ui.control.NumericEditField
        SampleEditFieldLabel            matlab.ui.control.Label
        TimesSpinner                    matlab.ui.control.Spinner
        TimesSpinnerLabel               matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        AddAnnotationtoChartButton      matlab.ui.control.Button
        AmplitudeVEditField             matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel        matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        ChannelListBoxLabel             matlab.ui.control.Label
        TimeSeriesPanel                 matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SignalProcessingTab             matlab.ui.container.Tab
        BaselineTimesSpinner            matlab.ui.control.Spinner
        BaselineTimesSpinnerLabel       matlab.ui.control.Label
        DetrendCheckBox                 matlab.ui.control.CheckBox
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        HighCutOffSpinner               matlab.ui.control.Spinner
        LowCutOffSpinner                matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        NotchFilterButton               matlab.ui.control.RadioButton
        BandpassFilterButton            matlab.ui.control.RadioButton
        FilteringCheckBox               matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        ICATab                          matlab.ui.container.Tab
        MuscleLabel_4                   matlab.ui.control.Label
        ChannelNoiseEditField           matlab.ui.control.NumericEditField
        ChannelNoiseButton              matlab.ui.control.StateButton
        MuscleLabel_3                   matlab.ui.control.Label
        LineNoiseEditField              matlab.ui.control.NumericEditField
        LineNoiseButton                 matlab.ui.control.StateButton
        MuscleLabel_2                   matlab.ui.control.Label
        HeartEditField                  matlab.ui.control.NumericEditField
        HeartButton                     matlab.ui.control.StateButton
        EyeEditField                    matlab.ui.control.NumericEditField
        EyeLabel                        matlab.ui.control.Label
        EyeButton                       matlab.ui.control.StateButton
        MuscleLabel                     matlab.ui.control.Label
        MuscleEditField                 matlab.ui.control.NumericEditField
        MuscleButton                    matlab.ui.control.StateButton
        ICAArtifactsRemovalCheckBox     matlab.ui.control.CheckBox
        TriggerLabel                    matlab.ui.control.Label
        TriggerTextArea                 matlab.ui.control.TextArea
        YaxisPanel                      matlab.ui.container.Panel
        YlimCheckBox                    matlab.ui.control.CheckBox
        YlimSpinner                     matlab.ui.control.Spinner
        YlimSpinnerLabel                matlab.ui.control.Label
        DataFileNameTextArea            matlab.ui.control.TextArea
        DataFileNameTextAreaLabel       matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        XaxisWindowPanel                matlab.ui.container.Panel
        EndTimesSpinner                 matlab.ui.control.Spinner
        EndTimesSpinnerLabel            matlab.ui.control.Label
        StartTimesSpinner               matlab.ui.control.Spinner
        StartTimesSpinnerLabel          matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        AllChannelNode                  matlab.ui.container.TreeNode
        TimeSeriesDataUIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        locations
        sample_rate = 500;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global sample_rate
            global locations

            % app name
            app.UIFigure.Name = 'Time Domain';
            
            % public properties
            app.locations = locations;
            app.sample_rate = sample_rate;
            
            % channel list (list-box & tree and nodes)
            for i = 1:size(locations,1)
                uitreenode(app.AllChannelNode,'Text',locations.labels{i});
                app.ChannelListBox.Items(i) = locations.labels(i);
            end
            app.ChannelListBox.Value = app.ChannelListBox.Items(1);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            global EEG
            
            % EEG struct
            EEG.setname = 'matfile'; EEG.filename = ''; EEG.filepath = ''; EEG.subject = '';
            EEG.nbchan = size(app.locations,1); EEG.srate = app.sample_rate;
            EEG.trials = 1; EEG.pnts = []; EEG.xmin = 0; EEG.xmax = []; EEG.data = [];
            EEG.icaact = []; EEG.icawinv = []; EEG.icasphere = []; EEG.icaweights = []; EEG.icachansind = [];
            location_struct = table2struct(app.locations); EEG.chanlocs = location_struct;
            EEG.urchanlocs = []; EEG.chaninfo = [];
            EEG.event = []; EEG.urevent = []; EEG.epoch = [];
            EEG.reject = []; EEG.specdata = []; EEG.specicaact = [];
            EEG.etc = [];
            
            % load data
            [filename, filepath] = uigetfile('*.mat');
            if (ischar(filepath))
               file_name_path = [filepath filename];
               data = importdata(file_name_path);
            end
            app.DataFileNameTextArea.Value = file_name_path;
            
            if isstruct(data)
                rawdata = data.rawdata; % channel X data (matrix)
            else
                rawdata = data;
            end

            try
                if isstruct(data)
                    event_text = data.event_text;
                end
                if class(event_text) == "cell"
                    app.TriggerTextArea.Value = event_text;
                end
            catch
            end
            
            plot_rawdata = rawdata(1:size(app.locations,1),:); % channel X data (matrix)
            
            % additional EEG struct
            EEG.nbchan = size(plot_rawdata, 1); EEG.pnts = size(plot_rawdata, 2);
            EEG.data = plot_rawdata; % channel X data (matrix)
            EEG.xmax = (EEG.pnts-1)/EEG.srate;
            
            % x-axis (window)
            app.EndTimesSpinner.Limits = [EEG.xmin, EEG.xmax];
            app.TimesSpinner.Limits = [1/EEG.srate, EEG.xmax];
            app.EndTimesSpinner.Value = EEG.xmax;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global EEG
            global EEG2

            
            %%% Pre-processing
            EEG2 = EEG;

            % filtering
            if app.FilteringCheckBox.Value == 1
                EEG2 = pop_eegfiltnew(EEG2, app.LowCutOffSpinner.Value, app.HighCutOffSpinner.Value, [], app.NotchFilterButton.Value, [], 0);
            end
            
            % common average reference
            if app.CommonAverageReferenceCheckBox.Value == 1
                EEG2 = pop_reref(EEG2,[]); % channel X data (matrix)
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end

            % detrend
            if app.DetrendCheckBox.Value == 1
                detrend_data = detrend(transpose(EEG2.data)); % data X channel (matrix)
                EEG2.data = transpose(detrend_data); % channel X data (matrix)
            end

            % baseline correction
            if app.BaselineCorrectionCheckBox.Value == 1
                if (app.StartTimesSpinner.Value >= app.BaselineTimesSpinner.Value) && (app.StartTimesSpinner.Value ~= 0) && (app.BaselineTimesSpinner.Value ~= 0)
                    baseline_data  = EEG2.data(:, (app.StartTimesSpinner.Value*EEG.srate+1 - app.BaselineTimesSpinner.Value*EEG.srate):app.StartTimesSpinner.Value*EEG.srate+1); % channel X data (matrix)

                    baseline_mean = mean(baseline_data, 2);
                    corrected_data = EEG2.data - baseline_mean;

                    EEG2.data = corrected_data; % channel X data (matrix)
                end
            end

            % time window
            EEG2.data = EEG2.data(:, app.StartTimesSpinner.Value*EEG.srate+1:app.EndTimesSpinner.Value*EEG.srate);
            EEG2.pnts = size(EEG2.data, 2);
            EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;

            % ICA -> artifacts removal
            if app.ICAArtifactsRemovalCheckBox.Value == 1
                brain_value = [NaN NaN];
                other_value = [NaN NaN];

                if app.MuscleButton.Value == 1
                    muscle_value = [app.MuscleEditField.Value/100 1];
                else, muscle_value = [NaN NaN];
                end

                if app.EyeButton.Value == 1
                    eye_value = [app.EyeEditField.Value/100 1];
                else, eye_value = [NaN NaN];
                end

                if app.HeartButton.Value == 1
                    heart_value = [app.HeartEditField.Value/100 1];
                else, heart_value = [NaN NaN];
                end

                if app.LineNoiseButton.Value == 1
                    lineNoise_value = [app.LineNoiseEditField.Value/100 1];
                else, lineNoise_value = [NaN NaN];
                end

                if app.ChannelNoiseButton.Value == 1
                    channelNoise_value = [app.ChannelNoiseEditField.Value/100 1];
                else, channelNoise_value = [NaN NaN];
                end

                EEG2 = pop_runica(EEG2, 'icatype', 'runica', 'extended',1, 'rndreset','yes', 'interrupt','off');
                EEG2 = pop_iclabel(EEG2, 'default');
                EEG2 = pop_icflag(EEG2, [brain_value;muscle_value;eye_value;heart_value;lineNoise_value;channelNoise_value;other_value]);
                EEG2 = pop_subcomp(EEG2, find(EEG2.reject.gcompreject)', 0);
                
                if class(EEG2.data) ~= "double"
                    EEG2.data = double(EEG2.data);
                end
            end
            
            % time window
            processed_data = EEG2.data;
            EEG2.data = zeros(EEG.nbchan, EEG.pnts);
            EEG2.data(:, app.StartTimesSpinner.Value*EEG2.srate+1:app.EndTimesSpinner.Value*EEG2.srate) = processed_data;
            EEG2.pnts = size(EEG2.data, 2);
            EEG2.xmax = (EEG2.pnts-1)/EEG2.srate;

            % time series data UIAxes
            xticks(app.TimeSeriesDataUIAxes, linspace(app.StartTimesSpinner.Value*EEG2.srate, app.EndTimesSpinner.Value*EEG2.srate, 5));
            xtick_labels = cellstr(num2str(round(linspace(app.StartTimesSpinner.Value, app.EndTimesSpinner.Value, 5), 3)'));
            xticklabels(app.TimeSeriesDataUIAxes, xtick_labels);
            xlim(app.TimeSeriesDataUIAxes, [app.StartTimesSpinner.Value*EEG2.srate, app.EndTimesSpinner.Value*EEG2.srate]);
            
            yticks(app.TimeSeriesDataUIAxes, 'auto');
            if app.YlimCheckBox.Value == 0
                if abs(max(EEG2.data, [], 'all')) >= abs(min(EEG2.data, [], 'all'))
                    ylim(app.TimeSeriesDataUIAxes, [-abs(max(EEG2.data, [], 'all')), abs(max(EEG2.data, [], 'all'))]);
                else
                    ylim(app.TimeSeriesDataUIAxes, [-abs(min(EEG2.data, [], 'all')), abs(min(EEG2.data, [], 'all'))]);
                end
            elseif app.YlimCheckBox.Value == 1
                ylim(app.TimeSeriesDataUIAxes, [-app.YlimSpinner.Value, app.YlimSpinner.Value]);
            end
            
            % selected channels
            checkedNodes = app.Tree.CheckedNodes;
            checkedIndices = [];
            for i = 1:length(checkedNodes)
            nodeText = checkedNodes(i).Text;
            idx = find(strcmp(app.locations.labels, nodeText));
            if ~isempty(idx)
                checkedIndices = [checkedIndices, idx];
            end
            end
            selected_data = EEG2.data(checkedIndices,:);
            
            % time series data UIAxes
            plot(app.TimeSeriesDataUIAxes, transpose(selected_data));

            % channel list
            app.ChannelListBox.Items = {};
            for i = 1:size(app.locations,1)
                app.ChannelListBox.Items(i) = app.locations.labels(i);
            end
            app.ChannelListBox.Items = app.ChannelListBox.Items(checkedIndices);

            if ~isempty(app.ChannelListBox.Items)
                app.ChannelListBox.Value = app.ChannelListBox.Items(1);
            end

        end

        % Button pushed function: AddAnnotationtoChartButton
        function AddAnnotationtoChartButtonPushed(app, event)
            global EEG2
            
            selected_channel = find(strcmp(app.locations.labels, app.ChannelListBox.Value));

            if app.TimeCheckBox.Value == 1
                selected_time = app.TimesSpinner.Value;
                app.SampleEditField.Value = selected_time*EEG2.srate;
                app.AmplitudeVEditField.Value = EEG2.data(selected_channel, selected_time*EEG2.srate);
            elseif app.SampleCheckBox.Value == 1
                selected_sample = app.SampleEditField.Value;
                app.TimesSpinner.Value = selected_sample/EEG2.srate;
                app.AmplitudeVEditField.Value = EEG2.data(selected_channel, selected_sample);
            end
            time_text = string(app.TimesSpinner.Value)+ " s";
            amp_text = string(round(app.AmplitudeVEditField.Value, 2))+" µV";
            
            text(app.TimeSeriesDataUIAxes, app.TimesSpinner.Value*EEG2.srate, app.AmplitudeVEditField.Value, [time_text newline amp_text]);

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete(findobj(app.TimeSeriesDataUIAxes,'type','text'));

        end

        % Value changed function: TimeCheckBox
        function TimeCheckBoxValueChanged(app, event)
            if app.TimeCheckBox.Value == 1
                app.SampleCheckBox.Value = 0;
                app.TimesSpinner.Editable = "on";
                app.SampleEditField.Editable = "off";
            else
                app.SampleCheckBox.Value = 1;
                app.TimesSpinner.Editable = "off";
                app.SampleEditField.Editable = "on";
            end
            
        end

        % Value changed function: SampleCheckBox
        function SampleCheckBoxValueChanged(app, event)
            if app.SampleCheckBox.Value == 1
                app.TimeCheckBox.Value = 0;
                app.TimesSpinner.Editable = "off";
                app.SampleEditField.Editable = "on";
            else
                app.TimeCheckBox.Value = 0;
                app.TimesSpinner.Editable = "on";
                app.SampleEditField.Editable = "off";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSeriesDataUIAxes = uiaxes(app.UIFigure);
            title(app.TimeSeriesDataUIAxes, 'Time Series Data')
            xlabel(app.TimeSeriesDataUIAxes, 'Time (s)')
            app.TimeSeriesDataUIAxes.XTick = [];
            app.TimeSeriesDataUIAxes.XTickLabel = '';
            app.TimeSeriesDataUIAxes.YTick = [];
            app.TimeSeriesPanel = uipanel(app.UIFigure);
            app.Tree = uitree(app.TimeSeriesPanel, 'checkbox');
            app.AllChannelNode = uitreenode(app.Tree);
            app.AllChannelNode.Text = 'All Channel';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.AllChannelNode];
            app.XaxisWindowPanel = uipanel(app.TimeSeriesPanel);
            app.XaxisWindowPanel.Title = 'X-axis (Window)';
            app.StartTimesSpinnerLabel = uilabel(app.XaxisWindowPanel);
            app.StartTimesSpinnerLabel.Text = 'Start Time (s)';
            app.StartTimesSpinner = uispinner(app.XaxisWindowPanel);
            app.StartTimesSpinner.Limits = [0 Inf];
            app.EndTimesSpinnerLabel = uilabel(app.XaxisWindowPanel);
            app.EndTimesSpinnerLabel.Text = 'End Time (s)';
            app.EndTimesSpinner = uispinner(app.XaxisWindowPanel);
            app.EndTimesSpinner.Limits = [0 Inf];
            app.LoadDataButton = uibutton(app.TimeSeriesPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PlotButton = uibutton(app.TimeSeriesPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DataFileNameTextAreaLabel = uilabel(app.TimeSeriesPanel);
            app.DataFileNameTextAreaLabel.Text = 'Data File Name';
            app.DataFileNameTextArea = uitextarea(app.TimeSeriesPanel);
            app.DataFileNameTextArea.Editable = 'off';
            app.YaxisPanel = uipanel(app.TimeSeriesPanel);
            app.YaxisPanel.Title = 'Y-axis';
            app.YlimSpinnerLabel = uilabel(app.YaxisPanel);
            app.YlimSpinnerLabel.Text = 'Ylim';
            app.YlimSpinner = uispinner(app.YaxisPanel);
            app.YlimSpinner.Limits = [0 Inf];
            app.YlimSpinner.ValueDisplayFormat = '%.0f';
            app.YlimSpinner.Value = 300;
            app.YlimCheckBox = uicheckbox(app.YaxisPanel);
            app.YlimCheckBox.Text = '';
            app.TriggerTextArea = uitextarea(app.TimeSeriesPanel);
            app.TriggerTextArea.Editable = 'off';
            app.TriggerLabel = uilabel(app.TimeSeriesPanel);
            app.TriggerLabel.Text = 'Trigger';
            app.TabGroup = uitabgroup(app.TimeSeriesPanel);
            app.SignalProcessingTab = uitab(app.TabGroup);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.CommonAverageReferenceCheckBox = uicheckbox(app.SignalProcessingTab);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.BaselineCorrectionCheckBox = uicheckbox(app.SignalProcessingTab);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.FilteringCheckBox = uicheckbox(app.SignalProcessingTab);
            app.FilteringCheckBox.Text = 'Filtering';
            app.FilteringCheckBox.Value = true;
            app.FilterButtonGroup = uibuttongroup(app.SignalProcessingTab);
            app.BandpassFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.BandpassFilterButton.Value = true;
            app.NotchFilterButton = uiradiobutton(app.FilterButtonGroup);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.WindowLabel = uilabel(app.FilterButtonGroup);
            app.WindowLabel.Text = '~';
            app.LowCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.LowCutOffSpinner.Limits = [0 Inf];
            app.LowCutOffSpinner.Value = 4;
            app.HighCutOffSpinner = uispinner(app.FilterButtonGroup);
            app.HighCutOffSpinner.Limits = [0 Inf];
            app.HighCutOffSpinner.Value = 30;
            app.DetrendCheckBox = uicheckbox(app.SignalProcessingTab);
            app.DetrendCheckBox.Text = 'Detrend';
            app.BaselineTimesSpinnerLabel = uilabel(app.SignalProcessingTab);
            app.BaselineTimesSpinnerLabel.Text = '▷ Baseline Time (s)';
            app.BaselineTimesSpinner = uispinner(app.SignalProcessingTab);
            app.BaselineTimesSpinner.Step = 0.1;
            app.BaselineTimesSpinner.Limits = [0 Inf];
            app.BaselineTimesSpinner.ValueDisplayFormat = '%.1f';
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.ICAArtifactsRemovalCheckBox = uicheckbox(app.ICATab);
            app.ICAArtifactsRemovalCheckBox.Text = 'ICA +Artifacts Removal';
            app.MuscleButton = uibutton(app.ICATab, 'state');
            app.MuscleButton.Text = 'Muscle';
            app.MuscleButton.Value = true;
            app.MuscleEditField = uieditfield(app.ICATab, 'numeric');
            app.MuscleEditField.Limits = [0 100];
            app.MuscleEditField.ValueDisplayFormat = '%.0f';
            app.MuscleEditField.Value = 90;
            app.MuscleLabel = uilabel(app.ICATab);
            app.MuscleLabel.Text = '%';
            app.EyeButton = uibutton(app.ICATab, 'state');
            app.EyeButton.Text = 'Eye';
            app.EyeButton.Value = true;
            app.EyeLabel = uilabel(app.ICATab);
            app.EyeLabel.Text = '%';
            app.EyeEditField = uieditfield(app.ICATab, 'numeric');
            app.EyeEditField.Limits = [0 100];
            app.EyeEditField.ValueDisplayFormat = '%.0f';
            app.EyeEditField.Value = 90;
            app.HeartButton = uibutton(app.ICATab, 'state');
            app.HeartButton.Text = 'Heart';
            app.HeartEditField = uieditfield(app.ICATab, 'numeric');
            app.HeartEditField.Limits = [0 100];
            app.HeartEditField.ValueDisplayFormat = '%.0f';
            app.HeartEditField.Value = 90;
            app.MuscleLabel_2 = uilabel(app.ICATab);
            app.MuscleLabel_2.Text = '%';
            app.LineNoiseButton = uibutton(app.ICATab, 'state');
            app.LineNoiseButton.Text = 'Line Noise';
            app.LineNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.LineNoiseEditField.Limits = [0 100];
            app.LineNoiseEditField.ValueDisplayFormat = '%.0f';
            app.LineNoiseEditField.Value = 90;
            app.MuscleLabel_3 = uilabel(app.ICATab);
            app.MuscleLabel_3.Text = '%';
            app.ChannelNoiseButton = uibutton(app.ICATab, 'state');
            app.ChannelNoiseButton.Text = 'Channel Noise';
            app.ChannelNoiseEditField = uieditfield(app.ICATab, 'numeric');
            app.ChannelNoiseEditField.Limits = [0 100];
            app.ChannelNoiseEditField.ValueDisplayFormat = '%.0f';
            app.ChannelNoiseEditField.Value = 90;
            app.MuscleLabel_4 = uilabel(app.ICATab);
            app.MuscleLabel_4.Text = '%';
            app.ChannelListBoxLabel = uilabel(app.UIFigure);
            app.ChannelListBoxLabel.Text = 'Channel';
            app.ChannelListBox = uilistbox(app.UIFigure);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Value = {};
            app.AmplitudeVEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (µV)';
            app.AmplitudeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeVEditField.Editable = 'off';
            app.AddAnnotationtoChartButton = uibutton(app.UIFigure, 'push');
            app.AddAnnotationtoChartButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnnotationtoChartButtonPushed, true);
            app.AddAnnotationtoChartButton.Text = 'Add Annotation to Chart';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.TimesSpinnerLabel = uilabel(app.UIFigure);
            app.TimesSpinnerLabel.Text = 'Time (s)';
            app.TimesSpinner = uispinner(app.UIFigure);
            app.TimesSpinner.Limits = [0 Inf];
            app.SampleEditFieldLabel = uilabel(app.UIFigure);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleEditField.Limits = [1 Inf];
            app.SampleEditField.ValueDisplayFormat = '%.0f';
            app.SampleEditField.Editable = 'off';
            app.SampleEditField.Value = 1;
            app.TimeCheckBox = uicheckbox(app.UIFigure);
            app.TimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeCheckBoxValueChanged, true);
            app.TimeCheckBox.Text = '';
            app.TimeCheckBox.Value = true;
            app.SampleCheckBox = uicheckbox(app.UIFigure);
            app.SampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SampleCheckBoxValueChanged, true);
            app.SampleCheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Timeseries_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end